classdef Final_LoadPredictionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadPredictionTab               matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        RUNButton                       matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        MAXofL2inuseEditFieldLabel      matlab.ui.control.Label
        MAXofL2inuseEditField           matlab.ui.control.NumericEditField
        MINofL2inuseEditFieldLabel      matlab.ui.control.Label
        MINofL2inuseEditField           matlab.ui.control.NumericEditField
        ChargetimehEditFieldLabel       matlab.ui.control.Label
        ChargetimehEditField            matlab.ui.control.NumericEditField
        MAXofL3inuseEditFieldLabel      matlab.ui.control.Label
        MAXofL3inuseEditField           matlab.ui.control.NumericEditField
        MINofL3inuseEditFieldLabel      matlab.ui.control.Label
        MINofL3inuseEditField           matlab.ui.control.NumericEditField
        DischargetimeL2hEditFieldLabel  matlab.ui.control.Label
        DischargetimeL2hEditField       matlab.ui.control.NumericEditField
        DischargetimeL3hEditFieldLabel  matlab.ui.control.Label
        DischargetimeL3hEditField       matlab.ui.control.NumericEditField
        initialSOCEditFieldLabel        matlab.ui.control.Label
        initialSOCEditField             matlab.ui.control.NumericEditField
        L2chargersEditFieldLabel        matlab.ui.control.Label
        L2chargersEditField             matlab.ui.control.NumericEditField
        L3chargersEditFieldLabel        matlab.ui.control.Label
        L3chargersEditField             matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function [t2,p2,t3,p3] = WC2(app,nl2,nl3,c,d2,d3,sc)
            
numL2 = nl2;
numL3 = nl3;
totloadL2= zeros(1,40);
totloadL3= zeros(1,100);
%assume BESS starts off full @ 100kWh
%assume 3h to charge 100kW BESS, but that could be changable 
%assume max bess is 90%, and min bess is 10%
%assume car's battery capacity is 100kWh
%assume chargers are always in use at full capacity 

%time array
n2=zeros(1,40);
n3=zeros(1,100);
% state of charge 
soc= sc;
%for index
i=1;
%totcharge time (input)
ct=c;
%tot dicharge time forL3(assumption made from research)
dt = d3;
index=0;

%L3 structure 
while index<=24
   
   %charging
   if soc<=10
     while soc<90 
        if (90> soc)&&(soc>=80)
            %soc*battery capacity
          totloadL3(i) = .9*100000*numL3;
          n3(i)=n3(i-1)+0.21*ct;
          i=i+1;
          soc= soc+10;
        elseif (80> soc)&&(soc>=70)
          totloadL3(i) = .8*100000*numL3;
          n3(i)=n3(i-1)+0.2*ct;
          i=i+1;
          soc= soc+10;
        elseif  (70> soc)&&(soc>=60)
          totloadL3(i) = .7*100000*numL3;
          n3(i)=n3(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (60> soc)&&(soc>=50)
          totloadL3(i) =.6*100000*numL3 ;
          n3(i)=n3(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        elseif (50> soc)&&(soc>=40)
          totloadL3(i) = .5*100000*numL3;
          n3(i)=n3(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        elseif (40> soc)&&(soc>=30)
          totloadL3(i) = .4*100000*numL3;
          n3(i)=n3(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (30> soc)&&(soc>=20)
          totloadL3(i) = .3*100000*numL3;
          n3(i)=n3(i-1)+0.09*ct;
          i=i+1;
          soc= soc+10;
        elseif (20> soc)&&(soc>=10)
          totloadL3(i) = .2*100000*numL3;
          n3(i)= n3(i-1)+0.08*ct;
          i=i+1;
          soc= soc+10;
        end 
     end
   continue
   end
   
  %Dishcarging 
  % takes 80 mins to discharge = 1.3h
  
  while soc>10 
   if (90>= soc)&&(soc>80)&&(i==1)
          totloadL3(i) = .9*100000*numL3;
          n3(i)=0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif (90>= soc)&&(soc>80)&&(i>1)
          totloadL3(i) = .9*100000*numL3;
          n3(i)=n3(i-1)+0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif (80>= soc)&&(soc>70)
          totloadL3(i) = .8*100000*numL3;
          n3(i)=n3(i-1)+0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif  (70>= soc)&&(soc>60)
          totloadL3(i) = .7*100000*numL3;
          n3(i)=n3(i-1)+0.053*dt;
          i=i+1;
          soc= soc-10;
   elseif (60>= soc)&&(soc>50)
          totloadL3(i) =.6*100000*numL3;
          n3(i)=n3(i-1)+0.046*dt;
          i=i+1;
          soc= soc-10;
   elseif (50>= soc)&&(soc>40)
          totloadL3(i) = .5*100000*numL3;
          n3(i)=n3(i-1)+0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif (40>= soc)&&(soc>30)
          totloadL3(i) = .4*100000*numL3;
          n3(i)=n3(i-1)+0.06*dt;
          i=i+1;
          soc= soc-10;
   elseif (30>= soc)&&(soc>20)
          totloadL3(i) = .3*100000*numL3;
          n3(i)=n3(i-1)+0.15*dt;
          i=i+1;
          soc= soc-10;
   elseif (20>= soc)&&(soc>10)
          totloadL3(i) = .2*100000*numL3;
          n3(i)= n3(i-1)+0.11*dt;
          i=i+1;
          soc= soc-10;            
   end  
   continue 
  end
  index=n3(i-1);
end
% figure 
% plot(n3,totloadL3);
% xlim([1 24]),xlabel('Time(h)'),ylabel('Power(W)'),title('Power(w) vs Time(h) of L3')

%/////////////////////////////////////////////////////////////////////////////

%structure of L2
soc=sc;
i=1;
dt2=d2;%dischrage time for L2 based on research
index=0;
while index<=24
   
   %charging
   if soc<=10
     while soc<90 
        if (90> soc)&&(soc>=80)
            %soc*battery capacity
          totloadL2(i) = .9*100000*numL2;
          n2(i)=n2(i-1)+0.21*ct;
          i=i+1;
          soc= soc+10;
        elseif (80> soc)&&(soc>=70)
          totloadL2(i) = .8*100000*numL2;
          n2(i)=n2(i-1)+0.2*ct;
          i=i+1;
          soc= soc+10;
        elseif  (70> soc)&&(soc>=60)
          totloadL2(i) = .7*100000*numL2;
          n2(i)=n2(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (60> soc)&&(soc>=50)
          totloadL2(i) =.6*100000*numL2;
          n2(i)=n2(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        elseif (50> soc)&&(soc>=40)
          totloadL2(i) = .5*100000*numL2;
          n2(i)=n2(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        elseif (40> soc)&&(soc>=30)
          totloadL2(i) = .4*100000*numL2;
          n2(i)=n2(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (30> soc)&&(soc>=20)
          totloadL2(i) = .3*100000*numL2;
          n2(i)=n2(i-1)+0.09*ct;
          i=i+1;
          soc= soc+10;
        elseif (20> soc)&&(soc>=10)
          totloadL2(i) = .2*100000*numL2;
          n2(i)=n2(i-1)+0.08*ct;
          i=i+1;
          soc= soc+10;
        end 
     end
   continue
   end
   
  %Dishcarging 
  % takes 15h to charge 100kW tesla
  
  while soc>10 
   if (90>= soc)&&(soc>80)&&(i==1)
          totloadL2(i) = .9*100000*numL2;
          n2(i)=0.042*dt2;
          i=i+1;
          soc= soc-10;
   elseif (90>= soc)&&(soc>80)&&(i>1)
          totloadL2(i) = .9*100000*numL2;
          n2(i)=n2(i-1)+0.042*dt2;
          i=i+1;
          soc= soc-10;
   elseif (80>= soc)&&(soc>70)
          totloadL2(i) = .8*100000*numL2;
          n2(i)=n2(i-1)+0.0167*dt2;
          i=i+1;
          soc= soc-10;
   elseif  (70>= soc)&&(soc>60)
          totloadL2(i) = .7*100000*numL2;
          n2(i)=n2(i-1)+0.03*dt2;
          i=i+1;
          soc= soc-10;
   elseif (60>= soc)&&(soc>50)
          totloadL2(i) =.6*100000*numL2;
          n2(i)=n2(i-1)+0.05*dt2;
          i=i+1;
          soc= soc-10;
   elseif (50>= soc)&&(soc>40)
          totloadL2(i) = .5*100000*numL2;
          n2(i)=n2(i-1)+0.042*dt2;
          i=i+1;
          soc= soc-10;
   elseif (40>= soc)&&(soc>30)
          totloadL2(i) = .4*100000*numL2;
          n2(i)=n2(i-1)+0.058*dt2;
          i=i+1;
          soc= soc-10;
   elseif (30>= soc)&&(soc>20)
          totloadL2(i) = .3*100000*numL2;
          n2(i)=n2(i-1)+0.1*dt2;
          i=i+1;
          soc= soc-10;
   elseif (20>= soc)&&(soc>10)
          totloadL2(i) = .2*100000*numL2;
          n2(i)=n2(i-1)+0.26*dt2;
          i=i+1;
          soc= soc-10;       
   end  
   continue 
  end
  index=n2(i-1);
end
% figure (2)
% plot(n2,totloadL2);
% xlim([1 24]),xlabel('Time(h)'),ylabel('Power(W)'),title('Power(w) vs Time(h) of L2')
t2 = n2;
t3 = n3;
for w= 1:length(totloadL2)
   totloadL2(w)= totloadL2(w)/1000; 
end
for w= 1:length(totloadL3)
   totloadL3(w)= totloadL3(w)/1000; 
end
p2= totloadL2;
p3= totloadL3;

end
   
        
        function [t2,p2,t3,p3] = random(app,nL2,nL3,l2x,l2m,l3x,l3m,c,d2,d3,sc)
numL2 = nL2;
numL3 = nL3;
totloadL2= zeros(1,40);
totloadL3= zeros(1,100);
L2useMax=l2x;
L2useMin=l2m;
L3useMax=l3x;
L3useMin=l3m;
L3use=0;
L2use=0;

%assume BESS starts off full @ 100kWh
%assume 3h to charge 100kW BESS, but that could be changable 
%assume max bess is 90%, and min bess is 10%
%assume car's battery capacity is 100kWh
%assume chargers are always in use at full capacity 

%time array
n2=zeros(1,40);
n3=zeros(1,100);
% state of charge 
soc= sc;
%for index
i=1;
%totcharge time (input)
ct=c;
%tot dicharge time forL3(assumption made from research)
dt = d3;
index=0;


%L3 structure 
while index<=24
   
   %charging
  if (L3use<=.7)
   if soc<=10
     while soc<90 
        if (90> soc)&&(soc>=80)
            %soc*battery capacity
          totloadL3(i) = .9*100000*numL3;
          n3(i)=n3(i-1)+0.21*ct;
          i=i+1;
          soc= soc+10;
        elseif (80> soc)&&(soc>=70)
          totloadL3(i) = .8*100000*numL3;
          n3(i)=n3(i-1)+0.2*ct;
          i=i+1;
          soc= soc+10;
        elseif  (70> soc)&&(soc>=60)
          totloadL3(i) = .7*100000*numL3;
          n3(i)=n3(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (60> soc)&&(soc>=50)
          totloadL3(i) =.6*100000*numL3 ;
          n3(i)=n3(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        elseif (50> soc)&&(soc>=40)
          totloadL3(i) = .5*100000*numL3;
          n3(i)=n3(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        elseif (40> soc)&&(soc>=30)
          totloadL3(i) = .4*100000*numL3;
          n3(i)=n3(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (30> soc)&&(soc>=20)
          totloadL3(i) = .3*100000*numL3;
          n3(i)=n3(i-1)+0.09*ct;
          i=i+1;
          soc= soc+10;
        elseif (20> soc)&&(soc>=10)
          totloadL3(i) = .2*100000*numL3;
          n3(i)= n3(i-1)+0.08*ct;
          i=i+1;
          soc= soc+10;
        end 
     end
   continue
   end
  else
    if soc<=50
     while soc<90 
        if (90> soc)&&(soc>=80)
            %soc*battery capacity
          totloadL3(i) = .9*100000*numL3;
          n3(i)=n3(i-1)+0.21*ct;
          i=i+1;
          soc= soc+10;
        elseif (80> soc)&&(soc>=70)
          totloadL3(i) = .8*100000*numL3;
          n3(i)=n3(i-1)+0.2*ct;
          i=i+1;
          soc= soc+10;
        elseif  (70> soc)&&(soc>=60)
          totloadL3(i) = .7*100000*numL3;
          n3(i)=n3(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (60> soc)&&(soc>=50)
          totloadL3(i) =.6*100000*numL3 ;
          n3(i)=n3(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        end 
     end
   continue
   end  
  end
   
  %Dishcarging 
  % takes 80 mins to discharge = 1.3h
  
  while soc>10 
     
      %implementing the L3 %of usage
 if i>1
   if (index>=0)&&(index<5)
       L3use= L3useMin;
   elseif (index>=5)&&(index<10)
       L3use=0.7;
   elseif (index>=10)&&(index<15)
       L3use=0.82;
   elseif(index>=15)&&(index<20)
       L3use=L3useMax;
       
   elseif(index>=20)&&(index<24)
       L3use=0.85;
   end
 else
      L3use= L3useMin;
 end
 if (L3use<=.7)
   if (90>= soc)&&(soc>80)&&(i==1)
          totloadL3(i) = .9*100000*numL3*L3use;
          n3(i)=0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif (90>= soc)&&(soc>80)&&(i>1)
          totloadL3(i) = .9*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif (80>= soc)&&(soc>70)
          totloadL3(i) = .8*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif  (70>= soc)&&(soc>60)
          totloadL3(i) = .7*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.053*dt;
          i=i+1;
          soc= soc-10;
   elseif (60>= soc)&&(soc>50)
          totloadL3(i) =.6*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.046*dt;
          i=i+1;
          soc= soc-10;
   elseif (50>= soc)&&(soc>40)
          totloadL3(i) = .5*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif (40>= soc)&&(soc>30)
          totloadL3(i) = .4*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.06*dt;
          i=i+1;
          soc= soc-10;
   elseif (30>= soc)&&(soc>20)
          totloadL3(i) = .3*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.15*dt;
          i=i+1;
          soc= soc-10;
   elseif (20>= soc)&&(soc>10)
          totloadL3(i) = .2*100000*numL3*L3use;
          n3(i)= n3(i-1)+0.11*dt;
          i=i+1;
          soc= soc-10;            
   end  
   continue
 elseif (L3use>.7)&&(soc>50)
     if (90>= soc)&&(soc>80)&&(i==1)
          totloadL3(i) = .9*100000*numL3*L3use;
          n3(i)=0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif (90>= soc)&&(soc>80)
          totloadL3(i) = .9*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif (80>= soc)&&(soc>70)
          totloadL3(i) = .8*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.067*dt;
          i=i+1;
          soc= soc-10;
   elseif  (70>= soc)&&(soc>60)
          totloadL3(i) = .7*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.053*dt;
          i=i+1;
          soc= soc-10;
   elseif (60>= soc)&&(soc>50)
          totloadL3(i) =.6*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.046*dt;
          i=i+1;
          soc= soc-10;
   elseif (50>= soc)&&(soc>=40)
          totloadL3(i) = .5*100000*numL3*L3use;
          n3(i)=n3(i-1)+0.067*dt;
          i=i+1;
          soc= soc-10;
     end
 elseif (L3use>.7)&&(soc<=50)
     break
  end
  end
  index=n3(i-1);
end
% figure 
% plot(n3,totloadL3);
% xlim([1 24]),xlabel('Time(h)'),ylabel('Power(W)'),title('Power(w) vs Time(h) of L3')

%/////////////////////////////////////////////////////////////////////////////

%structure of L2
soc=sc;
i=1;
dt2=d2;%dischrage time for L2 based on research
index=0;
while index<=24
   %charging
 if (L2use<=.82)
   if soc<=10
     while soc<90 
        if (90> soc)&&(soc>=80)
            %soc*battery capacity
          totloadL2(i) = .9*100000*numL2;
          n2(i)=n2(i-1)+0.21*ct;
          i=i+1;
          soc= soc+10;
        elseif (80> soc)&&(soc>=70)
          totloadL2(i) = .8*100000*numL2;
          n2(i)=n2(i-1)+0.2*ct;
          i=i+1;
          soc= soc+10;
        elseif  (70> soc)&&(soc>=60)
          totloadL2(i) = .7*100000*numL2;
          n2(i)=n2(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (60> soc)&&(soc>=50)
          totloadL2(i) =.6*100000*numL2;
          n2(i)=n2(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        elseif (50> soc)&&(soc>=40)
          totloadL2(i) = .5*100000*numL2;
          n2(i)=n2(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        elseif (40> soc)&&(soc>=30)
          totloadL2(i) = .4*100000*numL2;
          n2(i)=n2(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (30> soc)&&(soc>=20)
          totloadL2(i) = .3*100000*numL2;
          n2(i)=n2(i-1)+0.09*ct;
          i=i+1;
          soc= soc+10;
        elseif (20> soc)&&(soc>=10)
          totloadL2(i) = .2*100000*numL2;
          n2(i)=n2(i-1)+0.08*ct;
          i=i+1;
          soc= soc+10;
        end 
     end
   continue
   end
 else
     if soc<=50
       while soc<90 
        if (90> soc)&&(soc>=80)
            %soc*battery capacity
          totloadL2(i) = .9*100000*numL2;
          n2(i)=n2(i-1)+0.21*ct;
          i=i+1;
          soc= soc+10;
        elseif (80> soc)&&(soc>=70)
          totloadL2(i) = .8*100000*numL2;
          n2(i)=n2(i-1)+0.2*ct;
          i=i+1;
          soc= soc+10;
        elseif  (70> soc)&&(soc>=60)
          totloadL2(i) = .7*100000*numL2;
          n2(i)=n2(i-1)+0.1*ct;
          i=i+1;
          soc= soc+10;
        elseif (60> soc)&&(soc>=50)
          totloadL2(i) =.6*100000*numL2;
          n2(i)=n2(i-1)+0.11*ct;
          i=i+1;
          soc= soc+10;
        end
       end
     end
 end
  %Dishcarging 
  % takes 15h to charge 100kW tesla
  
  while soc>10 
      %implementing the %of L2 usage
 if i>1
   if (index>=0)&&(index<=4)
       L2use= L2useMin;
   elseif (index>4)&&(index<=7) 
       L2use=0.83;
   elseif (index>7)&&(index<=10)
       L2use= 0.837890569;
   elseif (index>10)&&(index<=14)
       L2use= 0.865859363;
   elseif(index>14)&&(index<=17)
       L2use=0.847305204;
   elseif(index>17)&&(index<=20)
       L2use=L2useMax;
   elseif(index>20)&&(index<=22)
       L2use=0.854947425;
   elseif(index>22)&&(index<=24)
       L2use= 0.737335899;
   end
 else
     L2use= L2useMin;
 end
  if (L2use<=.82) 
   if (90>= soc)&&(soc>80)&&(i==1)
          totloadL2(i) = .9*100000*numL2*L2use;
          n2(i)=0.042*dt2;
          i=i+1;
          soc= soc-10;
   elseif (90>= soc)&&(soc>80)&&(i>1)
          totloadL2(i) = .9*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.042*dt2;
          i=i+1;
          soc= soc-10;
   elseif (80>= soc)&&(soc>70)
          totloadL2(i) = .8*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.0167*dt2;
          i=i+1;
          soc= soc-10;
   elseif  (70>= soc)&&(soc>60)
          totloadL2(i) = .7*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.03*dt2;
          i=i+1;
          soc= soc-10;
   elseif (60>= soc)&&(soc>50)
          totloadL2(i) =.6*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.05*dt2;
          i=i+1;
          soc= soc-10;
   elseif (50>= soc)&&(soc>40)
          totloadL2(i) = .5*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.042*dt2;
          i=i+1;
          soc= soc-10;
   elseif (40>= soc)&&(soc>30)
          totloadL2(i) = .4*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.058*dt2;
          i=i+1;
          soc= soc-10;
   elseif (30>= soc)&&(soc>20)
          totloadL2(i) = .3*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.1*dt2;
          i=i+1;
          soc= soc-10;
   elseif (20>= soc)&&(soc>10)
          totloadL2(i) = .2*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.26*dt2;
          i=i+1;
          soc= soc-10;       
   end   
   elseif (L2use>.82)&&(soc>50)
   if (90>= soc)&&(soc>80)&&(i==1)
          totloadL2(i) = .9*100000*numL2*L2use;
          n2(i)=0.042*dt2;
          i=i+1;
          soc= soc-10;
   elseif (90>= soc)&&(soc>80)&&(i>1)
          totloadL2(i) = .9*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.042*dt2;
          i=i+1;
          soc= soc-10;
   elseif (80>= soc)&&(soc>70)
          totloadL2(i) = .8*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.0167*dt2;
          i=i+1;
          soc= soc-10;
   elseif  (70>= soc)&&(soc>60)
          totloadL2(i) = .7*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.03*dt2;
          i=i+1;
          soc= soc-10;
   elseif (60>= soc)&&(soc>50)
          totloadL2(i) =.6*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.05*dt2;
          i=i+1;
          soc= soc-10;
   elseif (50>= soc)&&(soc>40)
          totloadL2(i) = .5*100000*numL2*L2use;
          n2(i)=n2(i-1)+0.042*dt2;
          i=i+1;
          soc= soc-10;
   end
   elseif (L2use>.82)&&(soc<=50)
      break
  end
  end
  index=n2(i-1);
end 
% plot(n3,totloadL3,'b',n2, totloadL2,'r')
% xlabel('Time(h)'),ylabel('Power(W)'),title('Average case on an hourly basis')
% axis tight, grid on
% xlim([1 24]),ylim([10000 inf])
% legend('L3','L2')
t2=n2;
t3 = n3;
for w= 1:length(totloadL2)
   totloadL2(w)= totloadL2(w)/1000; 
end
for w= 1:length(totloadL3)
   totloadL3(w)= totloadL3(w)/1000; 
end
p2= totloadL2;
p3= totloadL3;

        end
        
        function [tb2,pb2,tb3,pb3] = bestCase(app,nl2,nl3,c,d2,d3)
            % variables defention:

%number of each charger (input)
numL2 = nl2;
numL3 = nl3;
%array for total load
totloadL2= zeros(1,10);
totloadL3= zeros(1,20);
%time array
n2=zeros(1,10);
n3=zeros(1,20);
% state of charge ( intial value from assumption)
soc= 10;
%for index
i=1;
%totcharge time (input)
ct=c;
%tot dicharge time forL3(assumption made from research)
dt = d3;
index=0;

%______________________________________________________________________

%L3 structure:


while index<24
    %L3 discharging function
    while soc>10
        if (index<= 12+dt*0.63)
            if (90>= soc)&&(soc>80)
                  totloadL3(i) = .9*100000*numL3;
                  n3(i)=n3(i-1)+0.067*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (80>= soc)&&(soc>70)
                  totloadL3(i) = .8*100000*numL3;
                  n3(i)=n3(i-1)+0.067*dt;
                  i=i+1;
                  soc= soc-10;
           elseif  (70>= soc)&&(soc>60)
                  totloadL3(i) = .7*100000*numL3;
                  n3(i)=n3(i-1)+0.053*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (60>= soc)&&(soc>50)
                  totloadL3(i) =.6*100000*numL3;
                  n3(i)=n3(i-1)+0.046*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (50>= soc)&&(soc>40)
                  totloadL3(i) = .5*100000*numL3;
                  n3(i)=n3(i-1)+0.067*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (40>= soc)&&(soc>30)
                  totloadL3(i) = .4*100000*numL3;
                  n3(i)=n3(i-1)+0.06*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (30>= soc)&&(soc>20)
                  totloadL3(i) = .3*100000*numL3;
                  n3(i)=n3(i-1)+0.15*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (20>= soc)&&(soc>10)
                  totloadL3(i) = .2*100000*numL3;
                  n3(i)= n3(i-1)+0.11*dt;
                  i=i+1;
                  soc= soc-10;            
            end 
            index = n3(i-1);
        else
            if index>=24
                break
            else
           totloadL3(i) =0;
           n3(i) = n3(i-1)+1;
           index = n3(i);
           i=i+1;
            end
        end
    end
    
    % L3 charging function
    while soc<=10 || index<24
        if (index>=12) &&(index<12+dt*0.62) 
         while soc<90 
            if (90> soc)&&(soc>=80)
                %soc*battery capacity
              totloadL3(i) = .9*100000*numL3;
              n3(i)=n3(i-1)+0.21*ct;
              i=i+1;
              soc= soc+10;
            elseif (80> soc)&&(soc>=70)
              totloadL3(i) = .8*100000*numL3;
              n3(i)=n3(i-1)+0.2*ct;
              i=i+1;
              soc= soc+10;
            elseif  (70> soc)&&(soc>=60)
              totloadL3(i) = .7*100000*numL3;
              n3(i)=n3(i-1)+0.1*ct;
              i=i+1;
              soc= soc+10;
            elseif (60> soc)&&(soc>=50)
              totloadL3(i) =.6*100000*numL3 ;
              n3(i)=n3(i-1)+0.11*ct;
              i=i+1;
              soc= soc+10;
            elseif (50> soc)&&(soc>=40)
              totloadL3(i) = .5*100000*numL3;
              n3(i)=n3(i-1)+0.11*ct;
              i=i+1;
              soc= soc+10;
            elseif (40> soc)&&(soc>=30)
              totloadL3(i) = .4*100000*numL3;
              n3(i)=n3(i-1)+0.1*ct;
              i=i+1;
              soc= soc+10;
            elseif (30> soc)&&(soc>=20)
              totloadL3(i) = .3*100000*numL3;
              n3(i)=n3(i-1)+0.09*ct;
              i=i+1;
              soc= soc+10;
            elseif (20> soc)&&(soc>=10)
              totloadL3(i) = .2*100000*numL3;
              n3(i)= n3(i-1)+0.08*ct;
              i=i+1;
              soc= soc+10;
            end 
            index = n3(i-1);
         end
        else
            if index >=24
                break
            else
           totloadL3(i) =0;
           n3(i) = i-1;
           index = n3(i);
           i=i+1;
            end
        end
     
    end
  
end

%__________________________________________________________________________

%L2 structure 

%intial soc (input)
soc=10;
i=1;
%dischrage time for L2 based on research
dt=d2;
index=0;
while index<24
    %L2 discharging function
    while soc>10 && index<24
        if (index< 6+dt*.6) 
            if (90>= soc)&&(soc>80)
                  totloadL2(i) = .9*100000*numL2 ;
                  n2(i)=n2(i-1)+0.042*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (80>= soc)&&(soc>70)
                  totloadL2(i) = .8*100000*numL2 ;
                  n2(i)=n2(i-1)+0.0167*dt;
                  i=i+1;
                  soc= soc-10;
           elseif  (70>= soc)&&(soc>60)
                  totloadL2(i) = .7*100000*numL2 ;
                  n2(i)=n2(i-1)+0.03*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (60>= soc)&&(soc>50)
                  totloadL2(i) =.6*100000*numL2 ;
                  n2(i)=n2(i-1)+0.05*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (50>= soc)&&(soc>40)
                  totloadL2(i) = .5*100000*numL2 ;
                  n2(i)=n2(i-1)+0.042*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (40>= soc)&&(soc>30)
                  totloadL2(i) = .4*100000*numL2 ;
                  n2(i)=n2(i-1)+0.058*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (30>= soc)&&(soc>20)
                  totloadL2(i) = .3*100000*numL2 ;
                  n2(i)=n2(i-1)+0.1*dt;
                  i=i+1;
                  soc= soc-10;
           elseif (20>= soc)&&(soc>10)
                  totloadL2(i) = .2*100000*numL2 ;
                  n2(i)=n2(i-1)+0.26*dt;
                  i=i+1;
                  soc= soc-10;       
           end   
            index = n2(i-1);
        else
            if index>=24
                break
            else
           totloadL2(i) =0;
           n2(i) = n2(i-1)+1;
           index = n2(i);
           i=i+1;
            end
        end
    end
    
    % L2 charging function
    while soc<=10 
        if (index>=6) &&(index<6+dt*.6)
         while soc<90 
            if (90> soc)&&(soc>=80)
                %soc*battery capacity
              totloadL2(i) = .9*100000*numL2;
              n2(i)=n2(i-1)+0.21*ct;
              i=i+1;
              soc= soc+10;
            elseif (80> soc)&&(soc>=70)
              totloadL2(i) = .8*100000*numL2;
              n2(i)=n2(i-1)+0.2*ct;
              i=i+1;
              soc= soc+10;
            elseif  (70> soc)&&(soc>=60)
              totloadL2(i) = .7*100000*numL2;
              n2(i)=n2(i-1)+0.1*ct;
              i=i+1;
              soc= soc+10;
            elseif (60> soc)&&(soc>=50)
              totloadL2(i) =.6*100000*numL2 ;
              n2(i)=n2(i-1)+0.11*ct;
              i=i+1;
              soc= soc+10;
            elseif (50> soc)&&(soc>=40)
              totloadL2(i) = .5*100000*numL2;
              n2(i)=n2(i-1)+0.11*ct;
              i=i+1;
              soc= soc+10;
            elseif (40> soc)&&(soc>=30)
              totloadL2(i) = .4*100000*numL2;
              n2(i)=n2(i-1)+0.1*ct;
              i=i+1;
              soc= soc+10;
            elseif (30> soc)&&(soc>=20)
              totloadL2(i) = .3*100000*numL2;
              n2(i)=n2(i-1)+0.09*ct;
              i=i+1;
              soc= soc+10;
            elseif (20> soc)&&(soc>=10)
              totloadL2(i) = .2*100000*numL2;
              n2(i)= n2(i-1)+0.08*ct;
              i=i+1;
              soc= soc+10;
            end 
            index = n2(i-1);
         end
        else
            if index >=24
                break
            else
           totloadL2(i) =0;
           n2(i) = i-1;
           index = n2(i);
           i=i+1;
            end
        end
    end
end
%plot(n3,totloadL3,'b',n2,totloadL2,'r')
% xlabel('Time(h)'),ylabel('Power(W)'),title('best case on an hourly basis')
% axis tight
% xlim([1 24]),ylim([0 inf])
% legend('L3','L2')
% grid on
tb2=n2;
tb3 = n3;
for w= 1:length(totloadL2)
   totloadL2(w)= totloadL2(w)/1000; 
end
for w= 1:length(totloadL3)
   totloadL3(w)= totloadL3(w)/1000; 
end
pb2= totloadL2;
pb3=totloadL3;

            
        end
        
%         function [t,L2,L3] = LineupModel(app,nL2,nL3,ct,dt2,dt3)
%                             % this model integrates the pre-exisitng python Line up formation
%                 % application into MATLAB
%                 % this is done by loading python into matlab (visit only resources for more
%                 % info) and then displaying the resulting graphs into MATLAB
%                 
%                 %loading python into MATLAB (uncomment the following and change the
%                 %directory according to the location of your python file:
%                 %pyenv('Version','C:\Program Files\Spyder\Python\python.exe');
%                 
%                 % for the purposes of editing the python code and integrating it again into
%                 % matlab we must keep reloading it.
%                 % "mymod2" is the name of the python application
%                 %visit matlab's online resources if any errors are encountered 
%                 %reload:
%                 % mod2 = py.importlib.import_module('mymod2');
%                 % py.importlib.reload(mod2);
%                 
%                 %start of function 
%                 %nl2- number of L2
%                 %nl3- number of L3
%                 %ct - charge time for bess
%                 %dt2-  dishcarge time for L2 (mins )
%                 %dt3-  dishcarge time for L3 (mins )
%                 out= py.mymod2.bigF(nL2,nL3,ct,dt2,dt3);
%                 % the following is the number of hours 
%                 t= 0:24;
%                 %following is the line up for L2
%                 totL2 = out(1);
%                 totL3 = out(2);
%                 
%                 %converting from list to array 
%                 cL2 = cell(totL2);
%                 cL3 = cell(totL3);
%                 cellL2 = cellfun(@char,cell(totL2),'UniformOutput',false);
%                 cellL3 = cellfun(@char,cell(totL3),'UniformOutput',false);
%                 cell2= cell2mat(cellL2);
%                 cell3 = cell2mat(cellL3);
%                 L2 = str2num(cell2);
%                 L3 = str2num(cell3);
% 
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

           soc= app.initialSOCEditField.Value;
           numL2 = app.L2chargersEditField.Value;
           numL3 =app.L3chargersEditField.Value;
           ct= app.ChargetimehEditField.Value;
           dt2 = app.DischargetimeL2hEditField.Value;
           dt3 = app.DischargetimeL3hEditField.Value;
           l2usemax= app.MAXofL2inuseEditField.Value;
           l2usemin= app.MINofL2inuseEditField.Value;
           l3usemax =app.MAXofL3inuseEditField.Value;
           l3usemin = app.MINofL3inuseEditField.Value;
           [t2,p2,t3,p3] = WC2(app,numL2,numL3,ct,dt2,dt3,soc);
           plot(app.UIAxes2,t3,p3,'b',t2,p2,'r');
           xlim(app.UIAxes2,[1 24]),ylim(app.UIAxes2,[0 inf]),legend(app.UIAxes2,'L3','L2')
           [ta2,pa2,ta3,pa3] = random(app,numL2,numL3,l2usemax,l2usemin,l3usemax,l3usemin,ct,dt2,dt3,soc);
           plot(app.UIAxes,ta3,pa3,'b',ta2,pa2,'r');
           xlim(app.UIAxes,[1 24]),ylim(app.UIAxes,[0 inf]),legend(app.UIAxes,'L3','L2')
           [tb2,pb2,tb3,pb3] = bestCase(app,numL2,numL3,ct,dt2,dt3);
           plot(app.UIAxes3,tb3,pb3,'b',tb2,pb2,'r');
           xlim(app.UIAxes3,[1 24]),ylim(app.UIAxes3,[0 inf]),legend(app.UIAxes3,'L3','L2')
%            [t,L2,L3] = LineupModel(app,numL2,numL3,ct*60,dt2*60,dt3*60);
%            b=transpose(L2);
%            bar(app.UIAxes4, b,'r'), xlim(app.UIAxes4,[0 25]), xticks(app.UIAxes4,[0:1:24]), ylim(app.UIAxes4,[0 10])
%            c= transpose(L3);
%            bar(app.UIAxes5,c), xlim(app.UIAxes5,[0 25]), xticks(app.UIAxes5,[0:1:24]), ylim(app.UIAxes5,[0 10])
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadPredictionTab = uitab(app.TabGroup);
            app.LoadPredictionTab.Title = 'Load Prediction';
            app.UIAxes = uiaxes(app.LoadPredictionTab);
            title(app.UIAxes, 'Average Case')
            xlabel(app.UIAxes, 'Time (h)')
            ylabel(app.UIAxes, 'Power (kW)')
            app.UIAxes2 = uiaxes(app.LoadPredictionTab);
            title(app.UIAxes2, 'Worst Case')
            xlabel(app.UIAxes2, 'Time(h)')
            ylabel(app.UIAxes2, 'Power (kW)')
            app.RUNButton = uibutton(app.LoadPredictionTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.UIAxes3 = uiaxes(app.LoadPredictionTab);
            title(app.UIAxes3, 'Best Case')
            xlabel(app.UIAxes3, 'Time (h)')
            ylabel(app.UIAxes3, 'Power (kW)')
            app.MAXofL2inuseEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.MAXofL2inuseEditFieldLabel.Text = 'MAX % of L2 in use';
            app.MAXofL2inuseEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.MAXofL2inuseEditField.Value = 0.9;
            app.MINofL2inuseEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.MINofL2inuseEditFieldLabel.Text = 'MIN % of L2 in use';
            app.MINofL2inuseEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.MINofL2inuseEditField.Value = 0.6;
            app.ChargetimehEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.ChargetimehEditFieldLabel.Text = 'Charge time (h)';
            app.ChargetimehEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.ChargetimehEditField.Value = 3;
            app.MAXofL3inuseEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.MAXofL3inuseEditFieldLabel.Text = 'MAX % of L3 in use';
            app.MAXofL3inuseEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.MAXofL3inuseEditField.Value = 0.9;
            app.MINofL3inuseEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.MINofL3inuseEditFieldLabel.Text = 'MIN % of L3 in use';
            app.MINofL3inuseEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.MINofL3inuseEditField.Value = 0.3;
            app.DischargetimeL2hEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.DischargetimeL2hEditFieldLabel.Text = 'Discharge time L2 (h)';
            app.DischargetimeL2hEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.DischargetimeL2hEditField.Value = 15;
            app.DischargetimeL3hEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.DischargetimeL3hEditFieldLabel.Text = 'Discharge time L3 (h)';
            app.DischargetimeL3hEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.DischargetimeL3hEditField.Value = 1.3;
            app.initialSOCEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.initialSOCEditFieldLabel.Text = 'initial SOC';
            app.initialSOCEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.initialSOCEditField.Value = 90;
            app.L2chargersEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.L2chargersEditFieldLabel.Text = 'L2 chargers';
            app.L2chargersEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.L2chargersEditField.Value = 2;
            app.L3chargersEditFieldLabel = uilabel(app.LoadPredictionTab);
            app.L3chargersEditFieldLabel.Text = 'L3 chargers';
            app.L3chargersEditField = uieditfield(app.LoadPredictionTab, 'numeric');
            app.L3chargersEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_LoadPredictionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end