classdef MurbCostSavingsCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        MURBInfoLabel                   matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        AverageHourlyLoadProfileLabel   matlab.ui.control.Label
        EVInfoLabel                     matlab.ui.control.Label
        Level2ChargersLabel             matlab.ui.control.Label
        EV2Label                        matlab.ui.control.Label
        EV3Label                        matlab.ui.control.Label
        EV5Label                        matlab.ui.control.Label
        EV4Label                        matlab.ui.control.Label
        EV1Label                        matlab.ui.control.Label
        EV9Label                        matlab.ui.control.Label
        EV7Label                        matlab.ui.control.Label
        EV8Label                        matlab.ui.control.Label
        EV6Label                        matlab.ui.control.Label
        Level3ChargersLabel             matlab.ui.control.Label
        ChargingTimeLabel               matlab.ui.control.Label
        ChargingTimeLabel_2             matlab.ui.control.Label
        ChooseMURBDropDownLabel         matlab.ui.control.Label
        ChooseMURBDropDown              matlab.ui.control.DropDown
        EVTypeDropDown_5Label           matlab.ui.control.Label
        EVType1                         matlab.ui.control.DropDown
        EVTypeDropDown_5Label_2         matlab.ui.control.Label
        EVType2                         matlab.ui.control.DropDown
        EVTypeDropDown_5Label_3         matlab.ui.control.Label
        EVType3                         matlab.ui.control.DropDown
        EVTypeDropDown_5Label_4         matlab.ui.control.Label
        EVType4                         matlab.ui.control.DropDown
        EVTypeDropDown_5Label_5         matlab.ui.control.Label
        EVType5                         matlab.ui.control.DropDown
        EV10Label                       matlab.ui.control.Label
        EVTypeLabel                     matlab.ui.control.Label
        EVType6                         matlab.ui.control.DropDown
        EVTypeLabel_2                   matlab.ui.control.Label
        EVType7                         matlab.ui.control.DropDown
        EVTypeLabel_3                   matlab.ui.control.Label
        EVType8                         matlab.ui.control.DropDown
        EVTypeDropDown_5Label_7         matlab.ui.control.Label
        EVType10                        matlab.ui.control.DropDown
        EVTypeDropDown_5Label_8         matlab.ui.control.Label
        EVType9                         matlab.ui.control.DropDown
        EVModelLabel                    matlab.ui.control.Label
        EVModel1                        matlab.ui.control.DropDown
        EVModelLabel_2                  matlab.ui.control.Label
        EVModel2                        matlab.ui.control.DropDown
        EVModelLabel_4                  matlab.ui.control.Label
        EVModel5                        matlab.ui.control.DropDown
        EVModelLabel_5                  matlab.ui.control.Label
        EVModel3                        matlab.ui.control.DropDown
        EVModelLabel_7                  matlab.ui.control.Label
        EVModel5_3                      matlab.ui.control.DropDown
        EVModelLabel_8                  matlab.ui.control.Label
        EVModel5_4                      matlab.ui.control.DropDown
        EVModelLabel_9                  matlab.ui.control.Label
        EVModel5_5                      matlab.ui.control.DropDown
        EVModelLabel_10                 matlab.ui.control.Label
        EVModel5_6                      matlab.ui.control.DropDown
        EVModelLabel_11                 matlab.ui.control.Label
        EVModel5_7                      matlab.ui.control.DropDown
        ChargeEditFieldLabel            matlab.ui.control.Label
        Charge1                         matlab.ui.control.NumericEditField
        ChargeEditFieldLabel_2          matlab.ui.control.Label
        Charge2                         matlab.ui.control.NumericEditField
        ChargeEditFieldLabel_3          matlab.ui.control.Label
        Charge3                         matlab.ui.control.NumericEditField
        ChargeEditFieldLabel_4          matlab.ui.control.Label
        Charge4                         matlab.ui.control.NumericEditField
        ChargeEditFieldLabel_5          matlab.ui.control.Label
        Charge5                         matlab.ui.control.NumericEditField
        ChargeEditFieldLabel_7          matlab.ui.control.Label
        Charge6                         matlab.ui.control.NumericEditField
        EVModelLabel_12                 matlab.ui.control.Label
        EVModel3_2                      matlab.ui.control.DropDown
        ChargeEditFieldLabel_8          matlab.ui.control.Label
        Charge6_2                       matlab.ui.control.NumericEditField
        ChargeEditFieldLabel_9          matlab.ui.control.Label
        Charge6_3                       matlab.ui.control.NumericEditField
        ChargeEditFieldLabel_10         matlab.ui.control.Label
        Charge6_4                       matlab.ui.control.NumericEditField
        StartDropDownLabel              matlab.ui.control.Label
        StartDropDown                   matlab.ui.control.DropDown
        StartDropDown_2Label            matlab.ui.control.Label
        StartDropDown_2                 matlab.ui.control.DropDown
        StartDropDown_3Label            matlab.ui.control.Label
        StartDropDown_3                 matlab.ui.control.DropDown
        StartDropDown_4Label            matlab.ui.control.Label
        StartDropDown_4                 matlab.ui.control.DropDown
        StartDropDown_5Label            matlab.ui.control.Label
        StartDropDown_5                 matlab.ui.control.DropDown
        StartDropDown_6Label            matlab.ui.control.Label
        StartDropDown_6                 matlab.ui.control.DropDown
        StartDropDown_7Label            matlab.ui.control.Label
        StartDropDown_7                 matlab.ui.control.DropDown
        StartDropDown_8Label            matlab.ui.control.Label
        StartDropDown_8                 matlab.ui.control.DropDown
        StartDropDown_9Label            matlab.ui.control.Label
        StartDropDown_9                 matlab.ui.control.DropDown
        StartDropDown_10Label           matlab.ui.control.Label
        StartDropDown_10                matlab.ui.control.DropDown
        EndDropDown_5Label              matlab.ui.control.Label
        EndDropDown_5                   matlab.ui.control.DropDown
        EndDropDown_4Label              matlab.ui.control.Label
        EndDropDown_4                   matlab.ui.control.DropDown
        EndDropDown_3Label              matlab.ui.control.Label
        EndDropDown_3                   matlab.ui.control.DropDown
        EndDropDown_2Label              matlab.ui.control.Label
        EndDropDown_2                   matlab.ui.control.DropDown
        EndDropDownLabel                matlab.ui.control.Label
        EndDropDown                     matlab.ui.control.DropDown
        EndDropDown_6Label              matlab.ui.control.Label
        EndDropDown_6                   matlab.ui.control.DropDown
        EndDropDown_7Label              matlab.ui.control.Label
        EndDropDown_7                   matlab.ui.control.DropDown
        EndDropDown_8Label              matlab.ui.control.Label
        EndDropDown_8                   matlab.ui.control.DropDown
        EndDropDown_9Label              matlab.ui.control.Label
        EndDropDown_9                   matlab.ui.control.DropDown
        EndDropDown_10Label             matlab.ui.control.Label
        EndDropDown_10                  matlab.ui.control.DropDown
        ChargeEditFieldLabel_11         matlab.ui.control.Label
        Charge6_5                       matlab.ui.control.NumericEditField
        MurbCostSavingsCalculatorLabel  matlab.ui.control.Label
        ImportCustomDataButton          matlab.ui.control.Button
        ExportCustomDataButton_2        matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        DischargingTimeLabel            matlab.ui.control.Label
        ChargingTimeLabel_3             matlab.ui.control.Label
        BESSLabel                       matlab.ui.control.Label
        BESSRatingkWhEditField          matlab.ui.control.NumericEditField
        BESSRatingkWhEditFieldLabel     matlab.ui.control.Label
        StartDropDown_11                matlab.ui.control.DropDown
        StartDropDown_11Label           matlab.ui.control.Label
        EndDropDown_11                  matlab.ui.control.DropDown
        EndDropDown_11Label             matlab.ui.control.Label
        StartDropDown_12                matlab.ui.control.DropDown
        StartDropDown_12Label           matlab.ui.control.Label
        EndDropDown_12                  matlab.ui.control.DropDown
        EndDropDown_12Label             matlab.ui.control.Label
        PeakDemandKWEditField           matlab.ui.control.NumericEditField
        PeakDemandKWEditFieldLabel      matlab.ui.control.Label
        CostofElectricityLabel          matlab.ui.control.Label
        CustomizeratesButton            matlab.ui.control.Button
        CustomerTypeDropDownLabel       matlab.ui.control.Label
        CustomerTypeDropDown            matlab.ui.control.DropDown
        RatesDropDownLabel              matlab.ui.control.Label
        RatesDropDown                   matlab.ui.control.DropDown
        BatteryefficiencyLabel          matlab.ui.control.Label
        BatteryefficiencyEditField      matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        DailyCostLabel                  matlab.ui.control.Label
        MURBelectricitycostEditFieldLabel  matlab.ui.control.Label
        MURBelectricitycostEditField    matlab.ui.control.NumericEditField
        EVelectricitycostEditFieldLabel  matlab.ui.control.Label
        EVelectricitycostEditField      matlab.ui.control.NumericEditField
        BESSSavingsEditFieldLabel       matlab.ui.control.Label
        BESSSavingsEditField            matlab.ui.control.NumericEditField
        DailyCostEditFieldLabel         matlab.ui.control.Label
        DailyCostEditField              matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        DeliveryChargesLabel_2          matlab.ui.control.Label
        DeliveryChargesEditField        matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Property % Description
         RESSettingsDialogApp; %  Electricity Rate Settings Dialog App 
      BUSSettingsDialogApp; %  Electricity Rate Settings Dialog App
      
      loadProfileDay; % Indicates Weekday or Weekend      
      customerType; % Indicates customer type (Business or Residential)      
      customerRateType; %Indicates rate type (Tiered or Time of Use)      
     
      %%%%%%% Residential Settings Parameters %%%%%%%%%%%
        
      RESOnPeakHours; % On-Peak Time of Use Hours (used in settings box)
      RESmidPeakHours; % Mid-Peak Time of Use Hours (used in settings box)
      RESoffPeakHours; % Off-Peak Time of Use Hours (used in settings box)
      
      RESonPeakPrice; % On-Peak Electricity Rate (used in settings box)
      RESmidPeakPrice; % Mid-Peak Electricity Rate (used in settings box)
      RESoffPeakPrice; % Off-Peak Electricity Rate (used in settings box)
      
      REStierRateKW; % Tiered Rate Boundary (used in settings box)
      REStier1Price; % Tier 1 Pricing (used in settings box)
      REStier2Price; % Tier 2 Pricing (used in settings box)
      
      RESdeliveryPrice; % Delivery Charge Pricing (used in settings box)
      
      %%%%%%%%%% Business Settins Parameters %%%%%%%%%%%%
      
      BUSOnPeakHours; % On-Peak Time of Use Hours (used in settings box)
      BUSmidPeakHours; % Mid-Peak Time of Use Hours (used in settings box)
      BUSoffPeakHours; % Off-Peak Time of Use Hours (used in settings box)
      
      BUSonPeakPrice; % On-Peak Electricity Rate (used in settings box)
      BUSmidPeakPrice; % Mid-Peak Electricity Rate (used in settings box)
      BUSoffPeakPrice; % Off-Peak Electricity Rate (used in settings box)
      
      BUStierRateKW; % Tiered Rate Boundary (used in settings box)
      BUStier1Price; % Tier 1 Pricing (used in settings box)
      BUStier2Price; % Tier 2 Pricing (used in settings box)
      
      BUSdeliveryPrice1; % Lowest Delivery Charge Pricing (used in settings box)
      
      BUSdeliveryPrice2; % Delivery Charge Pricing (used in settings box)
      
      
      BUSdeliveryPrice3; % Delivery Charge Pricing (used in settings box)
   
      
      BUSdeliveryPrice4; % Highest Delivery Charge Pricing (used in settings box)
     
      %%%%%%%%%% Temp Function Return Values %%%%%%%%%%%%%%%%%
      
      tempDecimalTime; % Temp return value from 'convertTimetoDecimal' Function
      
    end
    
    methods (Access = private)
        
        function computeOverloadSections(app,time,shavedLoad,MURBLimit)
            
            startTimes = zeros(1,length(shavedLoad));
            endTimes = zeros(1,length(shavedLoad));
            
            % Find Overloaded sections
            overLoad = (shavedLoad>MURBLimit);
            overLoadDiff = diff(overLoad);
            
            startIndex = 1;
            endIndex = 1;
            
            for i = 1:length(overLoadDiff)
                
                if overLoadDiff(i) == 1
                    
                    startTimes(startIndex)=i+1;
                    startIndex = startIndex + 1;
                
                elseif overLoadDiff(i) == -1
                    
                    endTimes(endIndex)=i;
                    endIndex = endIndex + 1;
                    
                end
                
            end
                       
            
            start = nonzeros(startTimes)';
            assignin("base","start",start);
            
            endT = nonzeros(endTimes)';
            assignin("base","endT",endT);
            
                               
            % Add Overloaded Sections to Graph
            
            hold(app.UIAxes,'on');
            for k = 1:length(start)
            
            % Use interpolation to compute the exact crossing (START)
            
            % First Check if adjacent time values are equal (prevent error)
            if time(start(k))==time(start(k)-1)
                % Add in new point (same time value)
                time = [time(1:start(k)-1)' time(start(k)) time(start(k):length(time))']';
                shavedLoad = [shavedLoad(1:start(k)-1) MURBLimit shavedLoad(start(k):length(shavedLoad))];
            
            % Otherwise apply interpolation
            else
                % Obtain interpolated time value (intQX)
                intY = shavedLoad(start(k)-1:start(k));
                intX = time(start(k)-1:start(k));
                intYQ = MURBLimit;
                intXQ = interp1(intY,intX,intYQ);
                
                % Add interpolated interpolated value to existing vector
                time = [time(1:start(k)-1)' intXQ time(start(k):length(time))']';
                shavedLoad = [shavedLoad(1:start(k)-1) MURBLimit shavedLoad(start(k):length(shavedLoad))];
            end
            
            endT = endT + 1;
            
            % Use interpolation to compute the exact crossing (END)
            
            % First Check if adjacent time values are equal (prevent error)
            if time(endT(k))==time(endT(k)+1)
                % Add in new point (same time value)
                time = [time(1:endT(k))' time(endT(k)) time(endT(k)+1:length(time))']';
                shavedLoad = [shavedLoad(1:endT(k)) MURBLimit shavedLoad(endT(k)+1:length(shavedLoad))];
            
            % Otherwise apply interpolation
            else
                % Obtain interpolated time value (intQX)
                intY = shavedLoad(endT(k):endT(k)+1);
                intX = time(endT(k):endT(k)+1);
                intYQ = MURBLimit;
                intXQ = interp1(intY,intX,intYQ);
                
                % Add interpolated interpolated value to existing vector
                time = [time(1:endT(k))' intXQ time(endT(k)+1:length(time))']';
                shavedLoad = [shavedLoad(1:endT(k)) MURBLimit shavedLoad(endT(k)+1:length(shavedLoad))];
            end


            area(app.UIAxes,time(start(k):endT(k)+1),shavedLoad(start(k):endT(k)+1),MURBLimit,'FaceColor','r','ShowBaseLine','off','EdgeColor','r','FaceAlpha',0.5,'DisplayName','MURB Overloaded')  ;
            
            end
            hold(app.UIAxes,'off');
            
            
            if length(start) >= 1
                fig = uifigure;
                uialert(fig,'MURB Power Rating Has Been Exceeded. Consider Adjusting the Battery Settings','Overload Warning','CloseFcn',@(h,e) close(fig)); 
            end
            
            
        end  
    
        function convertTimetoDecimal(app,stringTime)
            timeVec=split(stringTime,":");
            HH=str2double(timeVec(1));
            ss=str2double(timeVec(2))/60;
            app.tempDecimalTime = HH+ss;
        end
      
    end
    
    methods (Access = public)
        
        function updateResidentialElectricityRateData(app,onPeakHours,midPeakHours,offPeakHours,onPeakPrice,midPeakPrice,offPeakPrice,tier1RateKW,tier1Price,tier2Price,deliveryPrice)
            app.RESOnPeakHours = onPeakHours;
            app.RESmidPeakHours = midPeakHours;
            app.RESoffPeakHours = offPeakHours;
            app.RESonPeakPrice = onPeakPrice;
            app.RESmidPeakPrice = midPeakPrice;
            app.RESoffPeakPrice = offPeakPrice;
            
            app.REStierRateKW = tier1RateKW; 
            app.REStier1Price = tier1Price; 
            app.REStier2Price = tier2Price; 
            
            app.RESdeliveryPrice = deliveryPrice;


        end
        
        function updateBusinessElectricityRateData(app,onPeakHours,midPeakHours,offPeakHours,onPeakPrice,midPeakPrice,offPeakPrice,tier1RateKW,tier1Price,tier2Price,deliveryPrice1,deliveryPrice2,deliveryPrice3,deliveryPrice4)
            app.BUSOnPeakHours = onPeakHours;
            app.BUSmidPeakHours = midPeakHours;
            app.BUSoffPeakHours = offPeakHours;
            app.BUSonPeakPrice = onPeakPrice;
            app.BUSmidPeakPrice = midPeakPrice;
            app.BUSoffPeakPrice = offPeakPrice;
            
            app.BUStierRateKW = tier1RateKW; 
            app.BUStier1Price = tier1Price; 
            app.BUStier2Price = tier2Price; 
            
            app.BUSdeliveryPrice1 = deliveryPrice1;
            app.BUSdeliveryPrice2 = deliveryPrice2;
            app.BUSdeliveryPrice3 = deliveryPrice3;
            app.BUSdeliveryPrice4 = deliveryPrice4;
        end         
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
            Load = [365.4
347.625
338.175
336.375
339.525
369.9
405.225
423.225
423.45
439.2
446.625
449.775
440.1
436.5
434.475
454.275
478.35
508.725
529.875
526.725
522.9
485.775
448.875
400.95
];
            TT = timetable(Load,'TimeStep',hours(1));
            T = timetable2table(TT);
            app.UITable.Data = T;
            
             value = app.EVType1.Value;
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel1.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel1.Items = Load;
             end      
            
                value = app.EVType2.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel2.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel2.Items = Load;
            end      
            
               value = app.EVType3.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel3.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel3.Items = Load;
            end     
            
                    value = app.EVType4.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel3_2.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel3_2.Items = Load;
            end      
            
            value = app.EVType5.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel5.Items = Load;
            end      
            
              value = app.EVType6.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_6.Items = Load;
            
                case 'PHEV'
                    Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_6.Items = Load;
            end      
            
             value = app.EVType7.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_5.Items = Load;
            
                case 'PHEV'
                   Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_5.Items = Load;
            end      
             value = app.EVType8.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_4.Items = Load;
            
                case 'PHEV'
                    Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_4.Items = Load;
            end      
              value = app.EVType9.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_3.Items = Load;
            
                case 'PHEV'
                    Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_3.Items = Load;
            end      
                value = app.EVType10.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_7.Items = Load;
            
                case 'PHEV'
                   Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_7.Items = Load;
            end 
            
   % Initialize Time of Use Electricity Rate Data (RESIDENTIAL)
            app.RESOnPeakHours = [7 11 17 19];
            app.RESmidPeakHours = [11 17]; 
            app.RESoffPeakHours = [19 7];           
            app.RESonPeakPrice = 17.6;
            app.RESmidPeakPrice = 11.9;
            app.RESoffPeakPrice = 8.5;
            
            app.REStierRateKW = 1000; 
            app.REStier1Price = 10.1; 
            app.REStier2Price = 11.8; 
            
            app.RESdeliveryPrice = 4.5;
           
            
            % Initialize Time of Use Electricity Rate Data (BUSINESS)
            app.BUSOnPeakHours = [7 11 17 19];
            app.BUSmidPeakHours = [11 17]; 
            app.BUSoffPeakHours = [19 7];           
            app.BUSonPeakPrice = 17.6;
            app.BUSmidPeakPrice = 11.9;
            app.BUSoffPeakPrice = 8.5;
            
            app.BUStierRateKW = 750; 
            app.BUStier1Price = 10.1; 
            app.BUStier2Price = 11.8; 
            
            app.BUSdeliveryPrice1 = 4.7; 
            app.BUSdeliveryPrice2 = 4.8419;
            app.BUSdeliveryPrice3 = 4.7484;
            app.BUSdeliveryPrice4 = 5.351;


        end

        % Value changed function: ChooseMURBDropDown
        function ChooseMURBDropDownValueChanged(app, event)
 value = app.ChooseMURBDropDown.Value;
    app.UITable.Visible = 1;
    switch value
        case 'MURB 1'
            Load = [365.4
347.625
338.175
336.375
339.525
369.9
405.225
423.225
423.45
439.2
446.625
449.775
440.1
436.5
434.475
454.275
478.35
508.725
529.875
526.725
522.9
485.775
448.875
400.95
];
            TT = timetable(Load,'TimeStep',hours(1));
            T = timetable2table(TT);
            app.UITable.Data = T;
            
        case 'MURB 2'
             Load = [107.812 
104.42 
102.953
103.669
111.425
118.798
125.642
130.89
133.913
135.771
138.186
138.076
137.672
136.092
134.228
133.784
136.583
140.692
142.367
140.791
138.553 
133.353 
124.16 
114.258];
            TT = timetable(Load,'TimeStep',hours(1));
            T = timetable2table(TT);
            app.UITable.Data = T;
            
        case 'Custom'
             Load = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0]';
            TT = timetable(Load,'TimeStep',hours(1));
            T = timetable2table(TT);
            app.UITable.Data = T;
            app.UITable.ColumnEditable = [false,true];
            
    end

        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
    
        end

        % Value changed function: Charge1, StartDropDown
        function StartDropDownValueChanged(app, event)
 value = app.EVModel1.Value;
 switch value
     
     case 'Average BEV'
         load = 8*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
     
     
     case 'Audi eTron'
         load = 10*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'Audi eTron Sportback'
       load = 14*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'BMW i3'
        load = 6*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'Chevrolet Bolt EV'
        load = 9.5*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
         load = 5*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Hyundai Kona Electric'
        load = 10*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Jaguar I-PACE'
        load = 13*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Kia Niro Electric'
         load = 10*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
         load = 5*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Nissan LEAF S'
        load = 11.5*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
        load = 8*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Polestar 2'
        load = 11*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Porsche Taycan'
         load = 12*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 9*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
         load = 9*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
         load = 15*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Tesla Model S Performance'
         load = 15*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Tesla Model X Long Range'
        load = 15*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Tesla Model X Performance'
        load = 15*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Tesla Model Y Long Range'
         load = 7.25*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Tesla Model Y Performance'
         load = 7.25*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
        load = 11*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      % PHEV
      
     case 'Average PHEV'
         load = 6*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'A8 L 60 TFSI e quattro tiptronic'
       load = 2*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Audi Q5'
        load = 2*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Bentley Bentayga Hybrid'
         load = 2.5*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'BMW 330e'
         load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'BMW 530e'
        load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'BMW 740e xDrive'
        load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'BMW X3 xDrive30e'
       load = 3.5*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'BMW X5 xDrive40e'
        load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Chrysler Pacifica Hybrid'
       load = 2*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Honda Clarity PHEV'
         load = 2.5*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Hyundai Ioniq PHEV'
         load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Hyundai Sonata PHEV'
         load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Karma Revero GT'
        load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Kia Niro PHEV'
         load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Kia Optima Plug-in Hybrid'
        load = 4*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Lincoln Aviator Grand Touring'
         load = 3.5*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'Mercedes GLC 350e'
        load = 2*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Mini Cooper S E Countryman A114'
        load = 2.25*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
        load = 4*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Polestar 1'
        load = 1*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Porsche Cayenne S E-Hybrid'
        load = 4*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Porchase Panamera E-Hybrid'
         load = 4*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Range Rover PHEV'
         load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Range Rover Sport PHEV'
       load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Subaru Crosstrek Hybrid (PHEV)'
        load = 2*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'Toyota Prius Prime'
         load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
     case 'Toyota RAV4 Prime'
         load = 4.5*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Volvo S90 T8 PHEV'
         load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Volvo XC60 T8 PHEV'
         load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
      
      case 'Volvo XC90 PHEV'
        load = 3*(1-app.Charge1.Value/100);
       l= duration(app.StartDropDown.Value)+hours(load);
      app.EndDropDown.Items = string(l);
            
 end
        end

        % Drop down opening function: ChooseMURBDropDown
        function ChooseMURBDropDownOpening(app, event)
            
        end

        % Callback function
        function EVType1ValueChanged(app, event)
           
        end

        % Callback function
        function EVType1DropDownOpening(app, event)
  
        end

        % Callback function
        function EVType2ValueChanged(app, event)
           
        end

        % Callback function
        function EVType3ValueChanged(app, event)
          
        end

        % Value changed function: EVType1
        function EVModel1DropDownOpening(app, event)
             value = app.EVType1.Value;
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel1.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel1.Items = Load;
             end      
        end

        % Value changed function: EVType2
        function EVModel2DropDownOpening(app, event)
            value = app.EVType2.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel2.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel2.Items = Load;
             end      
        end

        % Value changed function: EVType3
        function EVModel3DropDownOpening(app, event)
               value = app.EVType3.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel3.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel3.Items = Load;
             end      
        end

        % Callback function: EVModel3_2, EVType4
        function EVModel3_2DropDownOpening(app, event)
           value = app.EVType4.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel3_2.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel3_2.Items = Load;
             end      
        end

        % Callback function: EVModel5, EVType5
        function EVModel5DropDownOpening(app, event)
          value = app.EVType5.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5.Items = Load;
            
                case 'PHEV'
                    Load = ["Average PHEV"
"A8 L 60 TFSI e quattro tiptronic"
"Audi Q5"
"Bentley Bentayga Hybrid"
"BMW 330e"
"BMW 530e"
"BMW 740e xDrive"
"BMW X3 xDrive30e"
"BMW X5 xDrive40e"
"Chrysler Pacifica Hybrid"
"Honda Clarity PHEV"
"Hyundai Ioniq PHEV"
"Hyundai Sonata PHEV"
"Karma Revero GT"
"Kia Niro PHEV"
"Kia Optima Plug-in Hybrid"
"Lincoln Aviator Grand Touring"
"Mercedes GLC 350e"
"Mini Cooper S E Countryman A114"
"Mitsubishi Outlander PHEV"
"Polestar 1"
"Porsche Cayenne S E-Hybrid"
"Porchase Panamera E-Hybrid"
"Range Rover PHEV"
"Range Rover Sport PHEV"
"Subaru Crosstrek Hybrid (PHEV)"
"Toyota Prius Prime"
"Toyota RAV4 Prime"
"Volvo S90 T8 PHEV"
"Volvo XC60 T8 PHEV"
"Volvo XC90 PHEV"
];
            app.EVModel5.Items = Load;
             end      
        end

        % Callback function: EVModel5_6, EVType6
        function EVModel5_6DropDownOpening(app, event)
               value = app.EVType6.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_6.Items = Load;
            
                case 'PHEV'
                    Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_6.Items = Load;
             end      
        end

        % Value changed function: EVType7
        function EVModel5_5DropDownOpening(app, event)
              value = app.EVType7.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_5.Items = Load;
            
                case 'PHEV'
                   Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_5.Items = Load;
             end      
        end

        % Value changed function: EVType8
        function EVModel5_4DropDownOpening(app, event)
              value = app.EVType8.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_4.Items = Load;
            
                case 'PHEV'
                    Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_4.Items = Load;
             end      
        end

        % Callback function: EVModel5_3, EVType9
        function EVModel5_3DropDownOpening(app, event)
              value = app.EVType9.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_3.Items = Load;
            
                case 'PHEV'
                    Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_3.Items = Load;
             end      
        end

        % Value changed function: EVType10
        function EVModel5_7DropDownOpening(app, event)
              value = app.EVType10.Value; 
            switch value
        case 'BEV'
            
            Load = ["Average BEV"
"Audi eTron"
"Audi eTron Sportback"
"BMW i3"
"Chevrolet Bolt EV"
"Hyundai Ioniq Electric"
"Hyundai Kona Electric"
"Jaguar I-PACE"
"Kia Niro Electric"
"Mini Cooper S E Hardtop 2 door"
"Nissan LEAF S"
"Nissan LEAF S PLUS"
"Polestar 2"
"Porsche Taycan"
"Tesla Model 3 Long Range"
"Tesla Model 3 Standard Range Plus"
"Tesla Model S Long Range Plus"
"Tesla Model S Performance"
"Tesla Model X Long Range"
"Tesla Model X Performance"
"Tesla Model Y Long Range"
"Tesla Model Y Performance"
"Volvo XC40 AWD BEV"
];
            app.EVModel5_7.Items = Load;
            
                case 'PHEV'
                   Load = [
"Karma Revero GT"
"Mitsubishi Outlander PHEV"
];
            app.EVModel5_7.Items = Load;
             end      
        end

        % Callback function
        function UITableCellEdit(app, event)
          
           
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged2(app, event)
           
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)


            value = app.ChooseMURBDropDown.Value;
               switch value
            case 'MURB 1'
            T = app.UITable.Data;
            x=table2array(T(:,1));
            y=table2array(T(:,2));
            plot(app.UIAxes,x,y);
            a = y(1);
           a2 = a+y(2);
           a3 = a2+y(3);
           a4 = a3+y(4);
           a5 = a4+y(5);
           a6 = a5+y(6);
           a7 = a6+y(7);
           a8 = a7+y(8);
           a9 = a8+y(9);
           a10 = a9+y(10);    
           a11 = a10+y(11);    
           a12 = a11+y(12);   
           a13 = a12+y(13);  
           a14 = a13+y(14);    
           a15 = a14+y(15);    
           a16 = a15+y(16);    
           a17 = a16+y(17);
           a18 = a17+y(18);
           a19 = a18+y(19);
           a20 = a19+y(20);
           a21 = a20+y(21);
           a22 = a21+y(22);
           a23 = a22+y(23);
           a24 = a23+y(24); 
          t = [a,a2,a3,a4,a5,a6,a7,a8,a9,a10,a11,a12,a13,a14,a15,a16,a17,a18,a19,a20,a21,a22,a23,a24];
          
          case 'MURB 2'
            T = app.UITable.Data;
            x=table2array(T(:,1));
            y=table2array(T(:,2));
            plot(app.UIAxes,x,y);
         a = y(1);
           a2 = a+y(2);
           a3 = a2+y(3);
           a4 = a3+y(4);
           a5 = a4+y(5);
           a6 = a5+y(6);
           a7 = a6+y(7);
           a8 = a7+y(8);
           a9 = a8+y(9);
           a10 = a9+y(10);    
           a11 = a10+y(11);    
           a12 = a11+y(12);   
           a13 = a12+y(13);  
           a14 = a13+y(14);    
           a15 = a14+y(15);    
           a16 = a15+y(16);    
           a17 = a16+y(17);
           a18 = a17+y(18);
           a19 = a18+y(19);
           a20 = a19+y(20);
           a21 = a20+y(21);
           a22 = a21+y(22);
           a23 = a22+y(23);
           a24 = a23+y(24);
           
          t = [a,a2,a3,a4,a5,a6,a7,a8,a9,a10,a11,a12,a13,a14,a15,a16,a17,a18,a19,a20,a21,a22,a23,a24];
          
           case 'Custom'
            Load = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0]';   
            TT = timetable(Load,'TimeStep',hours(1));
            T = timetable2table(TT);
            t = app.UITable.Data;
            x=table2array(T(:,1));
            y=table2array(t(:,2));
            plot(app.UIAxes,x,y);
            
            a = y(1);
           a2 = a+y(2);
           a3 = a2+y(3);
           a4 = a3+y(4);
           a5 = a4+y(5);
           a6 = a5+y(6);
           a7 = a6+y(7);
           a8 = a7+y(8);
           a9 = a8+y(9);
           a10 = a9+y(10);    
           a11 = a10+y(11);    
           a12 = a11+y(12);   
           a13 = a12+y(13);  
           a14 = a13+y(14);    
           a15 = a14+y(15);    
           a16 = a15+y(16);    
           a17 = a16+y(17);
           a18 = a17+y(18);
           a19 = a18+y(19);
           a20 = a19+y(20);
           a21 = a20+y(21);
           a22 = a21+y(22);
           a23 = a22+y(23);
           a24 = a23+y(24);
           
          t = [a,a2,a3,a4,a5,a6,a7,a8,a9,a10,a11,a12,a13,a14,a15,a16,a17,a18,a19,a20,a21,a22,a23,a24];
          
               end
               
               value1 = app.RatesDropDown.Value;
               
               switch value1
                 
                   case 'Tiered'  
                     
                value = app.CustomerTypeDropDown.Value;
            
            switch value
                
                case 'Business'
          stop = sum(t<=app.BUStierRateKW);
          
          for i=1:stop
          element = t(i);
              c = element*app.BUStier1Price/100;
               cost = c + (t(end)-element)*app.BUStier2Price/100;
          end

         app.MURBelectricitycostEditField.Value = cost; 
           
                case'Residential'

          stop = sum(t<=app.REStierRateKW);
          
          for i=1:stop
          element = t(i);
              c = element*app.REStier1Price/100;
               cost = c + (t(end)-element)*app.REStier2Price/100;
          end

         app.MURBelectricitycostEditField.Value = cost; 
               
               
            end


                   case'Time of use'
                     value = app.CustomerTypeDropDown.Value;
            
            switch value
                
                case 'Business'
                    
             c=0;
                    
                    for i=app.BUSOnPeakHours(1):app.BUSOnPeakHours(2)
                        element = y(i+1);
                        c = element*app.BUSonPeakPrice/100 + c;
                    end
                        
                    for i=app.BUSOnPeakHours(3):app.BUSOnPeakHours(4)
                        
                        element = y(i+1);
                        c = element*app.BUSonPeakPrice/100 + c;  
                    end
                    for i=app.BUSmidPeakHours(1):app.BUSmidPeakHours(2)
                        
                        element = y(i+1);
                        c = element*app.BUSmidPeakPrice/100 + c;
                    end
                     for i=app.BUSoffPeakHours(1):app.BUSoffPeakHours(2)
                        
                        element = y(i+1);
                        c = element*app.app.BUSoffPeakPrice/100 + c;
                        
                     end
         
        app.MURBelectricitycostEditField.Value = c; 
           
                case'Residential'
                     
                     c=0;
                    
                    for i=app.RESOnPeakHours(1):app.RESOnPeakHours(2)
                        element = y(i+1);
                        c = element*app.RESonPeakPrice/100 + c;
                    end
                        
                    for i=app.RESOnPeakHours(3):app.RESOnPeakHours(4)
                        
                        element = y(i+1);
                        c = element*app.RESonPeakPrice/100 + c;  
                    end
                    for i=app.RESmidPeakHours(1):app.RESmidPeakHours(2)
                        
                        element = y(i+1);
                        c = element*app.RESmidPeakPrice/100 + c;
                    end
                     for i=app.RESoffPeakHours(1):app.RESoffPeakHours(2)
                        
                        element = y(i+1);
                        c = element*app.app.RESoffPeakPrice/100 + c;
                        
                     end
          
         app.MURBelectricitycostEditField.Value = c;
               
               
            end
                     
               
                end     
               

      %app.EVelectricitycostEditField.Value 
    
      s = string(duration(app.StartDropDown.Value));
      f = string(duration(app.EndDropDown.Value));
      
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
    sum1 = 0;

for i = t
    if i<=1
        sum1 = 13.28633255/60 + sum1;
    elseif i<=2
        sum1 = 11.71255896/60 + sum1;
    elseif i<=3
        sum1 = 10.99853774/60 + sum1;
    elseif i<=4
        sum1 = 10.45212264/60 + sum1;
    elseif i<=5
        sum1 = 9.251792453/60 + sum1;
    elseif i<=6
        sum1 = 11.25287736/60 + sum1;
    elseif i<=7
        sum1 = 17.69346698/60 + sum1;
    elseif i<=8
        sum1 = 25.86818396/60 + sum1;
    elseif i<=9
        sum1 = 25.95788915/60 + sum1;     
     elseif i<=10
        sum1 = 26.27402123/60 + sum1;
    elseif i<=11
        sum1 = 25.79295991/60 + sum1;
    elseif i<=12
        sum1 = 23.10417453/60 + sum1;
     elseif i<=13
        sum1 = 21.81304245/60 + sum1;
    elseif i<=14
        sum1 = 20.18597877/60 + sum1;
    elseif i<=15
        sum1 = 20.24576651/60 + sum1;
    elseif i<=16
        sum1 = 22.24021226/60 + sum1;
    elseif i<=17
        sum1 = 26.44505896/60 + sum1;
    elseif i<=18
        sum1 = 29.50470519/60 + sum1;
    elseif i<=19
        sum1 = 29.53410377/60 + sum1;
    elseif i<=20
        sum1 = 28.19198113/60 + sum1;
    elseif i<=21
        sum1 = 26.25326651/60 + sum1;
    elseif i<=22
        sum1 = 21.59908019/60 + sum1;
    elseif i<=23
        sum1 = 17.52544811/60 + sum1;     
     elseif i<=24
        sum1 = 14.81475236/60 + sum1;
    end
end
 s = string(duration(app.StartDropDown_2.Value));
      f = string(duration(app.EndDropDown_2.Value));
      
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
    sum2 = 0;

for i = t
    if i<=1
        sum2 = 13.28633255/60 + sum2;
    elseif i<=2
        sum2 = 11.71255896/60 + sum2;
    elseif i<=3
        sum2 = 10.99853774/60 + sum2;
    elseif i<=4
        sum2 = 10.45212264/60 + sum2;
    elseif i<=5
        sum2 = 9.251792453/60 + sum2;
    elseif i<=6
        sum2 = 11.25287736/60 + sum2;
    elseif i<=7
        sum2 = 17.69346698/60 + sum2;
    elseif i<=8
        sum2 = 25.86818396/60 + sum2;
    elseif i<=9
        sum2 = 25.95788915/60 + sum2;     
     elseif i<=10
        sum2 = 26.27402123/60 + sum2;
    elseif i<=11
        sum2 = 25.79295991/60 + sum2;
    elseif i<=12
        sum2 = 23.10417453/60 + sum2;
     elseif i<=13
        sum2 = 21.81304245/60 + sum2;
    elseif i<=14
        sum2 = 20.18597877/60 + sum2;
    elseif i<=15
        sum2 = 20.24576651/60 + sum2;
    elseif i<=16
        sum2 = 22.24021226/60 + sum2;
    elseif i<=17
        sum2 = 26.44505896/60 + sum2;
    elseif i<=18
        sum2 = 29.50470519/60 + sum2;
    elseif i<=19
        sum2 = 29.53410377/60 + sum2;
    elseif i<=20
        sum2 = 28.19198113/60 + sum2;
    elseif i<=21
        sum2 = 26.25326651/60 + sum2;
    elseif i<=22
        sum2 = 21.59908019/60 + sum2;
    elseif i<=23
        sum2 = 17.52544811/60 + sum2;     
     elseif i<=24
        sum2 = 14.81475236/60 + sum2;
    end
end

 s = string(duration(app.StartDropDown_3.Value));
f = string(duration(app.EndDropDown_3.Value));

      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
     sum3 = 0;

for i = t
    if i<=1
        sum3 = 13.28633255/60 + sum3;
    elseif i<=2
        sum3 = 11.71255896/60 + sum3;
    elseif i<=3
        sum3 = 10.99853774/60 + sum3;
    elseif i<=4
        sum3 = 10.45212264/60 + sum3;
    elseif i<=5
        sum3 = 9.251792453/60 + sum3;
    elseif i<=6
        sum3 = 11.25287736/60 + sum3;
    elseif i<=7
        sum3 = 17.69346698/60 + sum3;
    elseif i<=8
        sum3 = 25.86818396/60 + sum3;
    elseif i<=9
        sum3 = 25.95788915/60 + sum3;     
     elseif i<=10
        sum3 = 26.27402123/60 + sum3;
    elseif i<=11
        sum3 = 25.79295991/60 + sum3;
    elseif i<=12
        sum3 = 23.10417453/60 + sum3;
     elseif i<=13
        sum3 = 21.81304245/60 + sum3;
    elseif i<=14
        sum3 = 20.18597877/60 + sum3;
    elseif i<=15
        sum3 = 20.24576651/60 + sum3;
    elseif i<=16
        sum3 = 22.24021226/60 + sum3;
    elseif i<=17
        sum3 = 26.44505896/60 + sum3;
    elseif i<=18
        sum3 = 29.50470519/60 + sum3;
    elseif i<=19
        sum3 = 29.53410377/60 + sum3;
    elseif i<=20
        sum3 = 28.19198113/60 + sum3;
    elseif i<=21
        sum3 = 26.25326651/60 + sum3;
    elseif i<=22
        sum3 = 21.59908019/60 + sum3;
    elseif i<=23
        sum3 = 17.52544811/60 + sum3;     
     elseif i<=24
        sum3 = 14.81475236/60 + sum3;
    end
end

 s = string(duration(app.StartDropDown_4.Value));
 f = string(duration(app.EndDropDown_4.Value));
 
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
   sum4 = 0;

for i = t
    if i<=1
        sum4 = 13.28633255/60 + sum4;
    elseif i<=2
        sum4 = 11.71255896/60 + sum4;
    elseif i<=3
        sum4 = 10.99853774/60 + sum4;
    elseif i<=4
        sum4 = 10.45212264/60 + sum4;
    elseif i<=5
        sum4 = 9.251792453/60 + sum4;
    elseif i<=6
        sum4 = 11.25287736/60 + sum4;
    elseif i<=7
        sum4 = 17.69346698/60 + sum4;
    elseif i<=8
        sum4 = 25.86818396/60 + sum4;
    elseif i<=9
        sum4 = 25.95788915/60 + sum4;     
     elseif i<=10
        sum4 = 26.27402123/60 + sum4;
    elseif i<=11
        sum4 = 25.79295991/60 + sum4;
    elseif i<=12
        sum4 = 23.10417453/60 + sum4;
     elseif i<=13
        sum4 = 21.81304245/60 + sum4;
    elseif i<=14
        sum4 = 20.18597877/60 + sum4;
    elseif i<=15
        sum4 = 20.24576651/60 + sum4;
    elseif i<=16
        sum4 = 22.24021226/60 + sum4;
    elseif i<=17
        sum4 = 26.44505896/60 + sum4;
    elseif i<=18
        sum4 = 29.50470519/60 + sum4;
    elseif i<=19
        sum4 = 29.53410377/60 + sum4;
    elseif i<=20
        sum4 = 28.19198113/60 + sum4;
    elseif i<=21
        sum4 = 26.25326651/60 + sum4;
    elseif i<=22
        sum4 = 21.59908019/60 + sum4;
    elseif i<=23
        sum4 = 17.52544811/60 + sum4;     
     elseif i<=24
        sum4 = 14.81475236/60 + sum4;
    end
end

 s = string(duration(app.StartDropDown_5.Value));
 f = string(duration(app.EndDropDown_5.Value));
 
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
     sum5 = 0;

for i = t
    if i<=1
        sum5 = 13.28633255/60 + sum5;
    elseif i<=2
        sum5 = 11.71255896/60 + sum5;
    elseif i<=3
        sum5 = 10.99853774/60 + sum5;
    elseif i<=4
        sum5 = 10.45212264/60 + sum5;
    elseif i<=5
        sum5 = 9.251792453/60 + sum5;
    elseif i<=6
        sum5 = 11.25287736/60 + sum5;
    elseif i<=7
        sum5 = 17.69346698/60 + sum5;
    elseif i<=8
        sum5 = 25.86818396/60 + sum5;
    elseif i<=9
        sum5 = 25.95788915/60 + sum5;     
     elseif i<=10
        sum5 = 26.27402123/60 + sum5;
    elseif i<=11
        sum5 = 25.79295991/60 + sum5;
    elseif i<=12
        sum5 = 23.10417453/60 + sum5;
     elseif i<=13
        sum5 = 21.81304245/60 + sum5;
    elseif i<=14
        sum5 = 20.18597877/60 + sum5;
    elseif i<=15
        sum5 = 20.24576651/60 + sum5;
    elseif i<=16
        sum5 = 22.24021226/60 + sum5;
    elseif i<=17
        sum5 = 26.44505896/60 + sum5;
    elseif i<=18
        sum5 = 29.50470519/60 + sum5;
    elseif i<=19
        sum5 = 29.53410377/60 + sum5;
    elseif i<=20
        sum5 = 28.19198113/60 + sum5;
    elseif i<=21
        sum5 = 26.25326651/60 + sum5;
    elseif i<=22
        sum5 = 21.59908019/60 + sum5;
    elseif i<=23
        sum5 = 17.52544811/60 + sum5;     
     elseif i<=24
        sum5 = 14.81475236/60 + sum5;
    end
end

 s = string(duration(app.StartDropDown_6.Value));
 f = string(duration(app.EndDropDown_6.Value));
 
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;

      sum6 = 0;

for i = t
    if i<=1
        sum6 = 13.28633255/60 + sum6;
    elseif i<=2
        sum6 = 11.71255896/60 + sum6;
    elseif i<=3
        sum6 = 10.99853774/60 + sum6;
    elseif i<=4
        sum6 = 10.45212264/60 + sum6;
    elseif i<=5
        sum6 = 9.251792453/60 + sum6;
    elseif i<=6
        sum6 = 11.25287736/60 + sum6;
    elseif i<=7
        sum6 = 17.69346698/60 + sum6;
    elseif i<=8
        sum6 = 25.86818396/60 + sum6;
    elseif i<=9
        sum6 = 25.95788915/60 + sum6;     
     elseif i<=10
        sum6 = 26.27402123/60 + sum6;
    elseif i<=11
        sum6 = 25.79295991/60 + sum6;
    elseif i<=12
        sum6 = 23.10417453/60 + sum6;
     elseif i<=13
        sum6 = 21.81304245/60 + sum6;
    elseif i<=14
        sum6 = 20.18597877/60 + sum6;
    elseif i<=15
        sum6 = 20.24576651/60 + sum6;
    elseif i<=16
        sum6 = 22.24021226/60 + sum6;
    elseif i<=17
        sum6 = 26.44505896/60 + sum6;
    elseif i<=18
        sum6 = 29.50470519/60 + sum6;
    elseif i<=19
        sum6 = 29.53410377/60 + sum6;
    elseif i<=20
        sum6 = 28.19198113/60 + sum6;
    elseif i<=21
        sum6 = 26.25326651/60 + sum6;
    elseif i<=22
        sum6 = 21.59908019/60 + sum6;
    elseif i<=23
        sum6 = 17.52544811/60 + sum6;     
     elseif i<=24
        sum6 = 14.81475236/60 + sum6;
    end
end

 s = string(duration(app.StartDropDown_7.Value));
 f = string(duration(app.EndDropDown_7.Value));
 
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
     sum7 = 0;

for i = t
    if i<=1
        sum7 = 13.28633255/60 + sum7;
    elseif i<=2
        sum7 = 11.71255896/60 + sum7;
    elseif i<=3
        sum7 = 10.99853774/60 + sum7;
    elseif i<=4
        sum7 = 10.45212264/60 + sum7;
    elseif i<=5
        sum7 = 9.251792453/60 + sum7;
    elseif i<=6
        sum7 = 11.25287736/60 + sum7;
    elseif i<=7
        sum7 = 17.69346698/60 + sum7;
    elseif i<=8
        sum7 = 25.86818396/60 + sum7;
    elseif i<=9
        sum7 = 25.95788915/60 + sum7;     
     elseif i<=10
        sum7 = 26.27402123/60 + sum7;
    elseif i<=11
        sum7 = 25.79295991/60 + sum7;
    elseif i<=12
        sum7 = 23.10417453/60 + sum7;
     elseif i<=13
        sum7 = 21.81304245/60 + sum7;
    elseif i<=14
        sum7 = 20.18597877/60 + sum7;
    elseif i<=15
        sum7 = 20.24576651/60 + sum7;
    elseif i<=16
        sum7 = 22.24021226/60 + sum7;
    elseif i<=17
        sum7 = 26.44505896/60 + sum7;
    elseif i<=18
        sum7 = 29.50470519/60 + sum7;
    elseif i<=19
        sum7 = 29.53410377/60 + sum7;
    elseif i<=20
        sum7 = 28.19198113/60 + sum7;
    elseif i<=21
        sum7 = 26.25326651/60 + sum7;
    elseif i<=22
        sum7 = 21.59908019/60 + sum7;
    elseif i<=23
        sum7 = 17.52544811/60 + sum7;     
     elseif i<=24
        sum7 = 14.81475236/60 + sum7;
    end
end
 
 s = string(duration(app.StartDropDown_8.Value));
 f = string(duration(app.EndDropDown_8.Value));
 
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
     sum8 = 0;

for i = t
    if i<=1
        sum8 = 13.28633255/60 + sum8;
    elseif i<=2
        sum8 = 11.71255896/60 + sum8;
    elseif i<=3
        sum8 = 10.99853774/60 + sum8;
    elseif i<=4
        sum8 = 10.45212264/60 + sum8;
    elseif i<=5
        sum8 = 9.251792453/60 + sum8;
    elseif i<=6
        sum8 = 11.25287736/60 + sum8;
    elseif i<=7
        sum8 = 17.69346698/60 + sum8;
    elseif i<=8
        sum8 = 25.86818396/60 + sum8;
    elseif i<=9
        sum8 = 25.95788915/60 + sum8;     
     elseif i<=10
        sum8 = 26.27402123/60 + sum8;
    elseif i<=11
        sum8 = 25.79295991/60 + sum8;
    elseif i<=12
        sum8 = 23.10417453/60 + sum8;
     elseif i<=13
        sum8 = 21.81304245/60 + sum8;
    elseif i<=14
        sum8 = 20.18597877/60 + sum8;
    elseif i<=15
        sum8 = 20.24576651/60 + sum8;
    elseif i<=16
        sum8 = 22.24021226/60 + sum8;
    elseif i<=17
        sum8 = 26.44505896/60 + sum8;
    elseif i<=18
        sum8 = 29.50470519/60 + sum8;
    elseif i<=19
        sum8 = 29.53410377/60 + sum8;
    elseif i<=20
        sum8 = 28.19198113/60 + sum8;
    elseif i<=21
        sum8 = 26.25326651/60 + sum8;
    elseif i<=22
        sum8 = 21.59908019/60 + sum8;
    elseif i<=23
        sum8 = 17.52544811/60 + sum8;     
     elseif i<=24
        sum8 = 14.81475236/60 + sum8;
    end
 end


 s = string(duration(app.StartDropDown_9.Value));
 f = string(duration(app.EndDropDown_9.Value));
 
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
      sum9 = 0;

for i = t
    if i<=1
        sum9 = 13.28633255/60 + sum9;
    elseif i<=2
        sum9 = 11.71255896/60 + sum9;
    elseif i<=3
        sum9 = 10.99853774/60 + sum9;
    elseif i<=4
        sum9 = 10.45212264/60 + sum9;
    elseif i<=5
        sum9 = 9.251792453/60 + sum9;
    elseif i<=6
        sum9 = 11.25287736/60 + sum9;
    elseif i<=7
        sum9 = 17.69346698/60 + sum9;
    elseif i<=8
        sum9 = 25.86818396/60 + sum9;
    elseif i<=9
        sum9 = 25.95788915/60 + sum9;     
     elseif i<=10
        sum9 = 26.27402123/60 + sum9;
    elseif i<=11
        sum9 = 25.79295991/60 + sum9;
    elseif i<=12
        sum9 = 23.10417453/60 + sum9;
     elseif i<=13
        sum9 = 21.81304245/60 + sum9;
    elseif i<=14
        sum9 = 20.18597877/60 + sum9;
    elseif i<=15
        sum9 = 20.24576651/60 + sum9;
    elseif i<=16
        sum9 = 22.24021226/60 + sum9;
    elseif i<=17
        sum9 = 26.44505896/60 + sum9;
    elseif i<=18
        sum9 = 29.50470519/60 + sum9;
    elseif i<=19
        sum9 = 29.53410377/60 + sum9;
    elseif i<=20
        sum9 = 28.19198113/60 + sum9;
    elseif i<=21
        sum9 = 26.25326651/60 + sum9;
    elseif i<=22
        sum9 = 21.59908019/60 + sum9;
    elseif i<=23
        sum9 = 17.52544811/60 + sum9;     
     elseif i<=24
        sum9 = 14.81475236/60 + sum9;
    end
end
     
  s = string(duration(app.StartDropDown_10.Value));
  f = string(duration(app.EndDropDown_10.Value));
 
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
      sum10 = 0;

for i = t
    if i<=1
        sum10 = 13.28633255/60 + sum10;
    elseif i<=2
        sum10 = 11.71255896/60 + sum10;
    elseif i<=3
        sum10 = 10.99853774/60 + sum10;
    elseif i<=4
        sum10 = 10.45212264/60 + sum10;
    elseif i<=5
        sum10 = 9.251792453/60 + sum10;
    elseif i<=6
        sum10 = 11.25287736/60 + sum10;
    elseif i<=7
        sum10 = 17.69346698/60 + sum10;
    elseif i<=8
        sum10 = 25.86818396/60 + sum10;
    elseif i<=9
        sum10 = 25.95788915/60 + sum10;     
     elseif i<=10
        sum10 = 26.27402123/60 + sum10;
    elseif i<=11
        sum10 = 25.79295991/60 + sum10;
    elseif i<=12
        sum10 = 23.10417453/60 + sum10;
     elseif i<=13
        sum10 = 21.81304245/60 + sum10;
    elseif i<=14
        sum10 = 20.18597877/60 + sum10;
    elseif i<=15
        sum10 = 20.24576651/60 + sum10;
    elseif i<=16
        sum10 = 22.24021226/60 + sum10;
    elseif i<=17
        sum10 = 26.44505896/60 + sum10;
    elseif i<=18
        sum10 = 29.50470519/60 + sum10;
    elseif i<=19
        sum10 = 29.53410377/60 + sum10;
    elseif i<=20
        sum10 = 28.19198113/60 + sum10;
    elseif i<=21
        sum10 = 26.25326651/60 + sum10;
    elseif i<=22
        sum10 = 21.59908019/60 + sum10;
    elseif i<=23
        sum10 = 17.52544811/60 + sum10;     
     elseif i<=24
        sum10 = 14.81475236/60 + sum10;
    end
end

 app.EVelectricitycostEditField.Value = ((sum1+sum2+sum3+sum4+sum5)*7.2+(sum6+sum7+sum8+sum9+sum10)*50)/100;
     
     
 %battery
 %charging
 
  s = string(duration(app.StartDropDown_11.Value));
      f = string(duration(app.EndDropDown_11.Value));
      
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
    sumc = 0;

for i = t
    if i<=1
        sumc = 13.28633255/60 + sumc;
    elseif i<=2
        sumc = 11.71255896/60 + sumc;
    elseif i<=3
        sumc = 10.99853774/60 + sumc;
    elseif i<=4
        sumc = 10.45212264/60 + sumc;
    elseif i<=5
        sumc = 9.251792453/60 + sumc;
    elseif i<=6
        sumc = 11.25287736/60 + sumc;
    elseif i<=7
        sumc = 17.69346698/60 + sumc;
    elseif i<=8
        sumc = 25.86818396/60 + sumc;
    elseif i<=9
        sumc = 25.95788915/60 + sumc;     
     elseif i<=10
        sumc = 26.27402123/60 + sumc;
    elseif i<=11
        sumc = 25.79295991/60 + sumc;
    elseif i<=12
        sumc = 23.10417453/60 + sumc;
     elseif i<=13
        sumc = 21.81304245/60 + sumc;
    elseif i<=14
        sumc = 20.18597877/60 + sumc;
    elseif i<=15
        sumc = 20.24576651/60 + sumc;
    elseif i<=16
        sumc = 22.24021226/60 + sumc;
    elseif i<=17
        sumc = 26.44505896/60 + sumc;
    elseif i<=18
        sumc = 29.50470519/60 + sumc;
    elseif i<=19
        sumc = 29.53410377/60 + sumc;
    elseif i<=20
        sumc = 28.19198113/60 + sumc;
    elseif i<=21
        sumc = 26.25326651/60 + sumc;
    elseif i<=22
        sumc = 21.59908019/60 + sumc;
    elseif i<=23
        sumc = 17.52544811/60 + sumc;     
     elseif i<=24
        sumc = 14.81475236/60 + sumc;
    end
end
 
 s = string(duration(app.StartDropDown_12.Value));
      f = string(duration(app.EndDropDown_12.Value));
      
      s1 = split(s,":");
      sm2 = str2double(s1(2))/60;
      s2 = str2double(s1(1))+sm2;
      f1 = split(f,":");
      fm2 = str2double(f1(2))/60;
      f2 = str2double(f1(1))+fm2;
      t=s2:1/60:f2-1*1/60;
      
     sumd = 0;

for i = t
    if i<=1
        sumd = 13.28633255/60 + sumd;
    elseif i<=2
        sumd = 11.71255896/60 + sumd;
    elseif i<=3
        sumd = 10.99853774/60 + sumd;
    elseif i<=4
        sumd = 10.45212264/60 + sumd;
    elseif i<=5
        sumd = 9.251792453/60 + sumd;
    elseif i<=6
        sumd = 11.25287736/60 + sumd;
    elseif i<=7
        sumd = 17.69346698/60 + sumd;
    elseif i<=8
        sumd = 25.86818396/60 + sumd;
    elseif i<=9
        sumd = 25.95788915/60 + sumd;     
     elseif i<=10
        sumd = 26.27402123/60 + sumd;
    elseif i<=11
        sumd = 25.79295991/60 + sumd;
    elseif i<=12
        sumd = 23.10417453/60 + sumd;
     elseif i<=13
        sumd = 21.81304245/60 + sumd;
    elseif i<=14
        sumd = 20.18597877/60 + sumd;
    elseif i<=15
        sumd = 20.24576651/60 + sumd;
    elseif i<=16
        sumd = 22.24021226/60 + sumd;
    elseif i<=17
        sumd = 26.44505896/60 + sumd;
    elseif i<=18
        sumd = 29.50470519/60 + sumd;
    elseif i<=19
        sumd = 29.53410377/60 + sumd;
    elseif i<=20
        sumd = 28.19198113/60 + sumd;
    elseif i<=21
        sumd = 26.25326651/60 + sumd;
    elseif i<=22
        sumd = 21.59908019/60 + sumd;
    elseif i<=23
        sumd = 17.52544811/60 + sumd;     
     elseif i<=24
        sumd = 14.81475236/60 + sumd;
    end
end

 app.BESSSavingsEditField.Value = (sumd)*app.BESSRatingkWhEditField.Value/100*(app.BatteryefficiencyEditField.Value/100)-(sumc)*app.BESSRatingkWhEditField.Value/100*(1+(1-(app.BatteryefficiencyEditField.Value/100)));
     
     %             app.RESSettingsDialogApp = Peak_Shaving_Settings_Dialog_RESIDENTIAL(app,app.RESOnPeakHours, app.RESmidPeakHours, app.RESoffPeakHours, app.RESonPeakPrice, app.RESmidPeakPrice, app.RESoffPeakPrice, app.REStierRateKW, app.REStier1Price, app.REStier2Price,app.RESdeliveryPrice);
%             
%                 case 'Business'
%             app.RESSettingsDialogApp = Peak_Shaving_Settings_Dialog_BUSINESS(app,app.BUSOnPeakHours, app.BUSmidPeakHours, app.BUSoffPeakHours, app.BUSonPeakPrice, app.BUSmidPeakPrice, app.BUSoffPeakPrice, app.BUStierRateKW, app.BUStier1Price, app.BUStier2Price,app.BUSdeliveryPrice1,app.BUSdeliveryPrice2,app.BUSdeliveryPrice3,app.BUSdeliveryPrice4);
%             
%             end 
     
     %Delivery Charges
     t = app.UITable.Data;
            y=table2array(t(:,2));
     app.PeakDemandKWEditField.Value= max(y);
     
     
     value = app.CustomerTypeDropDown.Value;
     
    switch value
         case 'Residential'
             d = max(y)*app.RESdeliveryPrice/30/100;
             
    case'Business'
        
        total = a24;
        
        if total <=50
            c = app.BUSdeliveryPrice1/30*max(y)/100;
    
        elseif total<=999
            c = app.BUSdeliveryPrice2/30*max(y)/100;
    
        elseif total<=5000
            c = app.BUSdeliveryPrice3/30*max(y)/100;
    
        else 
            c = app.BUSdeliveryPrice4/30*max(y)/100;
    
        end 
        d = c;
           
     end
        app.DeliveryChargesEditField.Value = d;

     app.DailyCostEditField.Value = app.MURBelectricitycostEditField.Value + app.EVelectricitycostEditField.Value - app.BESSSavingsEditField.Value+app.DeliveryChargesEditField.Value;
        
        end

        % Value changed function: Charge2, StartDropDown_2
        function StartDropDown_2ValueChanged(app, event)
       value = app.EVModel2.Value;
 switch value
        
     case 'Average BEV'
         load = 8*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
    
     case 'Audi eTron'
         load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'Audi eTron Sportback'
       load = 14*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'BMW i3'
        load = 6*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'Chevrolet Bolt EV'
        load = 9.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
         load = 5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Hyundai Kona Electric'
        load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Jaguar I-PACE'
        load = 13*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Kia Niro Electric'
         load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
         load = 5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Nissan LEAF S'
        load = 11.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
        load = 8*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Polestar 2'
        load = 11*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Porsche Taycan'
         load = 12*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 9*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
         load = 9*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
         load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Tesla Model S Performance'
         load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Tesla Model X Long Range'
        load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Tesla Model X Performance'
        load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Tesla Model Y Long Range'
         load = 7.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Tesla Model Y Performance'
         load = 7.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
        load = 11*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      % PHEV
      
     case 'Average PHEV'
         load = 6*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'A8 L 60 TFSI e quattro tiptronic'
       load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Audi Q5'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Bentley Bentayga Hybrid'
         load = 2.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'BMW 330e'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'BMW 530e'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'BMW 740e xDrive'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'BMW X3 xDrive30e'
       load = 3.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'BMW X5 xDrive40e'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Chrysler Pacifica Hybrid'
       load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Honda Clarity PHEV'
         load = 2.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Hyundai Ioniq PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Hyundai Sonata PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Karma Revero GT'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Kia Niro PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Kia Optima Plug-in Hybrid'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Lincoln Aviator Grand Touring'
         load = 3.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'Mercedes GLC 350e'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Mini Cooper S E Countryman A114'
        load = 2.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Polestar 1'
        load = 1*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Porsche Cayenne S E-Hybrid'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Porchase Panamera E-Hybrid'
         load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Range Rover PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Range Rover Sport PHEV'
       load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Subaru Crosstrek Hybrid (PHEV)'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'Toyota Prius Prime'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
     case 'Toyota RAV4 Prime'
         load = 4.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Volvo S90 T8 PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Volvo XC60 T8 PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
      
      case 'Volvo XC90 PHEV'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_2.Items = string(l);
            
 end
        end

        % Value changed function: Charge3, StartDropDown_3
        function StartDropDown_3ValueChanged(app, event)
           value = app.EVModel3.Value;
switch value
        
     case 'Average BEV'
         load = 8*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
    
     case 'Audi eTron'
         load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'Audi eTron Sportback'
       load = 14*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'BMW i3'
        load = 6*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'Chevrolet Bolt EV'
        load = 9.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
         load = 5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Hyundai Kona Electric'
        load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Jaguar I-PACE'
        load = 13*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Kia Niro Electric'
         load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
         load = 5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Nissan LEAF S'
        load = 11.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
        load = 8*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Polestar 2'
        load = 11*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Porsche Taycan'
         load = 12*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 9*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
         load = 9*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
         load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Tesla Model S Performance'
         load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Tesla Model X Long Range'
        load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Tesla Model X Performance'
        load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Tesla Model Y Long Range'
         load = 7.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Tesla Model Y Performance'
         load = 7.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
        load = 11*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      % PHEV
      
     case 'Average PHEV'
         load = 6*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'A8 L 60 TFSI e quattro tiptronic'
       load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Audi Q5'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Bentley Bentayga Hybrid'
         load = 2.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'BMW 330e'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'BMW 530e'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'BMW 740e xDrive'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'BMW X3 xDrive30e'
       load = 3.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'BMW X5 xDrive40e'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Chrysler Pacifica Hybrid'
       load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Honda Clarity PHEV'
         load = 2.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Hyundai Ioniq PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Hyundai Sonata PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Karma Revero GT'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Kia Niro PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Kia Optima Plug-in Hybrid'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Lincoln Aviator Grand Touring'
         load = 3.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'Mercedes GLC 350e'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Mini Cooper S E Countryman A114'
        load = 2.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Polestar 1'
        load = 1*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Porsche Cayenne S E-Hybrid'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Porchase Panamera E-Hybrid'
         load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Range Rover PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Range Rover Sport PHEV'
       load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Subaru Crosstrek Hybrid (PHEV)'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'Toyota Prius Prime'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
     case 'Toyota RAV4 Prime'
         load = 4.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Volvo S90 T8 PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Volvo XC60 T8 PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
      
      case 'Volvo XC90 PHEV'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_3.Items = string(l);
            
end
        end

        % Callback function
        function StartDropDown_ValueChanged(app, event)
   
        end

        % Value changed function: Charge4, StartDropDown_4
        function StartDropDown_4ValueChanged(app, event)
    value = app.EVModel3_2.Value;
switch value
        
     case 'Average BEV'
         load = 8*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
    
     case 'Audi eTron'
         load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'Audi eTron Sportback'
       load = 14*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'BMW i3'
        load = 6*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'Chevrolet Bolt EV'
        load = 9.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
         load = 5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Hyundai Kona Electric'
        load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Jaguar I-PACE'
        load = 13*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Kia Niro Electric'
         load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
         load = 5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Nissan LEAF S'
        load = 11.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
        load = 8*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Polestar 2'
        load = 11*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Porsche Taycan'
         load = 12*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 9*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
         load = 9*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
         load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Tesla Model S Performance'
         load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Tesla Model X Long Range'
        load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Tesla Model X Performance'
        load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Tesla Model Y Long Range'
         load = 7.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Tesla Model Y Performance'
         load = 7.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
        load = 11*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      % PHEV
      
     case 'Average PHEV'
         load = 6*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'A8 L 60 TFSI e quattro tiptronic'
       load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Audi Q5'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Bentley Bentayga Hybrid'
         load = 2.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'BMW 330e'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'BMW 530e'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'BMW 740e xDrive'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'BMW X3 xDrive30e'
       load = 3.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'BMW X5 xDrive40e'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Chrysler Pacifica Hybrid'
       load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Honda Clarity PHEV'
         load = 2.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Hyundai Ioniq PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Hyundai Sonata PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Karma Revero GT'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Kia Niro PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Kia Optima Plug-in Hybrid'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Lincoln Aviator Grand Touring'
         load = 3.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'Mercedes GLC 350e'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Mini Cooper S E Countryman A114'
        load = 2.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Polestar 1'
        load = 1*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Porsche Cayenne S E-Hybrid'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Porchase Panamera E-Hybrid'
         load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Range Rover PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Range Rover Sport PHEV'
       load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Subaru Crosstrek Hybrid (PHEV)'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'Toyota Prius Prime'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
     case 'Toyota RAV4 Prime'
         load = 4.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Volvo S90 T8 PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Volvo XC60 T8 PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
      
      case 'Volvo XC90 PHEV'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_4.Items = string(l);
            
end
        end

        % Value changed function: Charge5, StartDropDown_5
        function StartDropDown_5ValueChanged(app, event)
        value = app.EVModel5.Value;
 switch value
        
     case 'Average BEV'
         load = 8*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
    
     case 'Audi eTron'
         load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'Audi eTron Sportback'
       load = 14*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'BMW i3'
        load = 6*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'Chevrolet Bolt EV'
        load = 9.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
         load = 5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Hyundai Kona Electric'
        load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Jaguar I-PACE'
        load = 13*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Kia Niro Electric'
         load = 10*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
         load = 5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Nissan LEAF S'
        load = 11.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
        load = 8*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Polestar 2'
        load = 11*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Porsche Taycan'
         load = 12*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 9*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
         load = 9*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
         load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Tesla Model S Performance'
         load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Tesla Model X Long Range'
        load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Tesla Model X Performance'
        load = 15*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Tesla Model Y Long Range'
         load = 7.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Tesla Model Y Performance'
         load = 7.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
        load = 11*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      % PHEV
      
     case 'Average PHEV'
         load = 6*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'A8 L 60 TFSI e quattro tiptronic'
       load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Audi Q5'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Bentley Bentayga Hybrid'
         load = 2.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'BMW 330e'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'BMW 530e'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'BMW 740e xDrive'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'BMW X3 xDrive30e'
       load = 3.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'BMW X5 xDrive40e'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Chrysler Pacifica Hybrid'
       load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Honda Clarity PHEV'
         load = 2.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Hyundai Ioniq PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Hyundai Sonata PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Karma Revero GT'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Kia Niro PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Kia Optima Plug-in Hybrid'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Lincoln Aviator Grand Touring'
         load = 3.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'Mercedes GLC 350e'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Mini Cooper S E Countryman A114'
        load = 2.25*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Polestar 1'
        load = 1*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Porsche Cayenne S E-Hybrid'
        load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Porchase Panamera E-Hybrid'
         load = 4*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Range Rover PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Range Rover Sport PHEV'
       load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Subaru Crosstrek Hybrid (PHEV)'
        load = 2*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'Toyota Prius Prime'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
     case 'Toyota RAV4 Prime'
         load = 4.5*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Volvo S90 T8 PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Volvo XC60 T8 PHEV'
         load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
      
      case 'Volvo XC90 PHEV'
        load = 3*(1-app.Charge2.Value/100);
       l= duration(app.StartDropDown_2.Value)+hours(load);
      app.EndDropDown_5.Items = string(l);
            
 end
        end

        % Value changed function: Charge6, StartDropDown_6
        function StartDropDown_6ValueChanged(app, event)
       value = app.EVModel5_6.Value;
 switch value
     case 'Average BEV'
         load = 30*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
     case 'Audi eTron'
         load = 60*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
     case 'Audi eTron Sportback'
           load = 70*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
     case 'BMW i3'
           load = 40*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
     case 'Chevrolet Bolt EV'
          load = 77.7*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
           load = 30*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Hyundai Kona Electric'
           load = 50*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Jaguar I-PACE'
           load = 70*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Kia Niro Electric'
          load = 50*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
          load = 30*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Nissan LEAF S'
           load = 27*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
          load = 60*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Polestar 2'
           load = 60*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Porsche Taycan'
           load = 60*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 50*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
           load = 50*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
          load = 80*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Tesla Model S Performance'
          load = 80*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Tesla Model X Long Range'
          load = 80*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Tesla Model X Performance'
          load = 80*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Tesla Model Y Long Range'
          load = 36*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Tesla Model Y Performance'
           load = 36*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
           load = 60*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      % PHEV
      
      case 'Karma Revero GT'
          load = 24*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
         load = 30*(1-app.Charge6.Value/100);
       l= duration(app.StartDropDown_6.Value)+minutes(load);
      app.EndDropDown_6.Items = string(l);
 end
            
        end

        % Value changed function: Charge6_2, StartDropDown_7
        function StartDropDown_7ValueChanged(app, event)
            value = app.EVModel5_5.Value;
  switch value
     case 'Average BEV'
         load = 30*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
     case 'Audi eTron'
         load = 60*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
     case 'Audi eTron Sportback'
           load = 70*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
     case 'BMW i3'
           load = 40*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
     case 'Chevrolet Bolt EV'
          load = 77.7*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
           load = 30*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Hyundai Kona Electric'
           load = 50*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Jaguar I-PACE'
           load = 70*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Kia Niro Electric'
          load = 50*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
          load = 30*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Nissan LEAF S'
           load = 27*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
          load = 60*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Polestar 2'
           load = 60*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Porsche Taycan'
           load = 60*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 50*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
           load = 50*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
          load = 80*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Tesla Model S Performance'
          load = 80*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Tesla Model X Long Range'
          load = 80*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Tesla Model X Performance'
          load = 80*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Tesla Model Y Long Range'
          load = 36*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Tesla Model Y Performance'
           load = 36*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
           load = 60*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      % PHEV
      
      case 'Karma Revero GT'
          load = 24*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
         load = 30*(1-app.Charge6_2.Value/100);
       l= duration(app.StartDropDown_7.Value)+minutes(load);
      app.EndDropDown_7.Items = string(l);
 end
        end

        % Value changed function: Charge6_3, StartDropDown_8
        function StartDropDown_8ValueChanged(app, event)
        value = app.EVModel5_4.Value;
  switch value
     case 'Average BEV'
         load = 30*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
     case 'Audi eTron'
         load = 60*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
     case 'Audi eTron Sportback'
           load = 70*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
     case 'BMW i3'
           load = 40*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
     case 'Chevrolet Bolt EV'
          load = 77.7*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
           load = 30*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Hyundai Kona Electric'
           load = 50*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Jaguar I-PACE'
           load = 70*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Kia Niro Electric'
          load = 50*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
          load = 30*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Nissan LEAF S'
           load = 27*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
          load = 60*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Polestar 2'
           load = 60*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Porsche Taycan'
           load = 60*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 50*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
           load = 50*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
          load = 80*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Tesla Model S Performance'
          load = 80*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Tesla Model X Long Range'
          load = 80*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Tesla Model X Performance'
          load = 80*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Tesla Model Y Long Range'
          load = 36*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Tesla Model Y Performance'
           load = 36*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
           load = 60*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      % PHEV
      
      case 'Karma Revero GT'
          load = 24*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
         load = 30*(1-app.Charge6_3.Value/100);
       l= duration(app.StartDropDown_8.Value)+minutes(load);
      app.EndDropDown_8.Items = string(l);
 end
        end

        % Value changed function: Charge6_4, StartDropDown_9
        function StartDropDown_9ValueChanged(app, event)
 value = app.EVModel5_3.Value;
  switch value
     case 'Average BEV'
         load = 30*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
     case 'Audi eTron'
         load = 60*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
     case 'Audi eTron Sportback'
           load = 70*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
     case 'BMW i3'
           load = 40*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
     case 'Chevrolet Bolt EV'
          load = 77.7*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
           load = 30*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Hyundai Kona Electric'
           load = 50*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Jaguar I-PACE'
           load = 70*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Kia Niro Electric'
          load = 50*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
          load = 30*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Nissan LEAF S'
           load = 27*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
          load = 60*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Polestar 2'
           load = 60*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Porsche Taycan'
           load = 60*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 50*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
           load = 50*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
          load = 80*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Tesla Model S Performance'
          load = 80*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Tesla Model X Long Range'
          load = 80*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Tesla Model X Performance'
          load = 80*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Tesla Model Y Long Range'
          load = 36*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Tesla Model Y Performance'
           load = 36*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
           load = 60*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      % PHEV
      
      case 'Karma Revero GT'
          load = 24*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
         load = 30*(1-app.Charge6_4.Value/100);
       l= duration(app.StartDropDown_9.Value)+minutes(load);
      app.EndDropDown_9.Items = string(l);
 end
        end

        % Value changed function: Charge6_5, StartDropDown_10
        function StartDropDown_10ValueChanged(app, event)
          
           
            value = app.EVModel5_7.Value;
  switch value
     case 'Average BEV'
         load = 30*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
     case 'Audi eTron'
         load = 60*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
     case 'Audi eTron Sportback'
           load = 70*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
     case 'BMW i3'
           load = 40*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
     case 'Chevrolet Bolt EV'
          load = 77.7*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Hyundai Ioniq Electric'
           load = 30*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Hyundai Kona Electric'
           load = 50*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Jaguar I-PACE'
           load = 70*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Kia Niro Electric'
          load = 50*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Mini Cooper S E Hardtop 2 door'
          load = 30*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Nissan LEAF S'
           load = 27*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Nissan LEAF S PLUS'
          load = 60*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Polestar 2'
           load = 60*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Porsche Taycan'
           load = 60*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Tesla Model 3 Long Range'
         load = 50*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Tesla Model 3 Standard Range Plus'
           load = 50*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Tesla Model S Long Range Plus'
          load = 80*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Tesla Model S Performance'
          load = 80*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Tesla Model X Long Range'
          load = 80*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Tesla Model X Performance'
          load = 80*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Tesla Model Y Long Range'
          load = 36*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Tesla Model Y Performance'
           load = 36*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Volvo XC40 AWD BEV'
           load = 60*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      % PHEV
      
      case 'Karma Revero GT'
          load = 24*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
      
      case 'Mitsubishi Outlander PHEV'
         load = 30*(1-app.Charge6_5.Value/100);
       l= duration(app.StartDropDown_10.Value)+minutes(load);
      app.EndDropDown_10.Items = string(l);
  end
        end

        % Callback function
        function EVModel1ValueChanged(app, event)
    
            
        end

        % Callback function
        function StartDropDownOpening(app, event)
            
        end

        % Value changed function: EndDropDown
        function EndDropDownValueChanged(app, event)
        
   
        end

        % Drop down opening function: StartDropDown_2
        function StartDropDown_2Opening(app, event)
            
        end

        % Button pushed function: ImportCustomDataButton
        function ImportCustomDataButtonPushed(app, event)
         value = app.ChooseMURBDropDown.Value;
        switch value
            
      case 'Custom'
            data = readtable("custom_murb_data.xlsx");
            app.UITable.Data=data;
           app.UITable.ColumnEditable = [false,true];
        end
        
        end

        % Callback function
        function ResidentialMenuSelected(app, event)
   
        end

        % Button pushed function: CustomizeratesButton
        function CustomizeratesButtonPushed(app, event)

            value = app.CustomerTypeDropDown.Value;
        
            switch value
            
                case 'Residential'

            app.RESSettingsDialogApp = Peak_Shaving_Settings_Dialog_RESIDENTIAL(app,app.RESOnPeakHours, app.RESmidPeakHours, app.RESoffPeakHours, app.RESonPeakPrice, app.RESmidPeakPrice, app.RESoffPeakPrice, app.REStierRateKW, app.REStier1Price, app.REStier2Price,app.RESdeliveryPrice);
            
                case 'Business'
            app.RESSettingsDialogApp = Peak_Shaving_Settings_Dialog_BUSINESS(app,app.BUSOnPeakHours, app.BUSmidPeakHours, app.BUSoffPeakHours, app.BUSonPeakPrice, app.BUSmidPeakPrice, app.BUSoffPeakPrice, app.BUStierRateKW, app.BUStier1Price, app.BUStier2Price,app.BUSdeliveryPrice1,app.BUSdeliveryPrice2,app.BUSdeliveryPrice3,app.BUSdeliveryPrice4);
            
            end
        end

        % Button pushed function: ExportCustomDataButton_2
        function ExportCustomDataButton_2Pushed(app, event)
              value = app.ChooseMURBDropDown.Value;
        switch value
      case 'Custom'
           data = app.UITable.Data; 
        filename = 'custom_murb_data.xlsx';
        writetable(data,filename)  
       end
       
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {616, 616, 616};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {616, 616};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1065, '1x', 504};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MURBInfoLabel = uilabel(app.LeftPanel);
            app.MURBInfoLabel.Text = 'MURB Info';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Hour'; 'Power (kW)'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged2, true);
            app.AverageHourlyLoadProfileLabel = uilabel(app.LeftPanel);
            app.AverageHourlyLoadProfileLabel.Text = 'Average Hourly Load Profile';
            app.EVInfoLabel = uilabel(app.LeftPanel);
            app.EVInfoLabel.Text = 'EV Info';
            app.Level2ChargersLabel = uilabel(app.LeftPanel);
            app.Level2ChargersLabel.Text = 'Level 2 Chargers';
            app.EV2Label = uilabel(app.LeftPanel);
            app.EV2Label.Text = '  EV #2';
            app.EV3Label = uilabel(app.LeftPanel);
            app.EV3Label.Text = '  EV #3';
            app.EV5Label = uilabel(app.LeftPanel);
            app.EV5Label.Text = '  EV #5';
            app.EV4Label = uilabel(app.LeftPanel);
            app.EV4Label.Text = '  EV #4';
            app.EV1Label = uilabel(app.LeftPanel);
            app.EV1Label.Text = '  EV #1';
            app.EV9Label = uilabel(app.LeftPanel);
            app.EV9Label.Text = '  EV #4';
            app.EV7Label = uilabel(app.LeftPanel);
            app.EV7Label.Text = '  EV #2';
            app.EV8Label = uilabel(app.LeftPanel);
            app.EV8Label.Text = '  EV #3';
            app.EV6Label = uilabel(app.LeftPanel);
            app.EV6Label.Text = '  EV #1';
            app.Level3ChargersLabel = uilabel(app.LeftPanel);
            app.Level3ChargersLabel.Text = 'Level 3 Chargers';
            app.ChargingTimeLabel = uilabel(app.LeftPanel);
            app.ChargingTimeLabel.Text = 'Charging Time';
            app.ChargingTimeLabel_2 = uilabel(app.LeftPanel);
            app.ChargingTimeLabel_2.Text = 'Charging Time';
            app.ChooseMURBDropDownLabel = uilabel(app.LeftPanel);
            app.ChooseMURBDropDownLabel.Text = 'Choose MURB';
            app.ChooseMURBDropDown = uidropdown(app.LeftPanel);
            app.ChooseMURBDropDown.Items = {'MURB 1', 'MURB 2', 'Custom', ''};
            app.ChooseMURBDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseMURBDropDownOpening, true);
            app.ChooseMURBDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseMURBDropDownValueChanged, true);
            app.ChooseMURBDropDown.Value = 'MURB 1';
            app.EVTypeDropDown_5Label = uilabel(app.LeftPanel);
            app.EVTypeDropDown_5Label.Text = 'EV Type';
            app.EVType1 = uidropdown(app.LeftPanel);
            app.EVType1.Items = {'BEV', 'PHEV'};
            app.EVType1.ValueChangedFcn = createCallbackFcn(app, @EVModel1DropDownOpening, true);
            app.EVType1.Value = 'BEV';
            app.EVTypeDropDown_5Label_2 = uilabel(app.LeftPanel);
            app.EVTypeDropDown_5Label_2.Text = 'EV Type';
            app.EVType2 = uidropdown(app.LeftPanel);
            app.EVType2.Items = {'BEV', 'PHEV'};
            app.EVType2.ValueChangedFcn = createCallbackFcn(app, @EVModel2DropDownOpening, true);
            app.EVType2.Value = 'BEV';
            app.EVTypeDropDown_5Label_3 = uilabel(app.LeftPanel);
            app.EVTypeDropDown_5Label_3.Text = 'EV Type';
            app.EVType3 = uidropdown(app.LeftPanel);
            app.EVType3.Items = {'BEV', 'PHEV'};
            app.EVType3.ValueChangedFcn = createCallbackFcn(app, @EVModel3DropDownOpening, true);
            app.EVType3.Value = 'BEV';
            app.EVTypeDropDown_5Label_4 = uilabel(app.LeftPanel);
            app.EVTypeDropDown_5Label_4.Text = 'EV Type';
            app.EVType4 = uidropdown(app.LeftPanel);
            app.EVType4.Items = {'BEV', 'PHEV'};
            app.EVType4.ValueChangedFcn = createCallbackFcn(app, @EVModel3_2DropDownOpening, true);
            app.EVType4.Value = 'BEV';
            app.EVTypeDropDown_5Label_5 = uilabel(app.LeftPanel);
            app.EVTypeDropDown_5Label_5.Text = 'EV Type';
            app.EVType5 = uidropdown(app.LeftPanel);
            app.EVType5.Items = {'BEV', 'PHEV'};
            app.EVType5.ValueChangedFcn = createCallbackFcn(app, @EVModel5DropDownOpening, true);
            app.EVType5.Value = 'BEV';
            app.EV10Label = uilabel(app.LeftPanel);
            app.EV10Label.Text = '  EV #5';
            app.EVTypeLabel = uilabel(app.LeftPanel);
            app.EVTypeLabel.Text = 'EV Type';
            app.EVType6 = uidropdown(app.LeftPanel);
            app.EVType6.Items = {'BEV', 'PHEV'};
            app.EVType6.ValueChangedFcn = createCallbackFcn(app, @EVModel5_6DropDownOpening, true);
            app.EVType6.Value = 'BEV';
            app.EVTypeLabel_2 = uilabel(app.LeftPanel);
            app.EVTypeLabel_2.Text = 'EV Type';
            app.EVType7 = uidropdown(app.LeftPanel);
            app.EVType7.Items = {'BEV', 'PHEV'};
            app.EVType7.ValueChangedFcn = createCallbackFcn(app, @EVModel5_5DropDownOpening, true);
            app.EVType7.Value = 'BEV';
            app.EVTypeLabel_3 = uilabel(app.LeftPanel);
            app.EVTypeLabel_3.Text = 'EV Type';
            app.EVType8 = uidropdown(app.LeftPanel);
            app.EVType8.Items = {'BEV', 'PHEV'};
            app.EVType8.ValueChangedFcn = createCallbackFcn(app, @EVModel5_4DropDownOpening, true);
            app.EVType8.Value = 'BEV';
            app.EVTypeDropDown_5Label_7 = uilabel(app.LeftPanel);
            app.EVTypeDropDown_5Label_7.Text = 'EV Type';
            app.EVType10 = uidropdown(app.LeftPanel);
            app.EVType10.Items = {'BEV', 'PHEV'};
            app.EVType10.ValueChangedFcn = createCallbackFcn(app, @EVModel5_7DropDownOpening, true);
            app.EVType10.Value = 'BEV';
            app.EVTypeDropDown_5Label_8 = uilabel(app.LeftPanel);
            app.EVTypeDropDown_5Label_8.Text = 'EV Type';
            app.EVType9 = uidropdown(app.LeftPanel);
            app.EVType9.Items = {'BEV', 'PHEV'};
            app.EVType9.ValueChangedFcn = createCallbackFcn(app, @EVModel5_3DropDownOpening, true);
            app.EVType9.Value = 'BEV';
            app.EVModelLabel = uilabel(app.LeftPanel);
            app.EVModelLabel.Text = 'EV Model';
            app.EVModel1 = uidropdown(app.LeftPanel);
            app.EVModel1.Items = {};
            app.EVModel1.Value = {};
            app.EVModelLabel_2 = uilabel(app.LeftPanel);
            app.EVModelLabel_2.Text = 'EV Model';
            app.EVModel2 = uidropdown(app.LeftPanel);
            app.EVModel2.Items = {};
            app.EVModel2.Value = {};
            app.EVModelLabel_4 = uilabel(app.LeftPanel);
            app.EVModelLabel_4.Text = 'EV Model';
            app.EVModel5 = uidropdown(app.LeftPanel);
            app.EVModel5.Items = {};
            app.EVModel5.DropDownOpeningFcn = createCallbackFcn(app, @EVModel5DropDownOpening, true);
            app.EVModel5.Value = {};
            app.EVModelLabel_5 = uilabel(app.LeftPanel);
            app.EVModelLabel_5.Text = 'EV Model';
            app.EVModel3 = uidropdown(app.LeftPanel);
            app.EVModel3.Items = {};
            app.EVModel3.Value = {};
            app.EVModelLabel_7 = uilabel(app.LeftPanel);
            app.EVModelLabel_7.Text = 'EV Model';
            app.EVModel5_3 = uidropdown(app.LeftPanel);
            app.EVModel5_3.Items = {};
            app.EVModel5_3.DropDownOpeningFcn = createCallbackFcn(app, @EVModel5_3DropDownOpening, true);
            app.EVModel5_3.Value = {};
            app.EVModelLabel_8 = uilabel(app.LeftPanel);
            app.EVModelLabel_8.Text = 'EV Model';
            app.EVModel5_4 = uidropdown(app.LeftPanel);
            app.EVModel5_4.Items = {};
            app.EVModel5_4.Value = {};
            app.EVModelLabel_9 = uilabel(app.LeftPanel);
            app.EVModelLabel_9.Text = 'EV Model';
            app.EVModel5_5 = uidropdown(app.LeftPanel);
            app.EVModel5_5.Items = {};
            app.EVModel5_5.Value = {};
            app.EVModelLabel_10 = uilabel(app.LeftPanel);
            app.EVModelLabel_10.Text = 'EV Model';
            app.EVModel5_6 = uidropdown(app.LeftPanel);
            app.EVModel5_6.Items = {};
            app.EVModel5_6.DropDownOpeningFcn = createCallbackFcn(app, @EVModel5_6DropDownOpening, true);
            app.EVModel5_6.Value = {};
            app.EVModelLabel_11 = uilabel(app.LeftPanel);
            app.EVModelLabel_11.Text = 'EV Model';
            app.EVModel5_7 = uidropdown(app.LeftPanel);
            app.EVModel5_7.Items = {};
            app.EVModel5_7.Value = {};
            app.ChargeEditFieldLabel = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel.Text = '% Charge';
            app.Charge1 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge1.Limits = [0 100];
            app.Charge1.ValueChangedFcn = createCallbackFcn(app, @StartDropDownValueChanged, true);
            app.ChargeEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel_2.Text = '% Charge';
            app.Charge2 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge2.Limits = [0 100];
            app.Charge2.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_2ValueChanged, true);
            app.ChargeEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel_3.Text = '% Charge';
            app.Charge3 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge3.Limits = [0 100];
            app.Charge3.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_3ValueChanged, true);
            app.ChargeEditFieldLabel_4 = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel_4.Text = '% Charge';
            app.Charge4 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge4.Limits = [0 100];
            app.Charge4.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_4ValueChanged, true);
            app.ChargeEditFieldLabel_5 = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel_5.Text = '% Charge';
            app.Charge5 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge5.Limits = [0 100];
            app.Charge5.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_5ValueChanged, true);
            app.ChargeEditFieldLabel_7 = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel_7.Text = '% Charge';
            app.Charge6 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge6.Limits = [0 100];
            app.Charge6.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_6ValueChanged, true);
            app.EVModelLabel_12 = uilabel(app.LeftPanel);
            app.EVModelLabel_12.Text = 'EV Model';
            app.EVModel3_2 = uidropdown(app.LeftPanel);
            app.EVModel3_2.Items = {};
            app.EVModel3_2.DropDownOpeningFcn = createCallbackFcn(app, @EVModel3_2DropDownOpening, true);
            app.EVModel3_2.Value = {};
            app.ChargeEditFieldLabel_8 = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel_8.Text = '% Charge';
            app.Charge6_2 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge6_2.Limits = [0 100];
            app.Charge6_2.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_7ValueChanged, true);
            app.ChargeEditFieldLabel_9 = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel_9.Text = '% Charge';
            app.Charge6_3 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge6_3.Limits = [0 100];
            app.Charge6_3.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_8ValueChanged, true);
            app.ChargeEditFieldLabel_10 = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel_10.Text = '% Charge';
            app.Charge6_4 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge6_4.Limits = [0 100];
            app.Charge6_4.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_9ValueChanged, true);
            app.StartDropDownLabel = uilabel(app.LeftPanel);
            app.StartDropDownLabel.Text = 'Start';
            app.StartDropDown = uidropdown(app.LeftPanel);
            app.StartDropDown.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown.ValueChangedFcn = createCallbackFcn(app, @StartDropDownValueChanged, true);
            app.StartDropDown.Value = '00:00:00';
            app.StartDropDown_2Label = uilabel(app.LeftPanel);
            app.StartDropDown_2Label.Text = 'Start';
            app.StartDropDown_2 = uidropdown(app.LeftPanel);
            app.StartDropDown_2.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @StartDropDown_2Opening, true);
            app.StartDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_2ValueChanged, true);
            app.StartDropDown_2.Value = '00:00:00';
            app.StartDropDown_3Label = uilabel(app.LeftPanel);
            app.StartDropDown_3Label.Text = 'Start';
            app.StartDropDown_3 = uidropdown(app.LeftPanel);
            app.StartDropDown_3.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_3.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_3ValueChanged, true);
            app.StartDropDown_3.Value = '00:00:00';
            app.StartDropDown_4Label = uilabel(app.LeftPanel);
            app.StartDropDown_4Label.Text = 'Start';
            app.StartDropDown_4 = uidropdown(app.LeftPanel);
            app.StartDropDown_4.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_4.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_4ValueChanged, true);
            app.StartDropDown_4.Value = '00:00:00';
            app.StartDropDown_5Label = uilabel(app.LeftPanel);
            app.StartDropDown_5Label.Text = 'Start';
            app.StartDropDown_5 = uidropdown(app.LeftPanel);
            app.StartDropDown_5.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_5.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_5ValueChanged, true);
            app.StartDropDown_5.Value = '00:00:00';
            app.StartDropDown_6Label = uilabel(app.LeftPanel);
            app.StartDropDown_6Label.Text = 'Start';
            app.StartDropDown_6 = uidropdown(app.LeftPanel);
            app.StartDropDown_6.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_6.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_6ValueChanged, true);
            app.StartDropDown_6.Value = '00:00:00';
            app.StartDropDown_7Label = uilabel(app.LeftPanel);
            app.StartDropDown_7Label.Text = 'Start';
            app.StartDropDown_7 = uidropdown(app.LeftPanel);
            app.StartDropDown_7.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_7.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_7ValueChanged, true);
            app.StartDropDown_7.Value = '00:00:00';
            app.StartDropDown_8Label = uilabel(app.LeftPanel);
            app.StartDropDown_8Label.Text = 'Start';
            app.StartDropDown_8 = uidropdown(app.LeftPanel);
            app.StartDropDown_8.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_8.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_8ValueChanged, true);
            app.StartDropDown_8.Value = '00:00:00';
            app.StartDropDown_9Label = uilabel(app.LeftPanel);
            app.StartDropDown_9Label.Text = 'Start';
            app.StartDropDown_9 = uidropdown(app.LeftPanel);
            app.StartDropDown_9.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_9.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_9ValueChanged, true);
            app.StartDropDown_9.Value = '00:00:00';
            app.StartDropDown_10Label = uilabel(app.LeftPanel);
            app.StartDropDown_10Label.Text = 'Start';
            app.StartDropDown_10 = uidropdown(app.LeftPanel);
            app.StartDropDown_10.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_10.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_10ValueChanged, true);
            app.StartDropDown_10.Value = '00:00:00';
            app.EndDropDown_5Label = uilabel(app.LeftPanel);
            app.EndDropDown_5Label.Text = 'End';
            app.EndDropDown_5 = uidropdown(app.LeftPanel);
            app.EndDropDown_5.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_5.Value = '00:00:00';
            app.EndDropDown_4Label = uilabel(app.LeftPanel);
            app.EndDropDown_4Label.Text = 'End';
            app.EndDropDown_4 = uidropdown(app.LeftPanel);
            app.EndDropDown_4.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_4.Value = '00:00:00';
            app.EndDropDown_3Label = uilabel(app.LeftPanel);
            app.EndDropDown_3Label.Text = 'End';
            app.EndDropDown_3 = uidropdown(app.LeftPanel);
            app.EndDropDown_3.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_3.Value = '00:00:00';
            app.EndDropDown_2Label = uilabel(app.LeftPanel);
            app.EndDropDown_2Label.Text = 'End';
            app.EndDropDown_2 = uidropdown(app.LeftPanel);
            app.EndDropDown_2.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_2.Value = '00:00:00';
            app.EndDropDownLabel = uilabel(app.LeftPanel);
            app.EndDropDownLabel.Text = 'End';
            app.EndDropDown = uidropdown(app.LeftPanel);
            app.EndDropDown.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown.ValueChangedFcn = createCallbackFcn(app, @EndDropDownValueChanged, true);
            app.EndDropDown.Value = '00:00:00';
            app.EndDropDown_6Label = uilabel(app.LeftPanel);
            app.EndDropDown_6Label.Text = 'End';
            app.EndDropDown_6 = uidropdown(app.LeftPanel);
            app.EndDropDown_6.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_6.Value = '00:00:00';
            app.EndDropDown_7Label = uilabel(app.LeftPanel);
            app.EndDropDown_7Label.Text = 'End';
            app.EndDropDown_7 = uidropdown(app.LeftPanel);
            app.EndDropDown_7.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_7.Value = '00:00:00';
            app.EndDropDown_8Label = uilabel(app.LeftPanel);
            app.EndDropDown_8Label.Text = 'End';
            app.EndDropDown_8 = uidropdown(app.LeftPanel);
            app.EndDropDown_8.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_8.Value = '00:00:00';
            app.EndDropDown_9Label = uilabel(app.LeftPanel);
            app.EndDropDown_9Label.Text = 'End';
            app.EndDropDown_9 = uidropdown(app.LeftPanel);
            app.EndDropDown_9.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_9.Value = '00:00:00';
            app.EndDropDown_10Label = uilabel(app.LeftPanel);
            app.EndDropDown_10Label.Text = 'End';
            app.EndDropDown_10 = uidropdown(app.LeftPanel);
            app.EndDropDown_10.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_10.Value = '00:00:00';
            app.ChargeEditFieldLabel_11 = uilabel(app.LeftPanel);
            app.ChargeEditFieldLabel_11.Text = '% Charge';
            app.Charge6_5 = uieditfield(app.LeftPanel, 'numeric');
            app.Charge6_5.Limits = [0 100];
            app.Charge6_5.ValueChangedFcn = createCallbackFcn(app, @StartDropDown_10ValueChanged, true);
            app.MurbCostSavingsCalculatorLabel = uilabel(app.LeftPanel);
            app.MurbCostSavingsCalculatorLabel.Text = 'Murb Cost Savings Calculator';
            app.ImportCustomDataButton = uibutton(app.LeftPanel, 'push');
            app.ImportCustomDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCustomDataButtonPushed, true);
            app.ImportCustomDataButton.Text = {'Import '; 'Custom Data'; ''};
            app.ExportCustomDataButton_2 = uibutton(app.LeftPanel, 'push');
            app.ExportCustomDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportCustomDataButton_2Pushed, true);
            app.ExportCustomDataButton_2.Text = {'Export '; 'Custom Data'; ''};
            app.CenterPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '';
            app.DischargingTimeLabel = uilabel(app.CenterPanel);
            app.DischargingTimeLabel.Text = 'Discharging Time';
            app.ChargingTimeLabel_3 = uilabel(app.CenterPanel);
            app.ChargingTimeLabel_3.Text = 'Charging Time';
            app.BESSLabel = uilabel(app.CenterPanel);
            app.BESSLabel.Text = 'BESS';
            app.BESSRatingkWhEditField = uieditfield(app.CenterPanel, 'numeric');
            app.BESSRatingkWhEditField.Limits = [0 Inf];
            app.BESSRatingkWhEditField.Value = 100;
            app.BESSRatingkWhEditFieldLabel = uilabel(app.CenterPanel);
            app.BESSRatingkWhEditFieldLabel.Text = 'BESS Rating (kWh)';
            app.StartDropDown_11 = uidropdown(app.CenterPanel);
            app.StartDropDown_11.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_11.Value = '00:00:00';
            app.StartDropDown_11Label = uilabel(app.CenterPanel);
            app.StartDropDown_11Label.Text = 'Start';
            app.EndDropDown_11 = uidropdown(app.CenterPanel);
            app.EndDropDown_11.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_11.Value = '00:00:00';
            app.EndDropDown_11Label = uilabel(app.CenterPanel);
            app.EndDropDown_11Label.Text = 'End';
            app.StartDropDown_12 = uidropdown(app.CenterPanel);
            app.StartDropDown_12.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.StartDropDown_12.Value = '00:00:00';
            app.StartDropDown_12Label = uilabel(app.CenterPanel);
            app.StartDropDown_12Label.Text = 'Start';
            app.EndDropDown_12 = uidropdown(app.CenterPanel);
            app.EndDropDown_12.Items = {'00:00:00', '00:15:00', '00:30:00', '00:45:00', '01:00:00', '01:15:00', '01:30:00', '01:45:00', '02:00:00', '02:15:00', '02:30:00', '02:45:00', '03:00:00', '03:15:00', '03:30:00', '03:45:00', '04:00:00', '04:15:00', '04:30:00', '04:45:00', '05:00:00', '05:15:00', '05:30:00', '05:45:00', '06:00:00', '06:15:00', '06:30:00', '06:45:00', '07:00:00', '07:15:00', '07:30:00', '07:45:00', '08:00:00', '08:15:00', '08:30:00', '08:45:00', '09:00:00', '09:15:00', '09:30:00', '09:45:00', '10:00:00', '10:15:00', '10:30:00', '10:45:00', '11:00:00', '11:15:00', '11:30:00', '11:45:00', '12:00:00', '12:15:00', '12:30:00', '12:45:00', '13:00:00', '13:15:00', '13:30:00', '13:45:00', '14:00:00', '14:15:00', '14:30:00', '14:45:00', '15:00:00', '15:15:00', '15:30:00', '15:45:00', '16:00:00', '16:15:00', '16:30:00', '16:45:00', '17:00:00', '17:15:00', '17:30:00', '17:45:00', '18:00:00', '18:15:00', '18:30:00', '18:45:00', '19:00:00', '19:15:00', '19:30:00', '19:45:00', '20:00:00', '20:15:00', '20:30:00', '20:45:00', '21:00:00', '21:15:00', '21:30:00', '21:45:00', '22:00:00', '22:15:00', '22:30:00', '22:45:00', '23:00:00', '23:15:00', '23:30:00', '23:45:00'};
            app.EndDropDown_12.Value = '00:00:00';
            app.EndDropDown_12Label = uilabel(app.CenterPanel);
            app.EndDropDown_12Label.Text = 'End';
            app.PeakDemandKWEditField = uieditfield(app.CenterPanel, 'numeric');
            app.PeakDemandKWEditFieldLabel = uilabel(app.CenterPanel);
            app.PeakDemandKWEditFieldLabel.Text = {'Peak Demand(KW)'; ''};
            app.CostofElectricityLabel = uilabel(app.CenterPanel);
            app.CostofElectricityLabel.Text = 'Cost of Electricity';
            app.CustomizeratesButton = uibutton(app.CenterPanel, 'push');
            app.CustomizeratesButton.ButtonPushedFcn = createCallbackFcn(app, @CustomizeratesButtonPushed, true);
            app.CustomizeratesButton.Text = 'Customize rates';
            app.CustomerTypeDropDownLabel = uilabel(app.CenterPanel);
            app.CustomerTypeDropDownLabel.Text = 'Customer Type';
            app.CustomerTypeDropDown = uidropdown(app.CenterPanel);
            app.CustomerTypeDropDown.Items = {'Residential', 'Business', ''};
            app.CustomerTypeDropDown.Value = 'Residential';
            app.RatesDropDownLabel = uilabel(app.CenterPanel);
            app.RatesDropDownLabel.Text = 'Rates';
            app.RatesDropDown = uidropdown(app.CenterPanel);
            app.RatesDropDown.Items = {'Time of use', 'Tiered'};
            app.RatesDropDown.Value = 'Time of use';
            app.BatteryefficiencyLabel = uilabel(app.CenterPanel);
            app.BatteryefficiencyLabel.Text = 'Battery efficiency %';
            app.BatteryefficiencyEditField = uieditfield(app.CenterPanel, 'numeric');
            app.BatteryefficiencyEditField.Limits = [0 100];
            app.BatteryefficiencyEditField.Value = 95;
            app.RightPanel = uipanel(app.GridLayout);
            app.DailyCostLabel = uilabel(app.RightPanel);
            app.DailyCostLabel.Text = 'Daily Cost';
            app.MURBelectricitycostEditFieldLabel = uilabel(app.RightPanel);
            app.MURBelectricitycostEditFieldLabel.Text = 'MURB electricity cost ($)';
            app.MURBelectricitycostEditField = uieditfield(app.RightPanel, 'numeric');
            app.EVelectricitycostEditFieldLabel = uilabel(app.RightPanel);
            app.EVelectricitycostEditFieldLabel.Text = 'EV electricity cost ($)';
            app.EVelectricitycostEditField = uieditfield(app.RightPanel, 'numeric');
            app.BESSSavingsEditFieldLabel = uilabel(app.RightPanel);
            app.BESSSavingsEditFieldLabel.Text = 'BESS Savings ($)';
            app.BESSSavingsEditField = uieditfield(app.RightPanel, 'numeric');
            app.DailyCostEditFieldLabel = uilabel(app.RightPanel);
            app.DailyCostEditFieldLabel.Text = 'Daily Cost ($)';
            app.DailyCostEditField = uieditfield(app.RightPanel, 'numeric');
            app.CalculateButton = uibutton(app.RightPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DeliveryChargesLabel_2 = uilabel(app.RightPanel);
            app.DeliveryChargesLabel_2.Text = 'Delivery Charges ($)';
            app.DeliveryChargesEditField = uieditfield(app.RightPanel, 'numeric');
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Daily MURB Electricity Cost')
            xlabel(app.UIAxes, 'Hour')
            ylabel(app.UIAxes, 'Cost ($)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MurbCostSavingsCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end