classdef Peak_Shaving < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        RunSimulationButton            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        HourLoadProfileLabel           matlab.ui.control.Label
        BatteryCapacitykWhLabel        matlab.ui.control.Label
        BatteryCapacitykWhEditField    matlab.ui.control.NumericEditField
        RatedPowerkWLabel              matlab.ui.control.Label
        RatedPowerkWEditField          matlab.ui.control.NumericEditField
        ConversionEfficiencyLabel      matlab.ui.control.Label
        ConversionEfficiencyEditField  matlab.ui.control.NumericEditField
        AuxilleryPowerRequirementkWLabel  matlab.ui.control.Label
        AuxilleryPowerRequirementkWEditField  matlab.ui.control.NumericEditField
        BatterySpecificationsLabel     matlab.ui.control.Label
        ChargeControllerSettingsLabel  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditFieldLabel  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditField  matlab.ui.control.NumericEditField
        kWLabel                        matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditFieldLabel  matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField  matlab.ui.control.NumericEditField
        kWLabel_2                      matlab.ui.control.Label
        BESSPEAKSHAVINGAPPLabel        matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ManualButton_2                 matlab.ui.control.RadioButton
        AutoButton                     matlab.ui.control.RadioButton
        EnterPowerLimitofMURBFacilityLabel  matlab.ui.control.Label
        EnterPowerLimitofMURBFacilityEditField  matlab.ui.control.NumericEditField
        kWLabel_3                      matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function computeOverloadSections(app,time,shavedLoad,MURBLimit)
            
            startTimes = zeros(1,length(shavedLoad));
            endTimes = zeros(1,length(shavedLoad));
            
            % Find Overloaded sections
            overLoad = (shavedLoad>MURBLimit);
            overLoadDiff = diff(overLoad);
            
            startIndex = 1;
            endIndex = 1;
            
            for i = 1:length(overLoadDiff)
                
                if overLoadDiff(i) == 1
                    
                    startTimes(startIndex)=i+1;
                    startIndex = startIndex + 1;
                
                elseif overLoadDiff(i) == -1
                    
                    endTimes(endIndex)=i;
                    endIndex = endIndex + 1;
                    
                end
                
            end
                       
            
            start = nonzeros(startTimes)';
            assignin("base","start",start);
            
            endT = nonzeros(endTimes)';
            assignin("base","endT",endT);
            
                               
            % Add Overloaded Sections to Graph
            
            hold(app.UIAxes,'on');
            for k = 1:length(start)
            
            % Use interpolation to compute the exact crossing (START)
            
            % First Check if adjacent time values are equal (prevent error)
            if time(start(k))==time(start(k)-1)
                % Add in new point (same time value)
                time = [time(1:start(k)-1)' time(start(k)) time(start(k):length(time))']';
                shavedLoad = [shavedLoad(1:start(k)-1) MURBLimit shavedLoad(start(k):length(shavedLoad))];
            
            % Otherwise apply interpolation
            else
                % Obtain interpolated time value (intQX)
                intY = shavedLoad(start(k)-1:start(k));
                intX = time(start(k)-1:start(k));
                intYQ = MURBLimit;
                intXQ = interp1(intY,intX,intYQ);
                
                % Add interpolated interpolated value to existing vector
                time = [time(1:start(k)-1)' intXQ time(start(k):length(time))']';
                shavedLoad = [shavedLoad(1:start(k)-1) MURBLimit shavedLoad(start(k):length(shavedLoad))];
            end
            
            endT = endT + 1;
            
            % Use interpolation to compute the exact crossing (END)
            
            % First Check if adjacent time values are equal (prevent error)
            if time(endT(k))==time(endT(k)+1)
                % Add in new point (same time value)
                time = [time(1:endT(k))' time(endT(k)) time(endT(k)+1:length(time))']';
                shavedLoad = [shavedLoad(1:endT(k)) MURBLimit shavedLoad(endT(k)+1:length(shavedLoad))];
            
            % Otherwise apply interpolation
            else
                % Obtain interpolated time value (intQX)
                intY = shavedLoad(endT(k):endT(k)+1);
                intX = time(endT(k):endT(k)+1);
                intYQ = MURBLimit;
                intXQ = interp1(intY,intX,intYQ);
                
                % Add interpolated interpolated value to existing vector
                time = [time(1:endT(k))' intXQ time(endT(k)+1:length(time))']';
                shavedLoad = [shavedLoad(1:endT(k)) MURBLimit shavedLoad(endT(k)+1:length(shavedLoad))];
            end


            area(app.UIAxes,time(start(k):endT(k)+1),shavedLoad(start(k):endT(k)+1),MURBLimit,'FaceColor','r','ShowBaseLine','off','EdgeColor','r','FaceAlpha',0.5,'DisplayName','MURB Overloaded')  ;
            
            end
            hold(app.UIAxes,'off');
            
            
            if length(start) >= 1
                fig = uifigure;
                uialert(fig,'MURB Power Rating Has Been Exceeded. Consider Adjusting the Battery Settings','Overload Warning','CloseFcn',@(h,e) close(fig)); 
            end
            
            
        end        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear;
            %clc;
            %Load = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0]';
            Load = [365 348 338 336 340 370 405 423 423 439 447 450 440 437 434 454 478 509 530 527 523 486 449 401]';
            TT = timetable(Load,'TimeStep',hours(1));
            T = timetable2table(TT);
            app.UITable.Data = T;
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            tableData = app.UITable.Data;
            
            % Get Time Data from Table
            x = datevec(tableData.Time);
            x = x(:,4);
            
            % Get Load Data from Table
            y = tableData.Load;
            
            %%%% Get Facility Overload Limit from Form %%%%
            MURBOverload = app.EnterPowerLimitofMURBFacilityEditField.Value;
            assignin("base","MURBOverload",MURBOverload);
            
            
            %%%%%%%%% Get BESS Data from Form %%%%%%%%
            % Battery Capcity
            BESSCapacity = app.BatteryCapacitykWhEditField.Value;
            assignin("base","BESSCapacity",BESSCapacity);
            
            % Initial State of Charge
            %initialSOC = app.InitialStateofChargeEditField.Value;
            %assignin("base","initialSOC",initialSOC);
            
            % Rated Power
            ratedPower = app.RatedPowerkWEditField.Value;
            assignin("base","ratedPower",ratedPower);
            
            ratedPowerNEG = -1*ratedPower;
            assignin("base","ratedPowerNEG",ratedPowerNEG);
            
            % Conversion Efficiency
            efficiency = app.ConversionEfficiencyEditField.Value;
            assignin("base","efficiency",efficiency);
            
            % Auxillery Power Requirement
            auxPower = app.AuxilleryPowerRequirementkWEditField.Value;
            assignin("base","auxPower",auxPower);
            
            
            %%%%%%%%% Get Charge Controller Data from Form %%%%%%%%
            % Discharge Setting
            dischargeSetting = app.DischargeBatteryWhenLoadisAboveEditField.Value;
            assignin("base","dischargeSetting",dischargeSetting);
            
            % Charge Setting
            chargeSetting = app.ChargeBatteryWhenLoadisBelowEditField.Value;
            assignin("base","chargeSetting",chargeSetting);


            % Reformat Data for Simulation
            ts=timeseries(y,x);    
            assignin("base","ts",ts);
            
            % Get Saturation Limit
            %satLim = str2num(app.SaturationLimitEditField.Value);
            %assignin("base","satLim",satLim);
            
            % Set Simulation Time
            simTime = length(y)-1;
            
            %Run Simulation
            out = sim('Peak_Shave_Model','StartTime','0','StopTime',num2str(simTime));       
            
            
            % Update Plot
            %plot(app.UIAxes,out.tout,out.initialLoad,out.tout,out.chargeDischarge);
            shaved = reshape(out.simout.Data,1,[]);     
            
            
            %assignin("base","start",start);
            %shaved
            %MURBOverload
           % startTimes
            %endTimes
            
            
            plot(app.UIAxes,out.tout,out.inLoad.Data,out.tout,shaved);
            plot(app.UIAxes_2,out.tout,out.StateOfCharge.Data); 
            legend(app.UIAxes,'Initial Load (kW)','Shaved Load with BESS (kW)');
            app.computeOverloadSections(out.tout,shaved,MURBOverload);            
            
            %plot(app.UIAxes,out.tout,out.simout);
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.AutoButton
                
            % Get Time Data from Table            
            tableData = app.UITable.Data;
            x = datevec(tableData.Time);
            x = x(:,4);
            
            % Get Load Data from Table
            y = tableData.Load;
            
            % Set Shave Levels
            shaveLevels = round(min(y),-2):10:max(y);
            assignin("base","shaveLevels",shaveLevels);  
            
            % Reformat Data for Simulation
            ts=timeseries(y,x);    
            assignin("base","ts",ts);            
            
            
            % Set Simulation Time
            simTime = length(y)-1;
            
            % Show loading bar while simulation runs
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Determining Optimal Shave Level',...
                'Indeterminate','on');
            
            
            % Run Simulation
            optimizationOut = sim('Optimization_Model','StartTime','0','StopTime',num2str(simTime)); 
            
            % Find minimum of objective function                
            objectiveFunc=optimizationOut.objectiveFunction.Data(1,:,length(optimizationOut.objectiveFunction.Data));
            minimized = min(objectiveFunc);
            
                        
            optimizedShaveLevelIndex = objectiveFunc==minimized;
            optimizedShaveLevel = shaveLevels(optimizedShaveLevelIndex);
            
            close(d);
            close(fig);
            
            
            app.ChargeBatteryWhenLoadisBelowEditField.Value = optimizedShaveLevel;
            app.DischargeBatteryWhenLoadisAboveEditField.Value = optimizedShaveLevel;
                
            app.ChargeBatteryWhenLoadisBelowEditField.Enable=false;
            app.DischargeBatteryWhenLoadisAboveEditField.Enable=false;


            else
                app.ChargeBatteryWhenLoadisBelowEditField.Enable=true;
                app.DischargeBatteryWhenLoadisAboveEditField.Enable=true; 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time'; 'Load (KW)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true];
            app.UITable.ColumnEditable = [false true];
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Peak Shaving Demand')
            xlabel(app.UIAxes, 'Time (h)')
            ylabel(app.UIAxes, 'Load (kW)')
            app.UIAxes.XLim = [0 24];
            app.HourLoadProfileLabel = uilabel(app.UIFigure);
            app.HourLoadProfileLabel.Text = '24 Hour Load Profile';
            app.BatteryCapacitykWhLabel = uilabel(app.UIFigure);
            app.BatteryCapacitykWhLabel.Text = 'Battery Capacity (kWh):';
            app.BatteryCapacitykWhEditField = uieditfield(app.UIFigure, 'numeric');
            app.RatedPowerkWLabel = uilabel(app.UIFigure);
            app.RatedPowerkWLabel.Text = 'Rated Power (kW):';
            app.RatedPowerkWEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConversionEfficiencyLabel = uilabel(app.UIFigure);
            app.ConversionEfficiencyLabel.Text = 'Conversion Efficiency (%):';
            app.ConversionEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConversionEfficiencyEditField.Value = 100;
            app.AuxilleryPowerRequirementkWLabel = uilabel(app.UIFigure);
            app.AuxilleryPowerRequirementkWLabel.Text = 'Auxillery Power Requirement (kW):';
            app.AuxilleryPowerRequirementkWEditField = uieditfield(app.UIFigure, 'numeric');
            app.BatterySpecificationsLabel = uilabel(app.UIFigure);
            app.BatterySpecificationsLabel.Text = 'Battery Specifications';
            app.ChargeControllerSettingsLabel = uilabel(app.UIFigure);
            app.ChargeControllerSettingsLabel.Text = 'Charge Controller Settings';
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel = uilabel(app.UIFigure);
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel.Text = 'Discharge Battery When Load is Above: ';
            app.DischargeBatteryWhenLoadisAboveEditField = uieditfield(app.UIFigure, 'numeric');
            app.kWLabel = uilabel(app.UIFigure);
            app.kWLabel.Text = '(kW)';
            app.ChargeBatteryWhenLoadisBelowEditFieldLabel = uilabel(app.UIFigure);
            app.ChargeBatteryWhenLoadisBelowEditFieldLabel.Text = 'Charge Battery When Load is Below: ';
            app.ChargeBatteryWhenLoadisBelowEditField = uieditfield(app.UIFigure, 'numeric');
            app.kWLabel_2 = uilabel(app.UIFigure);
            app.kWLabel_2.Text = '(kW)';
            app.BESSPEAKSHAVINGAPPLabel = uilabel(app.UIFigure);
            app.BESSPEAKSHAVINGAPPLabel.Text = 'BESS PEAK SHAVING APP';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'State of Charge')
            xlabel(app.UIAxes_2, 'Time (h)')
            ylabel(app.UIAxes_2, 'State of Charge (%)')
            app.UIAxes_2.XLim = [0 24];
            app.UIAxes_2.YLim = [0 100];
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ManualButton_2 = uiradiobutton(app.ButtonGroup);
            app.ManualButton_2.Text = 'Manual';
            app.ManualButton_2.Value = true;
            app.AutoButton = uiradiobutton(app.ButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.EnterPowerLimitofMURBFacilityLabel = uilabel(app.UIFigure);
            app.EnterPowerLimitofMURBFacilityLabel.Text = 'Enter Power Limit of MURB Facility:';
            app.EnterPowerLimitofMURBFacilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.kWLabel_3 = uilabel(app.UIFigure);
            app.kWLabel_3.Text = '(kW)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Peak_Shaving

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end