classdef Peak_Shaving < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PeakShavingAppUIFigure         matlab.ui.Figure
        RateSettingsMenu               matlab.ui.container.Menu
        ResidentialMenu                matlab.ui.container.Menu
        BusinessMenu                   matlab.ui.container.Menu
        UITable                        matlab.ui.control.Table
        RunSimulationButton            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        HourLoadProfileLabel           matlab.ui.control.Label
        BatteryCapacitykWhLabel        matlab.ui.control.Label
        BatteryCapacitykWhEditField    matlab.ui.control.NumericEditField
        RatedPowerkWLabel              matlab.ui.control.Label
        RatedPowerkWEditField          matlab.ui.control.NumericEditField
        ConversionEfficiencyLabel      matlab.ui.control.Label
        ConversionEfficiencyEditField  matlab.ui.control.NumericEditField
        AuxilleryPowerRequirementkWLabel  matlab.ui.control.Label
        AuxilleryPowerRequirementkWEditField  matlab.ui.control.NumericEditField
        BatterySpecificationsLabel     matlab.ui.control.Label
        ChargeControllerSettingsLabel  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditFieldLabel  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditField_ON  matlab.ui.control.NumericEditField
        kWLabel                        matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditFieldLabel  matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_ON  matlab.ui.control.NumericEditField
        kWLabel_2                      matlab.ui.control.Label
        BESSPEAKSHAVINGAPPLabel        matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ManualButton_2                 matlab.ui.control.RadioButton
        AutoButton                     matlab.ui.control.RadioButton
        EnterPowerLimitofMURBFacilityLabel  matlab.ui.control.Label
        EnterPowerLimitofMURBFacilityEditField  matlab.ui.control.NumericEditField
        KVALabel                       matlab.ui.control.Label
        OnPeakTieredLabel              matlab.ui.control.Label
        MidPeakLabel                   matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_2Label  matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_MID  matlab.ui.control.NumericEditField
        kWLabel_4                      matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditFieldLabel_2  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditField_MID  matlab.ui.control.NumericEditField
        kWLabel_5                      matlab.ui.control.Label
        OffPeakLabel                   matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_2Label_2  matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_OFF  matlab.ui.control.NumericEditField
        kWLabel_6                      matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditFieldLabel_3  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditField_OFF  matlab.ui.control.NumericEditField
        kWLabel_7                      matlab.ui.control.Label
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        WeekdayButton                  matlab.ui.control.ToggleButton
        WeekendButton                  matlab.ui.control.ToggleButton
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        ResidentialButton              matlab.ui.control.ToggleButton
        BusinessButton                 matlab.ui.control.ToggleButton
        PowerFactorLabel               matlab.ui.control.Label
        PowerFactorEditField           matlab.ui.control.NumericEditField
        CustomerTypeLabel              matlab.ui.control.Label
        ButtonGroup_4                  matlab.ui.container.ButtonGroup
        TieredButton                   matlab.ui.control.ToggleButton
        TimeofUseButton                matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)
      RESSettingsDialogApp; %  Electricity Rate Settings Dialog App 
      BUSSettingsDialogApp; %  Electricity Rate Settings Dialog App
      
      loadProfileDay; % Indicates Weekday or Weekend      
      customerType; % Indicates customer type (Business or Residential)      
      customerRateType; %Indicates rate type (Tiered or Time of Use)      
     
      %%%%%%% Residential Settings Parameters %%%%%%%%%%%
        
      RESOnPeakHours; % On-Peak Time of Use Hours (used in settings box)
      RESmidPeakHours; % Mid-Peak Time of Use Hours (used in settings box)
      RESoffPeakHours; % Off-Peak Time of Use Hours (used in settings box)
      
      RESonPeakPrice; % On-Peak Electricity Rate (used in settings box)
      RESmidPeakPrice; % Mid-Peak Electricity Rate (used in settings box)
      RESoffPeakPrice; % Off-Peak Electricity Rate (used in settings box)
      
      REStierRateKW; % Tiered Rate Boundary (used in settings box)
      REStier1Price; % Tier 1 Pricing (used in settings box)
      REStier2Price; % Tier 2 Pricing (used in settings box)
      
      RESdeliveryPrice; % Delivery Charge Pricing (used in settings box)
      
      %%%%%%%%%% Business Settins Parameters %%%%%%%%%%%%
      
      BUSOnPeakHours; % On-Peak Time of Use Hours (used in settings box)
      BUSmidPeakHours; % Mid-Peak Time of Use Hours (used in settings box)
      BUSoffPeakHours; % Off-Peak Time of Use Hours (used in settings box)
      
      BUSonPeakPrice; % On-Peak Electricity Rate (used in settings box)
      BUSmidPeakPrice; % Mid-Peak Electricity Rate (used in settings box)
      BUSoffPeakPrice; % Off-Peak Electricity Rate (used in settings box)
      
      BUStierRateKW; % Tiered Rate Boundary (used in settings box)
      BUStier1Price; % Tier 1 Pricing (used in settings box)
      BUStier2Price; % Tier 2 Pricing (used in settings box)
      
      BUSdeliveryPrice1; % Lowest Delivery Charge Pricing (used in settings box)
      BUSdeliveryPrice2; % Delivery Charge Pricing (used in settings box)
      BUSdeliveryPrice3; % Delivery Charge Pricing (used in settings box)
      BUSdeliveryPrice4; % Highest Delivery Charge Pricing (used in settings box)
      
      %%%%%%%%%% Temp Function Return Values %%%%%%%%%%%%%%%%%
      
      tempDecimalTime; % Temp return value from 'convertTimetoDecimal' Function
      
    end
    
    methods (Access = private)
        
        function computeOverloadSections(app,time,shavedLoad,MURBLimit)
            
            startTimes = zeros(1,length(shavedLoad));
            endTimes = zeros(1,length(shavedLoad));
            
            % Find Overloaded sections
            overLoad = (shavedLoad>MURBLimit);
            overLoadDiff = diff(overLoad);
            
            startIndex = 1;
            endIndex = 1;
            
            for i = 1:length(overLoadDiff)
                
                if overLoadDiff(i) == 1
                    
                    startTimes(startIndex)=i+1;
                    startIndex = startIndex + 1;
                
                elseif overLoadDiff(i) == -1
                    
                    endTimes(endIndex)=i;
                    endIndex = endIndex + 1;
                    
                end
                
            end
                       
            
            start = nonzeros(startTimes)';
            assignin("base","start",start);
            
            endT = nonzeros(endTimes)';
            assignin("base","endT",endT);
            
                               
            % Add Overloaded Sections to Graph
            
            hold(app.UIAxes,'on');
            for k = 1:length(start)
            
            % Use interpolation to compute the exact crossing (START)
            
            % First Check if adjacent time values are equal (prevent error)
            if time(start(k))==time(start(k)-1)
                % Add in new point (same time value)
                time = [time(1:start(k)-1)' time(start(k)) time(start(k):length(time))']';
                shavedLoad = [shavedLoad(1:start(k)-1) MURBLimit shavedLoad(start(k):length(shavedLoad))];
            
            % Otherwise apply interpolation
            else
                % Obtain interpolated time value (intQX)
                intY = shavedLoad(start(k)-1:start(k));
                intX = time(start(k)-1:start(k));
                intYQ = MURBLimit;
                intXQ = interp1(intY,intX,intYQ);
                
                % Add interpolated interpolated value to existing vector
                time = [time(1:start(k)-1)' intXQ time(start(k):length(time))']';
                shavedLoad = [shavedLoad(1:start(k)-1) MURBLimit shavedLoad(start(k):length(shavedLoad))];
            end
            
            endT = endT + 1;
            
            % Use interpolation to compute the exact crossing (END)
            
            % First Check if adjacent time values are equal (prevent error)
            if time(endT(k))==time(endT(k)+1)
                % Add in new point (same time value)
                time = [time(1:endT(k))' time(endT(k)) time(endT(k)+1:length(time))']';
                shavedLoad = [shavedLoad(1:endT(k)) MURBLimit shavedLoad(endT(k)+1:length(shavedLoad))];
            
            % Otherwise apply interpolation
            else
                % Obtain interpolated time value (intQX)
                intY = shavedLoad(endT(k):endT(k)+1);
                intX = time(endT(k):endT(k)+1);
                intYQ = MURBLimit;
                intXQ = interp1(intY,intX,intYQ);
                
                % Add interpolated interpolated value to existing vector
                time = [time(1:endT(k))' intXQ time(endT(k)+1:length(time))']';
                shavedLoad = [shavedLoad(1:endT(k)) MURBLimit shavedLoad(endT(k)+1:length(shavedLoad))];
            end


            area(app.UIAxes,time(start(k):endT(k)+1),shavedLoad(start(k):endT(k)+1),MURBLimit,'FaceColor','r','ShowBaseLine','off','EdgeColor','r','FaceAlpha',0.5,'DisplayName','MURB Overloaded')  ;
            
            end
            hold(app.UIAxes,'off');
            
            
            if length(start) >= 1
                fig = uifigure;
                uialert(fig,'MURB Power Rating Has Been Exceeded. Consider Adjusting the Battery Settings','Overload Warning','CloseFcn',@(h,e) close(fig)); 
            end
            
            
        end  
    
        function convertTimetoDecimal(app,stringTime)
            timeVec=split(stringTime,":");
            HH=str2double(timeVec(1));
            ss=str2double(timeVec(2))/60;
            app.tempDecimalTime = HH+ss;
        end
      
    end
    
    methods (Access = public)
        
        function updateResidentialElectricityRateData(app,onPeakHours,midPeakHours,offPeakHours,onPeakPrice,midPeakPrice,offPeakPrice,tier1RateKW,tier1Price,tier2Price,deliveryPrice)
            app.RESOnPeakHours = onPeakHours;
            app.RESmidPeakHours = midPeakHours;
            app.RESoffPeakHours = offPeakHours;
            app.RESonPeakPrice = onPeakPrice;
            app.RESmidPeakPrice = midPeakPrice;
            app.RESoffPeakPrice = offPeakPrice;
            
            app.REStierRateKW = tier1RateKW; 
            app.REStier1Price = tier1Price; 
            app.REStier2Price = tier2Price; 
            
            app.RESdeliveryPrice = deliveryPrice;
            
        end
        
        function updateBusinessElectricityRateData(app,onPeakHours,midPeakHours,offPeakHours,onPeakPrice,midPeakPrice,offPeakPrice,tier1RateKW,tier1Price,tier2Price,deliveryPrice1,deliveryPrice2,deliveryPrice3,deliveryPrice4)
            app.BUSOnPeakHours = onPeakHours;
            app.BUSmidPeakHours = midPeakHours;
            app.BUSoffPeakHours = offPeakHours;
            app.BUSonPeakPrice = onPeakPrice;
            app.BUSmidPeakPrice = midPeakPrice;
            app.BUSoffPeakPrice = offPeakPrice;
            
            app.BUStierRateKW = tier1RateKW; 
            app.BUStier1Price = tier1Price; 
            app.BUStier2Price = tier2Price; 
            
            app.BUSdeliveryPrice1 = deliveryPrice1;
            app.BUSdeliveryPrice2 = deliveryPrice2;
            app.BUSdeliveryPrice3 = deliveryPrice3;
            app.BUSdeliveryPrice4 = deliveryPrice4;
        end         
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize Loading Table Data
            Load = [365 348 338 336 340 370 405 423 423 439 447 450 440 437 434 454 478 509 530 527 523 486 449 401]';
            TT = timetable(Load,'TimeStep',hours(1));
            T = timetable2table(TT);
            app.UITable.Data = T;
            
            % Initial Load Profile Options
            app.loadProfileDay = 'weekday';      
            app.customerType = 'residential';     
            app.customerRateType = 'tou';
            
            
            % Initialize Time of Use Electricity Rate Data (RESIDENTIAL)
            app.RESOnPeakHours = [7 11 17 19];
            app.RESmidPeakHours = [11 17]; 
            app.RESoffPeakHours = [19 7];           
            app.RESonPeakPrice = 8.5;
            app.RESmidPeakPrice = 8.5;
            app.RESoffPeakPrice = 8.5;
            
            app.REStierRateKW = 1000; 
            app.REStier1Price = 8.5; 
            app.REStier2Price = 8.5; 
            
            app.RESdeliveryPrice = 8.5;
            
            
            % Initialize Time of Use Electricity Rate Data (BUSINESS)
            app.BUSOnPeakHours = [7 11 17 19];
            app.BUSmidPeakHours = [11 17]; 
            app.BUSoffPeakHours = [19 7];           
            app.BUSonPeakPrice = 8.5;
            app.BUSmidPeakPrice = 8.5;
            app.BUSoffPeakPrice = 8.5;
            
            app.BUStierRateKW = 750; 
            app.BUStier1Price = 8.5; 
            app.BUStier2Price = 8.5; 
            
            app.BUSdeliveryPrice1 = 8.5; 
            app.BUSdeliveryPrice2 = 8.5;
            app.BUSdeliveryPrice3 = 8.5;
            app.BUSdeliveryPrice4 = 8.5;
                                                    
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            tableData = app.UITable.Data;
            
            % Get Time Data from Table
            x = datevec(tableData.Time);
            x = x(:,4);
            
            % Get Load Data from Table
            y = tableData.Load;
            
            %%%% Get Facility Overload Limit from Form %%%%
            MURBOverload = app.EnterPowerLimitofMURBFacilityEditField.Value;
            assignin("base","MURBOverload",MURBOverload);
            
            
            %%%%%%%%% Get BESS Data from Form %%%%%%%%
            % Battery Capcity
            BESSCapacity = app.BatteryCapacitykWhEditField.Value;
            assignin("base","BESSCapacity",BESSCapacity);
            
            % Initial State of Charge
            %initialSOC = app.InitialStateofChargeEditField.Value;
            %assignin("base","initialSOC",initialSOC);
            
            % Rated Power
            ratedPower = app.RatedPowerkWEditField.Value;
            assignin("base","ratedPower",ratedPower);
            
            ratedPowerNEG = -1*ratedPower;
            assignin("base","ratedPowerNEG",ratedPowerNEG);
            
            % Conversion Efficiency
            efficiency = app.ConversionEfficiencyEditField.Value;
            assignin("base","efficiency",efficiency);
            
            % Auxillery Power Requirement
            auxPower = app.AuxilleryPowerRequirementkWEditField.Value;
            assignin("base","auxPower",auxPower);
            
            
            %%%%%%%%% Get Electricity Rate Data from Settings %%%%%%%%                
            assignin("base","RESOnPeakHours1",app.RESOnPeakHours(1));            
            assignin("base","RESOnPeakHours2",app.RESOnPeakHours(2));          
            assignin("base","RESOnPeakHours3",app.RESOnPeakHours(3));           
            assignin("base","RESOnPeakHours4",app.RESOnPeakHours(4));
                                    
            assignin("base","RESMidPeakHours1",app.RESmidPeakHours(1));             
            assignin("base","RESMidPeakHours2",app.RESmidPeakHours(2));                 
            
            assignin("base","RESOffPeakHours1",app.RESoffPeakHours(1));            
            assignin("base","RESOffPeakHours2",app.RESoffPeakHours(2));
            
            assignin("base","RESOnPeakPrice",app.RESonPeakPrice);
            assignin("base","RESMidPeakPrice",app.RESonPeakPrice);
            assignin("base","OffPeakPrice",app.RESoffPeakPrice);
            
            
            assignin("base","REStierRateKW",app.REStierRateKW);
            assignin("base","REStier1Price",app.REStier1Price); 
            assignin("base","REStier2Price",app.REStier2Price); 
            
            assignin("base","RESdeliveryPrice",app.RESdeliveryPrice);  


            assignin("base","BUSOnPeakHours1",app.BUSOnPeakHours(1));            
            assignin("base","BUSOnPeakHours2",app.BUSOnPeakHours(2));          
            assignin("base","BUSOnPeakHours3",app.BUSOnPeakHours(3));           
            assignin("base","BUSOnPeakHours4",app.BUSOnPeakHours(4));
                                    
            assignin("base","BUSMidPeakHours1",app.BUSmidPeakHours(1));             
            assignin("base","BUSMidPeakHours2",app.BUSmidPeakHours(2));                 
            
            assignin("base","BUSOffPeakHours1",app.BUSoffPeakHours(1));            
            assignin("base","BUSOffPeakHours2",app.BUSoffPeakHours(2));
            
            assignin("base","BUSOnPeakPrice",app.BUSonPeakPrice);
            assignin("base","BUSMidPeakPrice",app.BUSonPeakPrice);
            assignin("base","BUSPeakPrice",app.BUSoffPeakPrice);


            %%%%%%%%% Get Charge Controller Data from Form %%%%%%%%              
            % Discharge Setting
            dischargeSetting = app.DischargeBatteryWhenLoadisAboveEditField_ON.Value;
            assignin("base","dischargeSetting",dischargeSetting);
            
            % Charge Setting
            chargeSetting = app.ChargeBatteryWhenLoadisBelowEditField_ON.Value;
            assignin("base","chargeSetting",chargeSetting);


            % Reformat Data for Simulation
            ts=timeseries(y,x);    
            assignin("base","ts",ts);
            
            % Get Saturation Limit
            %satLim = str2num(app.SaturationLimitEditField.Value);
            %assignin("base","satLim",satLim);
            
            % Set Simulation Time
            simTime = length(y)-1;
            
            %Run Simulation
            out = sim('Peak_Shave_Model','StartTime','0','StopTime',num2str(simTime));       
            
            
            % Update Plot
            %plot(app.UIAxes,out.tout,out.initialLoad,out.tout,out.chargeDischarge);
            shaved = reshape(out.simout.Data,1,[]);     
            
            
            %assignin("base","start",start);
            %shaved
            %MURBOverload
           % startTimes
            %endTimes
            
            
            plot(app.UIAxes,out.tout,out.inLoad.Data,out.tout,shaved);
            plot(app.UIAxes_2,out.tout,out.StateOfCharge.Data); 
            legend(app.UIAxes,'Initial Load (kW)','Shaved Load with BESS (kW)');
            app.computeOverloadSections(out.tout,shaved,MURBOverload);            
            
            %plot(app.UIAxes,out.tout,out.simout);
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.AutoButton
                
            % Get Time Data from Table            
            tableData = app.UITable.Data;
            x = datevec(tableData.Time);
            x = x(:,4);
            
            % Get Load Data from Table
            y = tableData.Load;
            
            % Set Shave Levels
            shaveLevels = round(min(y),-2):10:max(y);
            assignin("base","shaveLevels",shaveLevels);  
            
            % Reformat Data for Simulation
            ts=timeseries(y,x);    
            assignin("base","ts",ts);            
            
            
            % Set Simulation Time
            simTime = length(y)-1;
            
            % Show loading bar while simulation runs
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Determining Optimal Shave Level',...
                'Indeterminate','on');
            
            
            % Run Simulation
            optimizationOut = sim('Optimization_Model','StartTime','0','StopTime',num2str(simTime)); 
            
            % Find minimum of objective function                
            objectiveFunc=optimizationOut.objectiveFunction.Data(1,:,length(optimizationOut.objectiveFunction.Data));
            minimized = min(objectiveFunc);
            
                        
            optimizedShaveLevelIndex = objectiveFunc==minimized;
            optimizedShaveLevel = shaveLevels(optimizedShaveLevelIndex);
            
            close(d);
            close(fig);
            
            
            app.ChargeBatteryWhenLoadisBelowEditField_ON.Value = optimizedShaveLevel;
            app.DischargeBatteryWhenLoadisAboveEditField_ON.Value = optimizedShaveLevel;
                
            app.ChargeBatteryWhenLoadisBelowEditField_ON.Enable=false;
            app.DischargeBatteryWhenLoadisAboveEditField_ON.Enable=false;


            else
                app.ChargeBatteryWhenLoadisBelowEditField_ON.Enable=true;
                app.DischargeBatteryWhenLoadisAboveEditField_ON.Enable=true; 
            end
            
        end

        % Menu selected function: ResidentialMenu
        function ResidentialMenuSelected(app, event)
            app.RESSettingsDialogApp = Peak_Shaving_Settings_Dialog_RESIDENTIAL(app,app.RESOnPeakHours, app.RESmidPeakHours, app.RESoffPeakHours, app.RESonPeakPrice, app.RESmidPeakPrice, app.RESoffPeakPrice, app.REStierRateKW, app.REStier1Price, app.REStier2Price,app.RESdeliveryPrice);            
        end

        % Close request function: PeakShavingAppUIFigure
        function PeakShavingAppUIFigureCloseRequest(app, event)
            delete(app.RESSettingsDialogApp) 
            delete(app.BUSSettingsDialogApp)
            delete(app)            
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            if app.ResidentialButton.Value                
                app.customerType = 'residential';               
            else
                app.customerType = 'business';
            end
            
            
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)
            if app.TimeofUseButton.Value
                app.customerRateType = 'tou';
                
                app.ChargeBatteryWhenLoadisBelowEditField_MID.Enable = true;
                app.DischargeBatteryWhenLoadisAboveEditField_MID.Enable = true;
                
                app.ChargeBatteryWhenLoadisBelowEditField_OFF.Enable = true;
                app.DischargeBatteryWhenLoadisAboveEditField_OFF.Enable = true;
                
            else
                app.customerRateType = 'tiered';
                
                app.ChargeBatteryWhenLoadisBelowEditField_MID.Enable = false;
                app.ChargeBatteryWhenLoadisBelowEditField_MID.Value = 0;
                app.DischargeBatteryWhenLoadisAboveEditField_MID.Enable = false;
                app.DischargeBatteryWhenLoadisAboveEditField_MID.Value = 0;
                
                app.ChargeBatteryWhenLoadisBelowEditField_OFF.Enable = false;
                app.ChargeBatteryWhenLoadisBelowEditField_OFF.Value = 0;
                app.DischargeBatteryWhenLoadisAboveEditField_OFF.Enable = false;
                app.DischargeBatteryWhenLoadisAboveEditField_OFF.Value = 0;
                
            end
            
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            if app.WeekdayButton.Value
                app.loadProfileDay = 'weekday';
            else 
                app.loadProfileDay = 'weekend';
            end
            
        end

        % Menu selected function: BusinessMenu
        function BusinessMenuSelected(app, event)
            app.RESSettingsDialogApp = Peak_Shaving_Settings_Dialog_BUSINESS(app,app.BUSOnPeakHours, app.BUSmidPeakHours, app.BUSoffPeakHours, app.BUSonPeakPrice, app.BUSmidPeakPrice, app.BUSoffPeakPrice, app.BUStierRateKW, app.BUStier1Price, app.BUStier2Price,app.BUSdeliveryPrice1,app.BUSdeliveryPrice2,app.BUSdeliveryPrice3,app.BUSdeliveryPrice4);   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PeakShavingAppUIFigure = uifigure('Visible', 'off');
            app.PeakShavingAppUIFigure.Name = 'Peak Shaving App';
            app.PeakShavingAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @PeakShavingAppUIFigureCloseRequest, true);
            app.RateSettingsMenu = uimenu(app.PeakShavingAppUIFigure);
            app.RateSettingsMenu.Text = 'Rate Settings';
            app.ResidentialMenu = uimenu(app.RateSettingsMenu);
            app.ResidentialMenu.MenuSelectedFcn = createCallbackFcn(app, @ResidentialMenuSelected, true);
            app.ResidentialMenu.Text = 'Residential';
            app.BusinessMenu = uimenu(app.RateSettingsMenu);
            app.BusinessMenu.MenuSelectedFcn = createCallbackFcn(app, @BusinessMenuSelected, true);
            app.BusinessMenu.Text = 'Business';
            app.UITable = uitable(app.PeakShavingAppUIFigure);
            app.UITable.ColumnName = {'Time'; 'Load (KW)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true];
            app.UITable.ColumnEditable = [false true];
            app.RunSimulationButton = uibutton(app.PeakShavingAppUIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.UIAxes = uiaxes(app.PeakShavingAppUIFigure);
            title(app.UIAxes, 'Peak Shaving Demand')
            xlabel(app.UIAxes, 'Time (h)')
            ylabel(app.UIAxes, 'Load (kW)')
            app.UIAxes.XLim = [0 24];
            app.HourLoadProfileLabel = uilabel(app.PeakShavingAppUIFigure);
            app.HourLoadProfileLabel.Text = '24 Hour Load Profile';
            app.BatteryCapacitykWhLabel = uilabel(app.PeakShavingAppUIFigure);
            app.BatteryCapacitykWhLabel.Text = 'Battery Capacity (kWh):';
            app.BatteryCapacitykWhEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.RatedPowerkWLabel = uilabel(app.PeakShavingAppUIFigure);
            app.RatedPowerkWLabel.Text = 'Rated Power (kW):';
            app.RatedPowerkWEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.ConversionEfficiencyLabel = uilabel(app.PeakShavingAppUIFigure);
            app.ConversionEfficiencyLabel.Text = 'Conversion Efficiency (%):';
            app.ConversionEfficiencyEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.ConversionEfficiencyEditField.Value = 100;
            app.AuxilleryPowerRequirementkWLabel = uilabel(app.PeakShavingAppUIFigure);
            app.AuxilleryPowerRequirementkWLabel.Text = 'Auxillery Power Requirement (kW):';
            app.AuxilleryPowerRequirementkWEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.BatterySpecificationsLabel = uilabel(app.PeakShavingAppUIFigure);
            app.BatterySpecificationsLabel.Text = 'Battery Specifications';
            app.ChargeControllerSettingsLabel = uilabel(app.PeakShavingAppUIFigure);
            app.ChargeControllerSettingsLabel.Text = 'Charge Controller Settings';
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel = uilabel(app.PeakShavingAppUIFigure);
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel.Text = 'Discharge Battery When Load is Above: ';
            app.DischargeBatteryWhenLoadisAboveEditField_ON = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.kWLabel = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel.Text = '(kW)';
            app.ChargeBatteryWhenLoadisBelowEditFieldLabel = uilabel(app.PeakShavingAppUIFigure);
            app.ChargeBatteryWhenLoadisBelowEditFieldLabel.Text = 'Charge Battery When Load is Below: ';
            app.ChargeBatteryWhenLoadisBelowEditField_ON = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.kWLabel_2 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_2.Text = '(kW)';
            app.BESSPEAKSHAVINGAPPLabel = uilabel(app.PeakShavingAppUIFigure);
            app.BESSPEAKSHAVINGAPPLabel.Text = 'BESS PEAK SHAVING APP';
            app.UIAxes_2 = uiaxes(app.PeakShavingAppUIFigure);
            title(app.UIAxes_2, 'State of Charge')
            xlabel(app.UIAxes_2, 'Time (h)')
            ylabel(app.UIAxes_2, 'State of Charge (%)')
            app.UIAxes_2.XLim = [0 24];
            app.UIAxes_2.YLim = [0 100];
            app.ButtonGroup = uibuttongroup(app.PeakShavingAppUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ManualButton_2 = uiradiobutton(app.ButtonGroup);
            app.ManualButton_2.Enable = 'off';
            app.ManualButton_2.Text = 'Manual';
            app.ManualButton_2.Value = true;
            app.AutoButton = uiradiobutton(app.ButtonGroup);
            app.AutoButton.Enable = 'off';
            app.AutoButton.Text = 'Auto';
            app.EnterPowerLimitofMURBFacilityLabel = uilabel(app.PeakShavingAppUIFigure);
            app.EnterPowerLimitofMURBFacilityLabel.Text = 'Enter Power Limit of MURB Facility:';
            app.EnterPowerLimitofMURBFacilityEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.KVALabel = uilabel(app.PeakShavingAppUIFigure);
            app.KVALabel.Text = '(KVA)';
            app.OnPeakTieredLabel = uilabel(app.PeakShavingAppUIFigure);
            app.OnPeakTieredLabel.Text = 'On-Peak / Tiered';
            app.MidPeakLabel = uilabel(app.PeakShavingAppUIFigure);
            app.MidPeakLabel.Text = 'Mid-Peak';
            app.ChargeBatteryWhenLoadisBelowEditField_2Label = uilabel(app.PeakShavingAppUIFigure);
            app.ChargeBatteryWhenLoadisBelowEditField_2Label.Text = 'Charge Battery When Load is Below:';
            app.ChargeBatteryWhenLoadisBelowEditField_MID = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.kWLabel_4 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_4.Text = '(kW)';
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel_2 = uilabel(app.PeakShavingAppUIFigure);
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel_2.Text = 'Discharge Battery When Load is Above: ';
            app.DischargeBatteryWhenLoadisAboveEditField_MID = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.kWLabel_5 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_5.Text = '(kW)';
            app.OffPeakLabel = uilabel(app.PeakShavingAppUIFigure);
            app.OffPeakLabel.Text = 'Off-Peak';
            app.ChargeBatteryWhenLoadisBelowEditField_2Label_2 = uilabel(app.PeakShavingAppUIFigure);
            app.ChargeBatteryWhenLoadisBelowEditField_2Label_2.Text = 'Charge Battery When Load is Below:';
            app.ChargeBatteryWhenLoadisBelowEditField_OFF = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.kWLabel_6 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_6.Text = '(kW)';
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel_3 = uilabel(app.PeakShavingAppUIFigure);
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel_3.Text = 'Discharge Battery When Load is Above: ';
            app.DischargeBatteryWhenLoadisAboveEditField_OFF = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.kWLabel_7 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_7.Text = '(kW)';
            app.ButtonGroup_2 = uibuttongroup(app.PeakShavingAppUIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.WeekdayButton = uitogglebutton(app.ButtonGroup_2);
            app.WeekdayButton.Text = 'Weekday';
            app.WeekdayButton.Value = true;
            app.WeekendButton = uitogglebutton(app.ButtonGroup_2);
            app.WeekendButton.Text = 'Weekend';
            app.ButtonGroup_3 = uibuttongroup(app.PeakShavingAppUIFigure);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.ResidentialButton = uitogglebutton(app.ButtonGroup_3);
            app.ResidentialButton.Text = 'Residential';
            app.ResidentialButton.Value = true;
            app.BusinessButton = uitogglebutton(app.ButtonGroup_3);
            app.BusinessButton.Text = 'Business';
            app.PowerFactorLabel = uilabel(app.PeakShavingAppUIFigure);
            app.PowerFactorLabel.Text = 'Power Factor:';
            app.PowerFactorEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.PowerFactorEditField.Value = 0.95;
            app.CustomerTypeLabel = uilabel(app.PeakShavingAppUIFigure);
            app.CustomerTypeLabel.Text = 'Customer Type:';
            app.ButtonGroup_4 = uibuttongroup(app.PeakShavingAppUIFigure);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.TieredButton = uitogglebutton(app.ButtonGroup_4);
            app.TieredButton.Text = 'Tiered';
            app.TimeofUseButton = uitogglebutton(app.ButtonGroup_4);
            app.TimeofUseButton.Text = 'Time of Use';
            app.TimeofUseButton.Value = true;
            % Show the figure after all components are created
            app.PeakShavingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Peak_Shaving

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PeakShavingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PeakShavingAppUIFigure)
        end
    end
end