classdef Peak_Shaving_Settings_Dialog_BUSINESS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElectricityRateSettingsUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TimeofUseRatesTab              matlab.ui.container.Tab
        TimeofUsePeriodsBusinessLabel  matlab.ui.control.Label
        OnPeakLabel                    matlab.ui.control.Label
        OnPeakDropDown1                matlab.ui.control.DropDown
        WeekdaysfromLabel              matlab.ui.control.Label
        toLabel                        matlab.ui.control.Label
        OnPeakDropDown2                matlab.ui.control.DropDown
        andLabel                       matlab.ui.control.Label
        OnPeakDropDown3                matlab.ui.control.DropDown
        toLabel_2                      matlab.ui.control.Label
        OnPeakDropDown4                matlab.ui.control.DropDown
        MidPeakLabel                   matlab.ui.control.Label
        WeekdaysfromLabel_2            matlab.ui.control.Label
        MidPeakDropDown1               matlab.ui.control.DropDown
        MidPeakDropDown2               matlab.ui.control.DropDown
        toLabel_3                      matlab.ui.control.Label
        OffPeakLabel                   matlab.ui.control.Label
        WeekdaysfromLabel_3            matlab.ui.control.Label
        OffPeakDropDown1               matlab.ui.control.DropDown
        toLabel_4                      matlab.ui.control.Label
        OffPeakDropDown2               matlab.ui.control.DropDown
        andalldayweekendsandholidaysLabel  matlab.ui.control.Label
        PricingLabel                   matlab.ui.control.Label
        perkWhLabel                    matlab.ui.control.Label
        perkWhLabel_2                  matlab.ui.control.Label
        perkWhLabel_3                  matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        OnPeakLabel_2                  matlab.ui.control.Label
        OnPeakEditField                matlab.ui.control.NumericEditField
        MidPeakLabel_2                 matlab.ui.control.Label
        MidPeakEditField               matlab.ui.control.NumericEditField
        OffPeakLabel_2                 matlab.ui.control.Label
        OffPeakEditField               matlab.ui.control.NumericEditField
        TieredRatesTab                 matlab.ui.container.Tab
        TieredRatePricingper30daysBusinessLabel  matlab.ui.control.Label
        PriceTierLabel                 matlab.ui.control.Label
        DescriptionLabel               matlab.ui.control.Label
        PriceLabel                     matlab.ui.control.Label
        Tier1Label                     matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        UptoEditFieldLabel             matlab.ui.control.Label
        Tier1DescriptionEditField      matlab.ui.control.NumericEditField
        kWhmonthLabel                  matlab.ui.control.Label
        Tier2DescriptionEditField      matlab.ui.control.NumericEditField
        kWhmonthLabel_2                matlab.ui.control.Label
        Tier2Label                     matlab.ui.control.Label
        perkWhEditFieldLabel           matlab.ui.control.Label
        Tier1PriceEditField            matlab.ui.control.NumericEditField
        perkWhEditField_2Label         matlab.ui.control.Label
        Tier2PriceEditField            matlab.ui.control.NumericEditField
        OKButton_2                     matlab.ui.control.Button
        MorethanLabel                  matlab.ui.control.Label
        DeliveryChargesTab             matlab.ui.container.Tab
        DeliveryChargesBusinessLabel   matlab.ui.control.Label
        PriceEditFieldLabel            matlab.ui.control.Label
        DeliveryPriceEditField1        matlab.ui.control.NumericEditField
        perpeakkVALabel                matlab.ui.control.Label
        OKButton_3                     matlab.ui.control.Button
        MonthlyDemandofLessthan50kWLabel  matlab.ui.control.Label
        MonthlyDemandof50kWto999kWLabel  matlab.ui.control.Label
        PriceEditFieldLabel_2          matlab.ui.control.Label
        DeliveryPriceEditField2        matlab.ui.control.NumericEditField
        perpeakkVALabel_2              matlab.ui.control.Label
        MonthlyDemandof1000kWto4999kWLabel  matlab.ui.control.Label
        PriceEditFieldLabel_3          matlab.ui.control.Label
        DeliveryPriceEditField3        matlab.ui.control.NumericEditField
        perpeakkVALabel_3              matlab.ui.control.Label
        MonthlyDemandof5000kWandaboveLabel  matlab.ui.control.Label
        PriceEditFieldLabel_4          matlab.ui.control.Label
        DeliveryPriceEditField4        matlab.ui.control.NumericEditField
        perpeakkVALabel_4              matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Main App Object        
        validTimeOfUseSequence % Indicates whether Time of Use data is valid        
    end
    
    methods (Access = private)
        
        % Send all current form data to the main app and close window
        function sendDataToMainApp(app)
            % Get Time of Use Data From Form
            onPeakHours = [str2num(app.OnPeakDropDown1.Value(1:2)) str2num(app.OnPeakDropDown2.Value(1:2)) str2num(app.OnPeakDropDown3.Value(1:2)) str2num(app.OnPeakDropDown4.Value(1:2))];
            midPeakHours = [str2num(app.MidPeakDropDown1.Value(1:2)) str2num(app.MidPeakDropDown2.Value(1:2))];
            offPeakHours = [str2num(app.OffPeakDropDown1.Value(1:2)) str2num(app.OffPeakDropDown2.Value(1:2))];
            
             % Get Time of Use Pricing From Form
            onPeakPrice = app.OnPeakEditField.Value;
            midPeakPrice = app.MidPeakEditField.Value;
            offPeakPrice = app.OffPeakEditField.Value;
            
            % Get Tiered Rate Pricing From Form
            tierRateKW = app.Tier1DescriptionEditField.Value;
            tier1Price = app.Tier1PriceEditField.Value;
            tier2Price = app.Tier2PriceEditField.Value;
            
            % Get Delivery Charges From Form
            deliveryPrice1 = app.DeliveryPriceEditField1.Value;
            deliveryPrice2 = app.DeliveryPriceEditField2.Value;
            deliveryPrice3 = app.DeliveryPriceEditField3.Value;
            deliveryPrice4 = app.DeliveryPriceEditField4.Value;
            
            
            % Check That Time Of Use Selection is Valid
            validateTimeOfUseSelection(app,onPeakHours(1:2),onPeakHours(3:4),midPeakHours,offPeakHours);
            
            % If Valid then Update Main App
            if app.validTimeOfUseSequence
                updateBusinessElectricityRateData(app.CallingApp,onPeakHours,midPeakHours,offPeakHours,onPeakPrice,midPeakPrice,offPeakPrice,tierRateKW,tier1Price,tier2Price,deliveryPrice1,deliveryPrice2,deliveryPrice3,deliveryPrice4);
                delete(app);
                % Otherwise Alert User of Selection Error
            else
                app.TabGroup.SelectedTab=app.TimeofUseRatesTab;                
                msgbox('Time of Use Sequence is Invalid','Invalid Selection','error');
            end
        end
        
       
        % Checks that time of use data is a valid time sequence
        function validateTimeOfUseSelection(app,onPeak1,onPeak2,midPeak,offPeak)
                  
            % Check for time overflow scenario (time1 > time2)
            onPeakOverflow1 = [0 0];
            if ~issorted(onPeak1)    
                onPeakOverflow1=[onPeak1(1) 24];
                onPeak1(1)=0;
            end

            % Check for time overflow scenario (time1 > time2)
            onPeakOverflow2 = [0 0];
            if ~issorted(onPeak2)    
                onPeakOverflow2=[onPeak2(1) 24];
                onPeak2(1)=0;
            end


            % Check for time overflow scenario (time1 > time2)
            midPeakOverflow = [0 0];
            if ~issorted(midPeak)    
                midPeakOverflow=[midPeak(1) 24];
                midPeak(1)=0;
            end


            % Check for time overflow scenario (time1 > time2)
            offPeakOverflow = [0 0];
            if ~issorted(offPeak)    
                offPeakOverflow=[offPeak(1) 24];
                offPeak(1)=0;
            end

            % Combine Pairs into Matrix Format
            timeMatrix = [onPeak1;onPeakOverflow1;onPeak2;onPeakOverflow2;midPeak;midPeakOverflow;offPeak;offPeakOverflow];

            % Sort Rows From Smallest to Largest
            sortedMatrix = sortrows(timeMatrix);

            % Remove Blank Rows ([0 0])
            while sortedMatrix(1,:) == [0 0]
                sortedMatrix = sortedMatrix((2):length(sortedMatrix),:);    
            end

            % Assume Sequence is Valid
            app.validTimeOfUseSequence = 1; % True

            % Check first and last values (should be 0 and 24 respectively)
            if (sortedMatrix(1,1)~=0) || (sortedMatrix(length(sortedMatrix),2)~=24)
                app.validTimeOfUseSequence = 0;
            end


            % Loop through and check that sequence is valid
            for i = 2:length(sortedMatrix)
                if sortedMatrix(i,1)~=sortedMatrix(i-1,2)
                    app.validTimeOfUseSequence = 0;
                    break
                end
            end  
            
            end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, onPeakHours, midPeakHours, offPeakHours, onPeakPrice, midPeakPrice, offPeakPrice, tierRateKW, tier1Price, tier2Price, deliveryPrice1, deliveryPrice2, deliveryPrice3, deliveryPrice4)
             app.CallingApp = mainApp;       
                        
             app.OnPeakDropDown1.Value = [num2str(onPeakHours(1),'%02d') ':00']; 
             app.OnPeakDropDown2.Value = [num2str(onPeakHours(2),'%02d') ':00'];
             app.OnPeakDropDown3.Value = [num2str(onPeakHours(3),'%02d') ':00']; 
             app.OnPeakDropDown4.Value = [num2str(onPeakHours(4),'%02d') ':00']; 
             
             app.MidPeakDropDown1.Value = [num2str(midPeakHours(1),'%02d') ':00'];
             app.MidPeakDropDown2.Value = [num2str(midPeakHours(2),'%02d') ':00'];
             
             app.OffPeakDropDown1.Value = [num2str(offPeakHours(1),'%02d') ':00'];
             app.OffPeakDropDown2.Value = [num2str(offPeakHours(2),'%02d') ':00'];
             
             app.OnPeakEditField.Value = onPeakPrice;
             app.MidPeakEditField.Value = midPeakPrice;
             app.OffPeakEditField.Value = offPeakPrice;
             
             app.Tier1DescriptionEditField.Value = tierRateKW;
             app.Tier2DescriptionEditField.Value = tierRateKW;
             
             app.Tier1PriceEditField.Value = tier1Price;
             app.Tier2PriceEditField.Value = tier2Price;
             
             app.DeliveryPriceEditField1.Value = deliveryPrice1;
             app.DeliveryPriceEditField2.Value = deliveryPrice2;
             app.DeliveryPriceEditField3.Value = deliveryPrice3;
             app.DeliveryPriceEditField4.Value = deliveryPrice4;
            
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            sendDataToMainApp(app);
        end

        % Value changed function: Tier1DescriptionEditField
        function Tier1DescriptionEditFieldValueChanged(app, event)
            value = app.Tier1DescriptionEditField.Value;
            app.Tier2DescriptionEditField.Value = value;
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
            sendDataToMainApp(app);
        end

        % Button pushed function: OKButton_3
        function OKButton_3Pushed(app, event)
            sendDataToMainApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElectricityRateSettingsUIFigure = uifigure('Visible', 'off');
            app.ElectricityRateSettingsUIFigure.Name = 'Electricity Rate Settings';
            app.TabGroup = uitabgroup(app.ElectricityRateSettingsUIFigure);
            app.TimeofUseRatesTab = uitab(app.TabGroup);
            app.TimeofUseRatesTab.Title = 'Time of Use Rates';
            app.TimeofUsePeriodsBusinessLabel = uilabel(app.TimeofUseRatesTab);
            app.TimeofUsePeriodsBusinessLabel.Text = 'Time of Use Periods - Business';
            app.OnPeakLabel = uilabel(app.TimeofUseRatesTab);
            app.OnPeakLabel.Text = 'On-Peak';
            app.OnPeakDropDown1 = uidropdown(app.TimeofUseRatesTab);
            app.OnPeakDropDown1.Items = {'00:00', '01:00', '02:00', '03:00', '04:00', '05:00', '06:00', '07:00', '08:00', '09:00', '10:00', '11:00', '12:00', '13:00', '14:00', '15:00', '16:00', '17:00', '18:00', '19:00', '20:00', '21:00', '22:00', '23:00', '24:00'};
            app.OnPeakDropDown1.Value = '00:00';
            app.WeekdaysfromLabel = uilabel(app.TimeofUseRatesTab);
            app.WeekdaysfromLabel.Text = 'Weekdays from:';
            app.toLabel = uilabel(app.TimeofUseRatesTab);
            app.toLabel.Text = 'to';
            app.OnPeakDropDown2 = uidropdown(app.TimeofUseRatesTab);
            app.OnPeakDropDown2.Items = {'00:00', '01:00', '02:00', '03:00', '04:00', '05:00', '06:00', '07:00', '08:00', '09:00', '10:00', '11:00', '12:00', '13:00', '14:00', '15:00', '16:00', '17:00', '18:00', '19:00', '20:00', '21:00', '22:00', '23:00', '24:00'};
            app.OnPeakDropDown2.Value = '00:00';
            app.andLabel = uilabel(app.TimeofUseRatesTab);
            app.andLabel.Text = 'and';
            app.OnPeakDropDown3 = uidropdown(app.TimeofUseRatesTab);
            app.OnPeakDropDown3.Items = {'00:00', '01:00', '02:00', '03:00', '04:00', '05:00', '06:00', '07:00', '08:00', '09:00', '10:00', '11:00', '12:00', '13:00', '14:00', '15:00', '16:00', '17:00', '18:00', '19:00', '20:00', '21:00', '22:00', '23:00', '24:00'};
            app.OnPeakDropDown3.Value = '00:00';
            app.toLabel_2 = uilabel(app.TimeofUseRatesTab);
            app.toLabel_2.Text = 'to';
            app.OnPeakDropDown4 = uidropdown(app.TimeofUseRatesTab);
            app.OnPeakDropDown4.Items = {'00:00', '01:00', '02:00', '03:00', '04:00', '05:00', '06:00', '07:00', '08:00', '09:00', '10:00', '11:00', '12:00', '13:00', '14:00', '15:00', '16:00', '17:00', '18:00', '19:00', '20:00', '21:00', '22:00', '23:00', '24:00'};
            app.OnPeakDropDown4.Value = '00:00';
            app.MidPeakLabel = uilabel(app.TimeofUseRatesTab);
            app.MidPeakLabel.Text = 'Mid-Peak';
            app.WeekdaysfromLabel_2 = uilabel(app.TimeofUseRatesTab);
            app.WeekdaysfromLabel_2.Text = 'Weekdays from:';
            app.MidPeakDropDown1 = uidropdown(app.TimeofUseRatesTab);
            app.MidPeakDropDown1.Items = {'00:00', '01:00', '02:00', '03:00', '04:00', '05:00', '06:00', '07:00', '08:00', '09:00', '10:00', '11:00', '12:00', '13:00', '14:00', '15:00', '16:00', '17:00', '18:00', '19:00', '20:00', '21:00', '22:00', '23:00', '24:00'};
            app.MidPeakDropDown1.Value = '00:00';
            app.MidPeakDropDown2 = uidropdown(app.TimeofUseRatesTab);
            app.MidPeakDropDown2.Items = {'00:00', '01:00', '02:00', '03:00', '04:00', '05:00', '06:00', '07:00', '08:00', '09:00', '10:00', '11:00', '12:00', '13:00', '14:00', '15:00', '16:00', '17:00', '18:00', '19:00', '20:00', '21:00', '22:00', '23:00', '24:00'};
            app.MidPeakDropDown2.Value = '00:00';
            app.toLabel_3 = uilabel(app.TimeofUseRatesTab);
            app.toLabel_3.Text = 'to';
            app.OffPeakLabel = uilabel(app.TimeofUseRatesTab);
            app.OffPeakLabel.Text = 'Off-Peak';
            app.WeekdaysfromLabel_3 = uilabel(app.TimeofUseRatesTab);
            app.WeekdaysfromLabel_3.Text = 'Weekdays from:';
            app.OffPeakDropDown1 = uidropdown(app.TimeofUseRatesTab);
            app.OffPeakDropDown1.Items = {'00:00', '01:00', '02:00', '03:00', '04:00', '05:00', '06:00', '07:00', '08:00', '09:00', '10:00', '11:00', '12:00', '13:00', '14:00', '15:00', '16:00', '17:00', '18:00', '19:00', '20:00', '21:00', '22:00', '23:00', '24:00'};
            app.OffPeakDropDown1.Value = '00:00';
            app.toLabel_4 = uilabel(app.TimeofUseRatesTab);
            app.toLabel_4.Text = 'to';
            app.OffPeakDropDown2 = uidropdown(app.TimeofUseRatesTab);
            app.OffPeakDropDown2.Items = {'00:00', '01:00', '02:00', '03:00', '04:00', '05:00', '06:00', '07:00', '08:00', '09:00', '10:00', '11:00', '12:00', '13:00', '14:00', '15:00', '16:00', '17:00', '18:00', '19:00', '20:00', '21:00', '22:00', '23:00', '24:00'};
            app.OffPeakDropDown2.Value = '00:00';
            app.andalldayweekendsandholidaysLabel = uilabel(app.TimeofUseRatesTab);
            app.andalldayweekendsandholidaysLabel.Text = 'and all day weekends and holidays';
            app.PricingLabel = uilabel(app.TimeofUseRatesTab);
            app.PricingLabel.Text = 'Pricing ';
            app.perkWhLabel = uilabel(app.TimeofUseRatesTab);
            app.perkWhLabel.Text = '₵ per kWh';
            app.perkWhLabel_2 = uilabel(app.TimeofUseRatesTab);
            app.perkWhLabel_2.Text = '₵ per kWh';
            app.perkWhLabel_3 = uilabel(app.TimeofUseRatesTab);
            app.perkWhLabel_3.Text = '₵ per kWh';
            app.OKButton = uibutton(app.TimeofUseRatesTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.OnPeakLabel_2 = uilabel(app.TimeofUseRatesTab);
            app.OnPeakLabel_2.Text = 'On - Peak:';
            app.OnPeakEditField = uieditfield(app.TimeofUseRatesTab, 'numeric');
            app.OnPeakEditField.Limits = [0 Inf];
            app.MidPeakLabel_2 = uilabel(app.TimeofUseRatesTab);
            app.MidPeakLabel_2.Text = 'Mid - Peak:';
            app.MidPeakEditField = uieditfield(app.TimeofUseRatesTab, 'numeric');
            app.MidPeakEditField.Limits = [0 Inf];
            app.OffPeakLabel_2 = uilabel(app.TimeofUseRatesTab);
            app.OffPeakLabel_2.Text = 'Off - Peak:';
            app.OffPeakEditField = uieditfield(app.TimeofUseRatesTab, 'numeric');
            app.OffPeakEditField.Limits = [0 Inf];
            app.TieredRatesTab = uitab(app.TabGroup);
            app.TieredRatesTab.Title = 'Tiered Rates';
            app.TieredRatePricingper30daysBusinessLabel = uilabel(app.TieredRatesTab);
            app.TieredRatePricingper30daysBusinessLabel.Text = 'Tiered Rate Pricing per 30 days - Business';
            app.PriceTierLabel = uilabel(app.TieredRatesTab);
            app.PriceTierLabel.Text = 'Price Tier';
            app.DescriptionLabel = uilabel(app.TieredRatesTab);
            app.DescriptionLabel.Text = 'Description';
            app.PriceLabel = uilabel(app.TieredRatesTab);
            app.PriceLabel.Text = 'Price';
            app.Tier1Label = uilabel(app.TieredRatesTab);
            app.Tier1Label.Text = 'Tier 1';
            app.Label = uilabel(app.TieredRatesTab);
            app.Label.Text = '___________________________________________________________________________';
            app.UptoEditFieldLabel = uilabel(app.TieredRatesTab);
            app.UptoEditFieldLabel.Text = 'Up to:';
            app.Tier1DescriptionEditField = uieditfield(app.TieredRatesTab, 'numeric');
            app.Tier1DescriptionEditField.Limits = [0 Inf];
            app.Tier1DescriptionEditField.ValueChangedFcn = createCallbackFcn(app, @Tier1DescriptionEditFieldValueChanged, true);
            app.Tier1DescriptionEditField.Value = 750;
            app.kWhmonthLabel = uilabel(app.TieredRatesTab);
            app.kWhmonthLabel.Text = 'kWh / month';
            app.Tier2DescriptionEditField = uieditfield(app.TieredRatesTab, 'numeric');
            app.Tier2DescriptionEditField.Enable = 'off';
            app.Tier2DescriptionEditField.Value = 1000;
            app.kWhmonthLabel_2 = uilabel(app.TieredRatesTab);
            app.kWhmonthLabel_2.Text = 'kWh / month';
            app.Tier2Label = uilabel(app.TieredRatesTab);
            app.Tier2Label.Text = 'Tier 2';
            app.perkWhEditFieldLabel = uilabel(app.TieredRatesTab);
            app.perkWhEditFieldLabel.Text = '₵ per kWh';
            app.Tier1PriceEditField = uieditfield(app.TieredRatesTab, 'numeric');
            app.Tier1PriceEditField.Limits = [0 Inf];
            app.Tier1PriceEditField.Value = 8.5;
            app.perkWhEditField_2Label = uilabel(app.TieredRatesTab);
            app.perkWhEditField_2Label.Text = '₵ per kWh';
            app.Tier2PriceEditField = uieditfield(app.TieredRatesTab, 'numeric');
            app.Tier2PriceEditField.Limits = [0 Inf];
            app.Tier2PriceEditField.Value = 8.5;
            app.OKButton_2 = uibutton(app.TieredRatesTab, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Text = 'OK';
            app.MorethanLabel = uilabel(app.TieredRatesTab);
            app.MorethanLabel.Text = 'More than:';
            app.DeliveryChargesTab = uitab(app.TabGroup);
            app.DeliveryChargesTab.Title = 'Delivery Charges';
            app.DeliveryChargesBusinessLabel = uilabel(app.DeliveryChargesTab);
            app.DeliveryChargesBusinessLabel.Text = 'Delivery Charges - Business';
            app.PriceEditFieldLabel = uilabel(app.DeliveryChargesTab);
            app.PriceEditFieldLabel.Text = 'Price:';
            app.DeliveryPriceEditField1 = uieditfield(app.DeliveryChargesTab, 'numeric');
            app.DeliveryPriceEditField1.Limits = [0 Inf];
            app.perpeakkVALabel = uilabel(app.DeliveryChargesTab);
            app.perpeakkVALabel.Text = '₵ per peak kVA';
            app.OKButton_3 = uibutton(app.DeliveryChargesTab, 'push');
            app.OKButton_3.ButtonPushedFcn = createCallbackFcn(app, @OKButton_3Pushed, true);
            app.OKButton_3.Text = 'OK';
            app.MonthlyDemandofLessthan50kWLabel = uilabel(app.DeliveryChargesTab);
            app.MonthlyDemandofLessthan50kWLabel.Text = 'Monthly Demand of Less than 50 kW';
            app.MonthlyDemandof50kWto999kWLabel = uilabel(app.DeliveryChargesTab);
            app.MonthlyDemandof50kWto999kWLabel.Text = 'Monthly Demand of 50 kW to 999 kW';
            app.PriceEditFieldLabel_2 = uilabel(app.DeliveryChargesTab);
            app.PriceEditFieldLabel_2.Text = 'Price:';
            app.DeliveryPriceEditField2 = uieditfield(app.DeliveryChargesTab, 'numeric');
            app.DeliveryPriceEditField2.Limits = [0 Inf];
            app.perpeakkVALabel_2 = uilabel(app.DeliveryChargesTab);
            app.perpeakkVALabel_2.Text = '₵ per peak kVA';
            app.MonthlyDemandof1000kWto4999kWLabel = uilabel(app.DeliveryChargesTab);
            app.MonthlyDemandof1000kWto4999kWLabel.Text = 'Monthly Demand of 1000 kW to 4,999 kW';
            app.PriceEditFieldLabel_3 = uilabel(app.DeliveryChargesTab);
            app.PriceEditFieldLabel_3.Text = 'Price:';
            app.DeliveryPriceEditField3 = uieditfield(app.DeliveryChargesTab, 'numeric');
            app.DeliveryPriceEditField3.Limits = [0 Inf];
            app.perpeakkVALabel_3 = uilabel(app.DeliveryChargesTab);
            app.perpeakkVALabel_3.Text = '₵ per peak kVA';
            app.MonthlyDemandof5000kWandaboveLabel = uilabel(app.DeliveryChargesTab);
            app.MonthlyDemandof5000kWandaboveLabel.Text = 'Monthly Demand of 5,000 kW and above';
            app.PriceEditFieldLabel_4 = uilabel(app.DeliveryChargesTab);
            app.PriceEditFieldLabel_4.Text = 'Price:';
            app.DeliveryPriceEditField4 = uieditfield(app.DeliveryChargesTab, 'numeric');
            app.DeliveryPriceEditField4.Limits = [0 Inf];
            app.perpeakkVALabel_4 = uilabel(app.DeliveryChargesTab);
            app.perpeakkVALabel_4.Text = '₵ per peak kVA';
            % Show the figure after all components are created
            app.ElectricityRateSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Peak_Shaving_Settings_Dialog_BUSINESS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElectricityRateSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElectricityRateSettingsUIFigure)
        end
    end
end