classdef Peak_ShavingFinal2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PeakShavingAppUIFigure         matlab.ui.Figure
        RateSettingsMenu               matlab.ui.container.Menu
        ResidentialMenu                matlab.ui.container.Menu
        BusinessMenu                   matlab.ui.container.Menu
        UITable                        matlab.ui.control.Table
        RunSimulationButton            matlab.ui.control.Button
        HourLoadProfileLabel           matlab.ui.control.Label
        BatteryCapacitykWhLabel        matlab.ui.control.Label
        BatteryCapacitykWhEditField    matlab.ui.control.NumericEditField
        RatedPowerkWLabel              matlab.ui.control.Label
        RatedPowerkWEditField          matlab.ui.control.NumericEditField
        ConversionEfficiencyLabel      matlab.ui.control.Label
        ConversionEfficiencyEditField  matlab.ui.control.NumericEditField
        AuxilleryPowerRequirementkWLabel  matlab.ui.control.Label
        AuxilleryPowerRequirementkWEditField  matlab.ui.control.NumericEditField
        BatterySpecificationsLabel     matlab.ui.control.Label
        ChargeControllerSettingsLabel  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditFieldLabel  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditField_ON  matlab.ui.control.NumericEditField
        kWLabel                        matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditFieldLabel  matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_ON  matlab.ui.control.NumericEditField
        kWLabel_2                      matlab.ui.control.Label
        BESSPEAKSHAVINGAPPLabel        matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ManualButton_2                 matlab.ui.control.RadioButton
        AutoButton                     matlab.ui.control.RadioButton
        EnterPowerLimitofMURBFacilityLabel  matlab.ui.control.Label
        EnterPowerLimitofMURBFacilityEditField  matlab.ui.control.NumericEditField
        KVALabel                       matlab.ui.control.Label
        OnPeakTieredLabel              matlab.ui.control.Label
        MidPeakLabel                   matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_2Label  matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_MID  matlab.ui.control.NumericEditField
        kWLabel_4                      matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditFieldLabel_2  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditField_MID  matlab.ui.control.NumericEditField
        kWLabel_5                      matlab.ui.control.Label
        OffPeakLabel                   matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_2Label_2  matlab.ui.control.Label
        ChargeBatteryWhenLoadisBelowEditField_OFF  matlab.ui.control.NumericEditField
        kWLabel_6                      matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditFieldLabel_3  matlab.ui.control.Label
        DischargeBatteryWhenLoadisAboveEditField_OFF  matlab.ui.control.NumericEditField
        kWLabel_7                      matlab.ui.control.Label
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        WeekdayButton                  matlab.ui.control.ToggleButton
        WeekendButton                  matlab.ui.control.ToggleButton
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        ResidentialButton              matlab.ui.control.ToggleButton
        BusinessButton                 matlab.ui.control.ToggleButton
        PowerFactorLabel               matlab.ui.control.Label
        PowerFactorEditField           matlab.ui.control.NumericEditField
        CustomerTypeLabel              matlab.ui.control.Label
        ButtonGroup_4                  matlab.ui.container.ButtonGroup
        TieredButton                   matlab.ui.control.ToggleButton
        TimeofUseButton                matlab.ui.control.ToggleButton
        TabGroup                       matlab.ui.container.TabGroup
        LoadProfileTab                 matlab.ui.container.Tab
        UnshavedCostEditFieldLabel     matlab.ui.control.Label
        UnshavedCostEditField          matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        UnshavedLoadFactorEditFieldLabel  matlab.ui.control.Label
        UnshavedLoadFactorEditField    matlab.ui.control.NumericEditField
        ShavedCostLabel                matlab.ui.control.Label
        ShavedCostEditField            matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        ShavedLoadFactorLabel          matlab.ui.control.Label
        ShavedLoadFactorEditField      matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        AvailableEVCapacityTab         matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        EVPowerConsumptionEditFieldLabel  matlab.ui.control.Label
        EVPowerConsumptionEditField    matlab.ui.control.NumericEditField
        KVALabel_2                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        
      simInp;   % Simulation Input Object
        
        
      RESSettingsDialogApp; %  Electricity Rate Settings Dialog App 
      BUSSettingsDialogApp; %  Electricity Rate Settings Dialog App
      
      loadProfileDay; % Indicates Weekday or Weekend      
      customerType; % Indicates customer type (Business or Residential)      
      customerRateType; %Indicates rate type (Tiered or Time of Use)      
     
      %%%%%%% Residential Settings Parameters %%%%%%%%%%%
        
      RESOnPeakHours; % On-Peak Time of Use Hours (used in settings box)
      RESmidPeakHours; % Mid-Peak Time of Use Hours (used in settings box)
      RESoffPeakHours; % Off-Peak Time of Use Hours (used in settings box)
      
      RESonPeakPrice; % On-Peak Electricity Rate (used in settings box)
      RESmidPeakPrice; % Mid-Peak Electricity Rate (used in settings box)
      RESoffPeakPrice; % Off-Peak Electricity Rate (used in settings box)
      
      REStierRateKW; % Tiered Rate Boundary (used in settings box)
      REStier1Price; % Tier 1 Pricing (used in settings box)
      REStier2Price; % Tier 2 Pricing (used in settings box)
      
      RESdeliveryPrice; % Delivery Charge Pricing (used in settings box)
      
      %%%%%%%%%% Business Settins Parameters %%%%%%%%%%%%
      
      BUSOnPeakHours; % On-Peak Time of Use Hours (used in settings box)
      BUSmidPeakHours; % Mid-Peak Time of Use Hours (used in settings box)
      BUSoffPeakHours; % Off-Peak Time of Use Hours (used in settings box)
      
      BUSonPeakPrice; % On-Peak Electricity Rate (used in settings box)
      BUSmidPeakPrice; % Mid-Peak Electricity Rate (used in settings box)
      BUSoffPeakPrice; % Off-Peak Electricity Rate (used in settings box)
      
      BUStierRateKW; % Tiered Rate Boundary (used in settings box)
      BUStier1Price; % Tier 1 Pricing (used in settings box)
      BUStier2Price; % Tier 2 Pricing (used in settings box)
      
      BUSdeliveryPrice1; % Lowest Delivery Charge Pricing (used in settings box)
      BUSdeliveryPrice2; % Delivery Charge Pricing (used in settings box)
      BUSdeliveryPrice3; % Delivery Charge Pricing (used in settings box)
      BUSdeliveryPrice4; % Highest Delivery Charge Pricing (used in settings box)
      
      %%%%%%%%%% Temp Function Return Values %%%%%%%%%%%%%%%%%
      
      tempDecimalTime; % Temp return value from 'convertTimetoDecimal' Function
      
    end
    
    methods (Access = private)
        
        function computeOverloadSections(app,time,shavedLoad,MURBLimit)
            
            startTimes = zeros(1,length(shavedLoad));
            endTimes = zeros(1,length(shavedLoad));
            
            % Find Overloaded sections
            overLoad = (shavedLoad>MURBLimit);            
            overLoadDiff = diff(overLoad);
            
            startIndex = 1;
            endIndex = 1;
            
            for i = 1:length(overLoadDiff)
                
                if overLoadDiff(i) == 1
                    
                    startTimes(startIndex)=i+1;
                    startIndex = startIndex + 1;
                
                elseif overLoadDiff(i) == -1
                    
                    endTimes(endIndex)=i;
                    endIndex = endIndex + 1;
                    
                end
                
            end
                       
            
            start = nonzeros(startTimes)';
            app.simInp = app.simInp.setVariable('start',start);
            
            endT = nonzeros(endTimes)';
            app.simInp = app.simInp.setVariable('endT',endT);
            
                               
            % Add Overloaded Sections to Graph
            
            hold(app.UIAxes,'on');
            for k = 1:length(start)
            
            % Use interpolation to compute the exact crossing (START)
            
            % First Check if adjacent time values are equal (prevent error)
            if shavedLoad(start(k))==shavedLoad(start(k)-1)
                % Add in new point (same time value)
                time = [time(1:start(k)-1)' time(start(k)) time(start(k):length(time))']';
                shavedLoad = [shavedLoad(1:start(k)-1) MURBLimit shavedLoad(start(k):length(shavedLoad))];
            
            % Otherwise apply interpolation
            else
                % Obtain interpolated time value (intQX)
                intY = shavedLoad(start(k)-1:start(k));
                intX = time(start(k)-1:start(k));
                intYQ = MURBLimit;
                intXQ = interp1(intY,intX,intYQ);
                
                % Add interpolated interpolated value to existing vector
                time = [time(1:start(k)-1)' intXQ time(start(k):length(time))']';
                shavedLoad = [shavedLoad(1:start(k)-1) MURBLimit shavedLoad(start(k):length(shavedLoad))];
            end
            
            endT = endT + 1;
            
            % Use interpolation to compute the exact crossing (END)
            
            % First Check if adjacent time values are equal (prevent error)
            if shavedLoad(endT(k))==shavedLoad(endT(k)+1)
                % Add in new point (same time value)
                time = [time(1:endT(k))' time(endT(k)) time(endT(k)+1:length(time))']';
                shavedLoad = [shavedLoad(1:endT(k)) MURBLimit shavedLoad(endT(k)+1:length(shavedLoad))];
            
            % Otherwise apply interpolation
            else
                % Obtain interpolated time value (intQX)
                intY = shavedLoad(endT(k):endT(k)+1);
                intX = time(endT(k):endT(k)+1);
                intYQ = MURBLimit;
                intXQ = interp1(intY,intX,intYQ);
                
                % Add interpolated interpolated value to existing vector
                time = [time(1:endT(k))' intXQ time(endT(k)+1:length(time))']';
                shavedLoad = [shavedLoad(1:endT(k)) MURBLimit shavedLoad(endT(k)+1:length(shavedLoad))];
            end


            area(app.UIAxes,time(start(k):endT(k)+1),shavedLoad(start(k):endT(k)+1),MURBLimit,'FaceColor','r','ShowBaseLine','off','EdgeColor','r','FaceAlpha',0.5,'DisplayName','MURB Overloaded')  ;
            
            end
            hold(app.UIAxes,'off');
            
            
            if length(start) >= 1
                fig = uifigure;
                uialert(fig,'MURB Power Rating Has Been Exceeded. Consider Adjusting the Battery Settings','Overload Warning','CloseFcn',@(h,e) close(fig)); 
            end
            
            
        end  
    
        function convertTimetoDecimal(app,stringTime)
            timeVec=split(stringTime,":");
            HH=str2double(timeVec(1));
            ss=str2double(timeVec(2))/60;
            app.tempDecimalTime = HH+ss;
        end
        
        
        %%%%%% LOADS RATE DATA TO WORKSPACE %%%%%%%%
        function loadElectricityRateData(app)
             
            %%%%% Get Electricity Rate Data from Settings Window %%%%%
             
            %%%%%%%%%%%%%%%%%%%%% RESIDENTIAL %%%%%%%%%%%%%%%%%%%%%%%%
            if strcmp(app.customerType,'residential')                                          
                
                %%%%%%%%%%%%%%%%%%%%%% WEEKEND %%%%%%%%%%%%%%%%%%%%%%%%%%%
                if strcmp(app.loadProfileDay,'weekend')
                    app.simInp = app.simInp.setVariable('OnPeakHours1',0);
                    app.simInp = app.simInp.setVariable('OnPeakHours2',0);                    
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflow1',0);
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflow2',0);                    
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus1',0);
                    
                    app.simInp = app.simInp.setVariable('OnPeakHours3',0);
                    app.simInp = app.simInp.setVariable('OnPeakHours4',0);                    
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflow3',0);
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflow4',0);                    
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus2',0);
                    
                    app.simInp = app.simInp.setVariable('MidPeakHours1',0);
                    app.simInp = app.simInp.setVariable('MidPeakHours2',0);                    
                    app.simInp = app.simInp.setVariable('MidPeakHoursOverflow1',0);
                    app.simInp = app.simInp.setVariable('MidPeakHoursOverflow2',0);                    
                    app.simInp = app.simInp.setVariable('MidPeakHoursOverflowStatus',0);
                    
                    app.simInp = app.simInp.setVariable('OffPeakHours1',0);
                    app.simInp = app.simInp.setVariable('OffPeakHours2',24);                    
                    app.simInp = app.simInp.setVariable('OffPeakHoursOverflow1',0);
                    app.simInp = app.simInp.setVariable('OffPeakHoursOverflow2',0);                    
                    app.simInp = app.simInp.setVariable('OffPeakHoursOverflowStatus',0);
                
                %%%%%%%%%%%%%%%%%%%%%% WEEKDAY %%%%%%%%%%%%%%%%%%%%%%%%%%%
                else
                    % Correct for Overflow (onPeakHour1 > onPeakHour2)
                    if app.RESOnPeakHours(1) > app.RESOnPeakHours(2)
                        app.simInp = app.simInp.setVariable('OnPeakHours1',0);
                        app.simInp = app.simInp.setVariable('OnPeakHours2',app.RESOnPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow1',app.RESOnPeakHours(1));
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow2',24);
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus1',1);
                        
                    else
                        app.simInp = app.simInp.setVariable('OnPeakHours1',app.RESOnPeakHours(1));
                        app.simInp = app.simInp.setVariable('OnPeakHours2',app.RESOnPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow1',0);
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow2',0);
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus1',0);
                    end
                    
                    % Correct for Overflow (onPeakHour3 > onPeakHour4)
                    if app.RESOnPeakHours(3) > app.RESOnPeakHours(4)
                        app.simInp = app.simInp.setVariable('OnPeakHours3',0);
                        app.simInp = app.simInp.setVariable('OnPeakHours4',app.RESOnPeakHours(4));
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow3',app.RESOnPeakHours(3));
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow4',24);
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus2',1);
                        
                    else
                        app.simInp = app.simInp.setVariable('OnPeakHours3',app.RESOnPeakHours(3));
                        app.simInp = app.simInp.setVariable('OnPeakHours4',app.RESOnPeakHours(4));
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow3',0);
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow4',0);
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus2',0);
                    end
                    
                    % Correct for Overflow (midPeakHour1 > midPeakHour2)
                    if app.RESmidPeakHours(1) > app.RESmidPeakHours(2)
                        app.simInp = app.simInp.setVariable('MidPeakHours1',0);
                        app.simInp = app.simInp.setVariable('MidPeakHours2',app.RESmidPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflow1',app.RESmidPeakHours(1));
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflow2',24);
                        
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflowStatus',1);
                        
                    else
                        app.simInp = app.simInp.setVariable('MidPeakHours1',app.RESmidPeakHours(1));
                        app.simInp = app.simInp.setVariable('MidPeakHours2',app.RESmidPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflow1',0);
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflow2',0);
                        
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflowStatus',0);
                    end                                    
                                     
                    % Correct for Overflow (offPeakHour1 > offPeakHour2)
                    if app.RESoffPeakHours(1) > app.RESoffPeakHours(2)
                        app.simInp = app.simInp.setVariable('OffPeakHours1',0);
                        app.simInp = app.simInp.setVariable('OffPeakHours2',app.RESoffPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflow1',app.RESoffPeakHours(1));
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflow2',24);
                        
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflowStatus',1);
                        
                    else
                        app.simInp = app.simInp.setVariable('OffPeakHours1',app.RESoffPeakHours(1));
                        app.simInp = app.simInp.setVariable('OffPeakHours2',app.RESoffPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflow1',0);
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflow2',0);
                        
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflowStatus',0);
                    end                    
                    
                end      
                                
                app.simInp = app.simInp.setVariable('OnPeakPrice',app.RESonPeakPrice);
                app.simInp = app.simInp.setVariable('MidPeakPrice',app.RESmidPeakPrice);
                app.simInp = app.simInp.setVariable('OffPeakPrice',app.RESoffPeakPrice);            
                
                app.simInp = app.simInp.setVariable('tierRateKW',app.REStierRateKW);
                app.simInp = app.simInp.setVariable('tier1Price',app.REStier1Price); 
                app.simInp = app.simInp.setVariable('tier2Price',app.REStier2Price); 
                
                app.simInp = app.simInp.setVariable('deliveryPrice1',app.RESdeliveryPrice);
                app.simInp = app.simInp.setVariable('deliveryPrice2',app.RESdeliveryPrice);
                app.simInp = app.simInp.setVariable('deliveryPrice3',app.RESdeliveryPrice);
                app.simInp = app.simInp.setVariable('deliveryPrice4',app.RESdeliveryPrice);
                
            %%%%%%%%%%%%%%%%%%%%% BUSINESS %%%%%%%%%%%%%%%%%%%%%%%%
            else                
                
                %%%%%%%%%%%%%%%%%%%%%% WEEKEND %%%%%%%%%%%%%%%%%%%%%%%%%%%
                if strcmp(app.loadProfileDay,'weekend')
                    app.simInp = app.simInp.setVariable('OnPeakHours1',0);
                    app.simInp = app.simInp.setVariable('OnPeakHours2',0);                    
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflow1',0);
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflow2',0);                    
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus1',0);
                    
                    app.simInp = app.simInp.setVariable('OnPeakHours3',0);
                    app.simInp = app.simInp.setVariable('OnPeakHours4',0);                    
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflow3',0);
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflow4',0);                    
                    app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus2',0);
                    
                    app.simInp = app.simInp.setVariable('MidPeakHours1',0);
                    app.simInp = app.simInp.setVariable('MidPeakHours2',0);                    
                    app.simInp = app.simInp.setVariable('MidPeakHoursOverflow1',0);
                    app.simInp = app.simInp.setVariable('MidPeakHoursOverflow2',0);                    
                    app.simInp = app.simInp.setVariable('MidPeakHoursOverflowStatus',0);
                    
                    app.simInp = app.simInp.setVariable('OffPeakHours1',0);
                    app.simInp = app.simInp.setVariable('OffPeakHours2',24);                    
                    app.simInp = app.simInp.setVariable('OffPeakHoursOverflow1',0);
                    app.simInp = app.simInp.setVariable('OffPeakHoursOverflow2',0);                    
                    app.simInp = app.simInp.setVariable('OffPeakHoursOverflowStatus',0);
                
                %%%%%%%%%%%%%%%%%%%%%% WEEKDAY %%%%%%%%%%%%%%%%%%%%%%%%%%%
                else
                    %%%%%%%%%%%%%%%%%%%%%% BUSINESS %%%%%%%%%%%%%%%%%%%%%%%%%
                    % Correct for Overflow (onPeakHour1 > onPeakHour2)
                    if app.BUSOnPeakHours(1) > app.BUSOnPeakHours(2)
                        app.simInp = app.simInp.setVariable('OnPeakHours1',0);
                        app.simInp = app.simInp.setVariable('OnPeakHours2',app.BUSOnPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow1',app.BUSOnPeakHours(1));
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow2',24);
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus1',1);
                        
                    else
                        app.simInp = app.simInp.setVariable('OnPeakHours1',app.BUSOnPeakHours(1));
                        app.simInp = app.simInp.setVariable('OnPeakHours2',app.BUSOnPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow1',0);
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow2',0);
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus1',0);
                    end
                    
                    % Correct for Overflow (onPeakHour3 > onPeakHour4)
                    if app.BUSOnPeakHours(3) > app.BUSOnPeakHours(4)
                        app.simInp = app.simInp.setVariable('OnPeakHours3',0);
                        app.simInp = app.simInp.setVariable('OnPeakHours4',app.BUSOnPeakHours(4));
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow3',app.BUSOnPeakHours(3));
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow4',24);
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus2',1);
                        
                    else
                        app.simInp = app.simInp.setVariable('OnPeakHours3',app.BUSOnPeakHours(3));
                        app.simInp = app.simInp.setVariable('OnPeakHours4',app.BUSOnPeakHours(4));
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow3',0);
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflow4',0);
                        
                        app.simInp = app.simInp.setVariable('OnPeakHoursOverflowStatus2',0);
                    end
                    
                    % Correct for Overflow (midPeakHour1 > midPeakHour2)
                    if app.BUSmidPeakHours(1) > app.BUSmidPeakHours(2)
                        app.simInp = app.simInp.setVariable('MidPeakHours1',0);
                        app.simInp = app.simInp.setVariable('MidPeakHours2',app.BUSmidPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflow1',app.BUSmidPeakHours(1));
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflow2',24);
                        
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflowStatus',1);
                        
                    else
                        app.simInp = app.simInp.setVariable('MidPeakHours1',app.BUSmidPeakHours(1));
                        app.simInp = app.simInp.setVariable('MidPeakHours2',app.BUSmidPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflow1',0);
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflow2',0);
                        
                        app.simInp = app.simInp.setVariable('MidPeakHoursOverflowStatus',0);
                    end                                    
                                     
                    % Correct for Overflow (offPeakHour1 > offPeakHour2)
                    if app.BUSoffPeakHours(1) > app.BUSoffPeakHours(2)
                        app.simInp = app.simInp.setVariable('OffPeakHours1',0);
                        app.simInp = app.simInp.setVariable('OffPeakHours2',app.BUSoffPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflow1',app.BUSoffPeakHours(1));
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflow2',24);
                        
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflowStatus',1);
                        
                    else
                        app.simInp = app.simInp.setVariable('OffPeakHours1',app.BUSoffPeakHours(1));
                        app.simInp = app.simInp.setVariable('OffPeakHours2',app.BUSoffPeakHours(2));
                        
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflow1',0);
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflow2',0);
                        
                        app.simInp = app.simInp.setVariable('OffPeakHoursOverflowStatus',0);
                    end
                
                end                
                app.simInp = app.simInp.setVariable('OnPeakPrice',app.BUSonPeakPrice);
                app.simInp = app.simInp.setVariable('MidPeakPrice',app.BUSmidPeakPrice);
                app.simInp = app.simInp.setVariable('OffPeakPrice',app.BUSoffPeakPrice);            
                
                app.simInp = app.simInp.setVariable('tierRateKW',app.BUStierRateKW);
                app.simInp = app.simInp.setVariable('tier1Price',app.BUStier1Price);
                app.simInp = app.simInp.setVariable('tier2Price',app.BUStier2Price);
                
                app.simInp = app.simInp.setVariable('deliveryPrice1',app.BUSdeliveryPrice1);
                app.simInp = app.simInp.setVariable('deliveryPrice2',app.BUSdeliveryPrice2);
                app.simInp = app.simInp.setVariable('deliveryPrice3',app.BUSdeliveryPrice3);
                app.simInp = app.simInp.setVariable('deliveryPrice4',app.BUSdeliveryPrice4);
                            
            end
        end
      
    end
    
    methods (Access = public)
        
        function updateResidentialElectricityRateData(app,onPeakHours,midPeakHours,offPeakHours,onPeakPrice,midPeakPrice,offPeakPrice,tier1RateKW,tier1Price,tier2Price,deliveryPrice)
            app.RESOnPeakHours = onPeakHours;
            app.RESmidPeakHours = midPeakHours;
            app.RESoffPeakHours = offPeakHours;
            app.RESonPeakPrice = onPeakPrice;
            app.RESmidPeakPrice = midPeakPrice;
            app.RESoffPeakPrice = offPeakPrice;
            
            app.REStierRateKW = tier1RateKW; 
            app.REStier1Price = tier1Price; 
            app.REStier2Price = tier2Price; 
            
            app.RESdeliveryPrice = deliveryPrice;
            
        end
        
        function updateBusinessElectricityRateData(app,onPeakHours,midPeakHours,offPeakHours,onPeakPrice,midPeakPrice,offPeakPrice,tier1RateKW,tier1Price,tier2Price,deliveryPrice1,deliveryPrice2,deliveryPrice3,deliveryPrice4)
            app.BUSOnPeakHours = onPeakHours;
            app.BUSmidPeakHours = midPeakHours;
            app.BUSoffPeakHours = offPeakHours;
            app.BUSonPeakPrice = onPeakPrice;
            app.BUSmidPeakPrice = midPeakPrice;
            app.BUSoffPeakPrice = offPeakPrice;
            
            app.BUStierRateKW = tier1RateKW; 
            app.BUStier1Price = tier1Price; 
            app.BUStier2Price = tier2Price; 
            
            app.BUSdeliveryPrice1 = deliveryPrice1;
            app.BUSdeliveryPrice2 = deliveryPrice2;
            app.BUSdeliveryPrice3 = deliveryPrice3;
            app.BUSdeliveryPrice4 = deliveryPrice4;
        end         
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define Simulation Input Object
            app.simInp = Simulink.SimulationInput('Peak_Shave_Model');
            
            % Initialize Loading Table Data
            Load = [365 348 338 336 340 370 405 423 423 439 447 450 440 437 434 454 478 509 530 527 523 486 449 401]';
            TT = timetable(Load,'TimeStep',hours(1));
            T = timetable2table(TT);
            app.UITable.Data = T;
            
            % Initial Load Profile Options
            app.loadProfileDay = 'weekday';      
            app.customerType = 'residential';     
            app.customerRateType = 'tou';
            
            
            % Initialize Time of Use Electricity Rate Data (RESIDENTIAL)
            app.RESOnPeakHours = [7 11 17 19];
            app.RESmidPeakHours = [11 17]; 
            app.RESoffPeakHours = [19 7];           
            app.RESonPeakPrice = 17.6;
            app.RESmidPeakPrice = 11.9;
            app.RESoffPeakPrice = 8.5;
            
            app.REStierRateKW = 1000; 
            app.REStier1Price = 10.1; 
            app.REStier2Price = 11.8; 
            
            app.RESdeliveryPrice = 8.5;
            
            
            % Initialize Time of Use Electricity Rate Data (BUSINESS)
            app.BUSOnPeakHours = [7 11 17 19];
            app.BUSmidPeakHours = [11 17]; 
            app.BUSoffPeakHours = [19 7];           
            app.BUSonPeakPrice = 17.6;
            app.BUSmidPeakPrice = 11.9;
            app.BUSoffPeakPrice = 8.5;
            
            app.BUStierRateKW = 750; 
            app.BUStier1Price = 10.1; 
            app.BUStier2Price = 11.8; 
            
            app.BUSdeliveryPrice1 = 7.5; 
            app.BUSdeliveryPrice2 = 8.5;
            app.BUSdeliveryPrice3 = 9.5;
            app.BUSdeliveryPrice4 = 10.5;
                                                    
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            tableData = app.UITable.Data;
            
            % Get Time Data from Table
            x = datevec(tableData.Time);
            x = x(:,4);
            
            % Get Load Data from Table
            y = tableData.Load;
            
            % Convert Load data from kW to KVA
            y = y./app.PowerFactorEditField.Value;
            
            %%%% Get Facility Overload Limit from Form %%%%
            MURBOverload = app.EnterPowerLimitofMURBFacilityEditField.Value;
            app.simInp = app.simInp.setVariable('MURBOverload',MURBOverload);
            
            %%%%%%%% Get Average EV Size from Form %%%%%%%%
            EVCapacity = app.EVPowerConsumptionEditField.Value;
            app.simInp = app.simInp.setVariable('EVCapacity',EVCapacity);
            
            
            %%%%%%%%% Get BESS Data from Form %%%%%%%%%%%%%
            % Battery Capcity
            BESSCapacity = app.BatteryCapacitykWhEditField.Value;
            app.simInp = app.simInp.setVariable('BESSCapacity',BESSCapacity);
            
            % Initial State of Charge
            %initialSOC = app.InitialStateofChargeEditField.Value;
            %app.simInp = app.simInp.setVariable('initialSOC',initialSOC);
            
            % Rated Power
            ratedPower = app.RatedPowerkWEditField.Value;
            app.simInp = app.simInp.setVariable('ratedPower',ratedPower);
            
            ratedPowerNEG = -1*ratedPower;
            app.simInp = app.simInp.setVariable('ratedPowerNEG',ratedPowerNEG);
            
            % Conversion Efficiency
            efficiency = app.ConversionEfficiencyEditField.Value;
            app.simInp = app.simInp.setVariable('efficiency',efficiency);
            
            % Auxillery Power Requirement
            auxPower = app.AuxilleryPowerRequirementkWEditField.Value;
            app.simInp = app.simInp.setVariable('auxPower',auxPower);
            
            
             %%%%%%%% Load All Electricity Rate Data To Workspace %%%%%%%%
            loadElectricityRateData(app);
           
            
            %%%%%%%%% Get Charge Controller Data from Form %%%%%%%%
            
            %%%%%%% IF TIME OF USE RATE SELECTED %%%%%%%%
            if strcmp(app.customerRateType,'tou')
            % Discharge Settings
                % On Peak
                onPeakDischargeSetting = app.DischargeBatteryWhenLoadisAboveEditField_ON.Value;
                app.simInp = app.simInp.setVariable('onPeakDischargeSetting',onPeakDischargeSetting);
                % Mid Peak
                midPeakDischargeSetting = app.DischargeBatteryWhenLoadisAboveEditField_MID.Value;
                app.simInp = app.simInp.setVariable('midPeakDischargeSetting',midPeakDischargeSetting);
                % Off Peak
                offPeakDischargeSetting = app.DischargeBatteryWhenLoadisAboveEditField_OFF.Value;
                app.simInp = app.simInp.setVariable('offPeakDischargeSetting',offPeakDischargeSetting);            
            
            % Charge Setting
                % On Peak
                onPeakChargeSetting = app.ChargeBatteryWhenLoadisBelowEditField_ON.Value;
                app.simInp = app.simInp.setVariable('onPeakChargeSetting',onPeakChargeSetting);
                % Mid Peak
                midPeakChargeSetting = app.ChargeBatteryWhenLoadisBelowEditField_MID.Value;
                app.simInp = app.simInp.setVariable('midPeakChargeSetting',midPeakChargeSetting);
                % Off Peak
                offPeakChargeSetting = app.ChargeBatteryWhenLoadisBelowEditField_OFF.Value;
                app.simInp = app.simInp.setVariable('offPeakChargeSetting',offPeakChargeSetting);
            
            %%%%%%%%% IF TIERED RATE SELECTED %%%%%%%%%%
            else
            % Discharge Settings
                onPeakDischargeSetting = app.DischargeBatteryWhenLoadisAboveEditField_ON.Value;
                app.simInp = app.simInp.setVariable('onPeakDischargeSetting',onPeakDischargeSetting);
                app.simInp = app.simInp.setVariable('midPeakDischargeSetting',onPeakDischargeSetting);
                app.simInp = app.simInp.setVariable('offPeakDischargeSetting',onPeakDischargeSetting);
            % Charge Setting
                onPeakChargeSetting = app.ChargeBatteryWhenLoadisBelowEditField_ON.Value;
                app.simInp = app.simInp.setVariable('onPeakChargeSetting',onPeakChargeSetting);            
                app.simInp = app.simInp.setVariable('midPeakChargeSetting',onPeakChargeSetting);
                app.simInp = app.simInp.setVariable('offPeakChargeSetting',onPeakChargeSetting);
            end
                        
            
            % Reformat Data for Simulation
            ts=timeseries(y,x);            
            app.simInp = app.simInp.setExternalInput(ts);            
            
            % Get Saturation Limit
            %satLim = str2num(app.SaturationLimitEditField.Value);
            %app.simInp = app.simInp.setVariable('satLim',satLim);
            
            % Set Simulation Time
            simTime = length(y)-1;
            app.simInp = app.simInp.setModelParameter('StopTime', num2str(simTime));
            
            
            % configure simInp for deployment
	        app.simInp = simulink.compiler.configureForDeployment(app.simInp);
            
            %Run Simulation
            out = sim(app.simInp);       
                        
            %%%%%%%%%%%%%% Define Simulated Data %%%%%%%%%%%%%%%%%%%            
            
            % Load Profile Data
            time = out.tout;            
            initialLoad = out.yout.get('inLoad').Values.Data;            
            shavedLoad = reshape(out.yout.get('simout').Values.Data,1,[]);
            
            % SOC Data
            SOC = out.yout.get('StateOfCharge').Values.Data;
            
            % EV Capacity 
            evCount = reshape(out.yout.get('EVCount').Values.Data,1,[]); 
            
            % Billing Data
            timeOfUseChargesShaved = out.yout.get('timeOfUseCharges').Values.Data;
            tieredRateChargesShaved = out.yout.get('tieredRateCharges').Values.Data;
            deliveryChargesShaved = out.yout.get('deliveryCharges').Values.Data; 
            
            timeOfUseChargesUnshaved = out.yout.get('timeOfUseChargesUnshaved').Values.Data;
            tieredRateChargesUnshaved = out.yout.get('tieredRateChargesUnshaved').Values.Data;
            deliveryChargesUnshaved = out.yout.get('deliveryChargesUnshaved').Values.Data; 
             
            % Load Factor
            loadFactorUnshaved = out.yout.get('LoadFactorUnshaved').Values.Data;
            loadFactorShaved = out.yout.get('LoadFactor').Values.Data;
            
            
            %%%%%%%%%%%%%%%%% Update Display %%%%%%%%%%%%%%%%%%%%%%
            
            % Update Load Profile Plot
            plot(app.UIAxes,time,initialLoad,time,shavedLoad);
            legend(app.UIAxes,'Initial Load (kW)','Shaved Load with BESS (kW)');
            app.computeOverloadSections(time,shavedLoad,MURBOverload);
            
            % Update SOC Plot
            plot(app.UIAxes_2,time,SOC);    
            
            % Update EV Count Plot
            plot(app.UIAxes_3,time,evCount);
            
            % Update Load Factor Value
            app.UnshavedLoadFactorEditField.Value =loadFactorUnshaved(length(loadFactorUnshaved));
            app.ShavedLoadFactorEditField.Value = loadFactorShaved(length(loadFactorUnshaved));
            
            % Update Cost Value
            if strcmp(app.customerRateType,'tou')
                app.UnshavedCostEditField.Value=timeOfUseChargesUnshaved(length(timeOfUseChargesUnshaved)) + deliveryChargesUnshaved(length(deliveryChargesUnshaved));
                app.ShavedCostEditField.Value=timeOfUseChargesShaved(length(timeOfUseChargesShaved)) + deliveryChargesShaved(length(timeOfUseChargesShaved));
            else
                app.UnshavedCostEditField.Value=tieredRateChargesUnshaved(length(tieredRateChargesUnshaved)) + deliveryChargesUnshaved(length(deliveryChargesUnshaved));
                app.ShavedCostEditField.Value=tieredRateChargesShaved(length(tieredRateChargesShaved)) + deliveryChargesShaved(length(deliveryChargesShaved));                
            end
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if selectedButton == app.AutoButton
               
               % Disable Charge Controller Settings on Form
               app.ChargeBatteryWhenLoadisBelowEditField_ON.Editable = false;
               app.ChargeBatteryWhenLoadisBelowEditField_MID.Editable = false;
               app.ChargeBatteryWhenLoadisBelowEditField_OFF.Editable = false;
               
               app.DischargeBatteryWhenLoadisAboveEditField_ON.Editable = false;
               app.DischargeBatteryWhenLoadisAboveEditField_MID.Editable = false;
               app.DischargeBatteryWhenLoadisAboveEditField_OFF.Editable = false;
                
                % Get Time Data from Table            
                tableData = app.UITable.Data;
                x = datevec(tableData.Time);
                x = x(:,4);
                
                % Get Load Data from Table
                y = tableData.Load;
                
                % Convert Load data from kW to KVA
                y = y./app.PowerFactorEditField.Value;              
                
                % Reformat Data for Simulation
                ts=timeseries(y,x);    
                app.simInp = app.simInp.setVariable('ts',ts);            
                
                % Set Simulation Time
                simTime = length(y)-1;            
                
                 %%%% Get Facility Overload Limit from Form %%%%
                MURBOverload = app.EnterPowerLimitofMURBFacilityEditField.Value;
                app.simInp = app.simInp.setVariable('MURBOverload',MURBOverload);
                
                %%%%%%%% Get Average EV Size from Form %%%%%%%%
                EVCapacity = app.EVPowerConsumptionEditField.Value;
                app.simInp = app.simInp.setVariable('EVCapacity',EVCapacity);
                
                
                %%%%%%%%% Get BESS Data from Form %%%%%%%%%%%%%
                % Battery Capcity
                BESSCapacity = app.BatteryCapacitykWhEditField.Value;
                app.simInp = app.simInp.setVariable('BESSCapacity',BESSCapacity);            
                           
                % Rated Power
                ratedPower = app.RatedPowerkWEditField.Value;
                app.simInp = app.simInp.setVariable('ratedPower',ratedPower);
                
                ratedPowerNEG = -1*ratedPower;
                app.simInp = app.simInp.setVariable('ratedPowerNEG',ratedPowerNEG);
                
                % Conversion Efficiency
                efficiency = app.ConversionEfficiencyEditField.Value;
                app.simInp = app.simInp.setVariable('efficiency',efficiency);
                
                % Auxillery Power Requirement
                auxPower = app.AuxilleryPowerRequirementkWEditField.Value;
                app.simInp = app.simInp.setVariable('auxPower',auxPower);            
                
                 %%%%%%%% Load All Electricity Rate Data To Workspace %%%%%%%%
                loadElectricityRateData(app);    


                % Show loading bar while simulation runs
                fig = uifigure;
                d = uiprogressdlg(fig,'Title','Please Wait ...',...
                    'Message','Determining Optimal Charge Controller Settings');   


                % Define Optimized Settings
                optimizedOnPeakSetting = 0;
                optimizedMidPeakSetting = 0;
                optimizedOffPeakSetting = 0;
                
                % Set optimized price to highest value possible (initial)
                optimizedPrice = max([app.RESonPeakPrice,app.RESmidPeakPrice,app.RESoffPeakPrice,app.BUSonPeakPrice,app.BUSmidPeakPrice,app.BUSoffPeakPrice])*max(y)*24 + max([app.RESdeliveryPrice,app.BUSdeliveryPrice1,app.BUSdeliveryPrice2,app.BUSdeliveryPrice3,app.BUSdeliveryPrice4])*max(y); 


                %%%%%%%%% LOOP THROUGH ALL POSSIBLE CHARGE CONTROLLER COMBINATIONS %%%%%%%%
                
                stepSize = 50;                
                steps = (((round(max(y),-2) - round(min(y),-2))/stepSize)+1)^3+1;
                
                %%%%%%% IF TIME OF USE RATE SELECTED %%%%%%%%
                if strcmp(app.customerRateType,'tou')            
                    for onPeak = round(min(y),-2):stepSize:round(max(y),-2)
                        for midPeak = round(min(y),-2):stepSize:round(max(y),-2)
                            for offPeak = round(min(y),-2):stepSize:round(max(y),-2)                        
                                % Discharge Settings
                                    % On Peak                                
                                    app.simInp = app.simInp.setVariable('onPeakDischargeSetting',onPeak);
                                    % Mid Peak                                
                                    app.simInp = app.simInp.setVariable('midPeakDischargeSetting',midPeak);
                                    % Off Peak                                
                                    app.simInp = app.simInp.setVariable('offPeakDischargeSetting',offPeak);            
                                
                                % Charge Setting
                                    % On Peak                                
                                    app.simInp = app.simInp.setVariable('onPeakChargeSetting',onPeak);
                                    % Mid Peak                                
                                    app.simInp = app.simInp.setVariable('midPeakChargeSetting',midPeak);
                                    % Off Peak                                
                                    app.simInp = app.simInp.setVariable('offPeakChargeSetting',offPeak);                               
                                                           
                                % Run Simulation
                                out = sim('Peak_Shave_Model','StartTime','0','StopTime',num2str(simTime));
                                
                                                                
                                % Get Simulated Cost
                                price = out.timeOfUseCharges.Data + out.deliveryCharges.Data;
                                
                                % Update optimization values (if applicable)
                                if price < optimizedPrice
                                    optimizedPrice = price;
                                    optimizedOnPeakSetting = onPeak;
                                    optimizedMidPeakSetting = midPeak;
                                    optimizedOffPeakSetting = offPeak;
                                end
                                
                                % Update Progress Bar
                                
                                d.Value = d.Value + 1/steps;
                                
                            end                     
                        end                
                    end    
                    
                d.Value = 1;
                pause(1);
                %%%%%%%%% IF TIERED RATE SELECTED %%%%%%%%%%
                else
                    for onPeak = round(min(y),-2):stepSize:round(max(y),-2)                   
                        % Discharge Settings                        
                            app.simInp = app.simInp.setVariable('onPeakDischargeSetting',onPeak);
                            app.simInp = app.simInp.setVariable('midPeakDischargeSetting',onPeak);
                            app.simInp = app.simInp.setVariable('offPeakDischargeSetting',onPeak);
                        % Charge Setting                        
                            app.simInp = app.simInp.setVariable('onPeakChargeSetting',onPeak);            
                            app.simInp = app.simInp.setVariable('midPeakChargeSetting',onPeak);
                            app.simInp = app.simInp.setVariable('offPeakChargeSetting',onPeak);
                            
                        % Run Simulation
                        out = sim('Peak_Shave_Model','StartTime','0','StopTime',num2str(simTime));
                        
                        % Get Simulated Cost
                        price = out.timeOfUseCharges.Data + out.deliveryCharges.Data;
                                
                        % Update optimization values (if applicable)
                        if price < optimizedPrice
                            optimizedPrice = price;  
                            optimizedOnPeakSetting = onPeak;
                        end                      
                    
                    end
                    
                end                                        
                                 
                % Close dialog box
                close(d);
                close(fig);               
               
                
               % Update The Charge Settings on Form
               
               if strcmp(app.customerRateType,'tou')                     
                   app.ChargeBatteryWhenLoadisBelowEditField_ON.Value = optimizedOnPeakSetting;
                   app.ChargeBatteryWhenLoadisBelowEditField_MID.Value = optimizedMidPeakSetting;
                   app.ChargeBatteryWhenLoadisBelowEditField_OFF.Value = optimizedOffPeakSetting;
                   
                   app.DischargeBatteryWhenLoadisAboveEditField_ON.Value = optimizedOnPeakSetting;
                   app.DischargeBatteryWhenLoadisAboveEditField_MID.Value = optimizedMidPeakSetting;
                   app.DischargeBatteryWhenLoadisAboveEditField_OFF.Value = optimizedOffPeakSetting;
               else
                   app.ChargeBatteryWhenLoadisBelowEditField_ON.Value = optimizedOnPeakSetting;
                   app.ChargeBatteryWhenLoadisBelowEditField_MID.Value = optimizedOnPeakSetting;
                   app.ChargeBatteryWhenLoadisBelowEditField_OFF.Enable = optimizedOnPeakSetting;
                   
                   app.DischargeBatteryWhenLoadisAboveEditField_ON.Value = optimizedOnPeakSetting;
                   app.DischargeBatteryWhenLoadisAboveEditField_MID.Value = optimizedOnPeakSetting;
                   app.DischargeBatteryWhenLoadisAboveEditField_OFF.Value = optimizedOnPeakSetting;
               end
            
            else
               if strcmp(app.customerRateType,'tou')
                   app.ChargeBatteryWhenLoadisBelowEditField_ON.Editable = true;
                   app.ChargeBatteryWhenLoadisBelowEditField_MID.Editable = true;
                   app.ChargeBatteryWhenLoadisBelowEditField_OFF.Editable = true;
                   
                   app.DischargeBatteryWhenLoadisAboveEditField_ON.Editable = true;
                   app.DischargeBatteryWhenLoadisAboveEditField_MID.Editable = true;
                   app.DischargeBatteryWhenLoadisAboveEditField_OFF.Editable = true;
               else
                   app.ChargeBatteryWhenLoadisBelowEditField_ON.Editable = true;
                   app.ChargeBatteryWhenLoadisBelowEditField_MID.Editable = false;
                   app.ChargeBatteryWhenLoadisBelowEditField_MID.Value = 0;
                   app.ChargeBatteryWhenLoadisBelowEditField_OFF.Editable = false;
                   app.ChargeBatteryWhenLoadisBelowEditField_OFF.Value = 0;
                   
                   app.DischargeBatteryWhenLoadisAboveEditField_ON.Editable = true;
                   app.DischargeBatteryWhenLoadisAboveEditField_MID.Editable = false;
                   app.DischargeBatteryWhenLoadisAboveEditField_MID.Value = 0;
                   app.DischargeBatteryWhenLoadisAboveEditField_OFF.Editable = false;
                   app.DischargeBatteryWhenLoadisAboveEditField_OFF.Value = 0;
               end
           end
            
        end

        % Menu selected function: ResidentialMenu
        function ResidentialMenuSelected(app, event)
            app.RESSettingsDialogApp = Peak_Shaving_Settings_Dialog_RESIDENTIAL(app,app.RESOnPeakHours, app.RESmidPeakHours, app.RESoffPeakHours, app.RESonPeakPrice, app.RESmidPeakPrice, app.RESoffPeakPrice, app.REStierRateKW, app.REStier1Price, app.REStier2Price,app.RESdeliveryPrice);            
        end

        % Close request function: PeakShavingAppUIFigure
        function PeakShavingAppUIFigureCloseRequest(app, event)
            delete(app.RESSettingsDialogApp) 
            delete(app.BUSSettingsDialogApp)
            delete(app)            
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            if app.ResidentialButton.Value                
                app.customerType = 'residential';               
            else
                app.customerType = 'business';
            end
            
            
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)
            if app.TieredButton.Value
                app.ManualButton_2.Enable = false;
                app.AutoButton.Enable = false;
                app.ButtonGroup.SelectedObject = app.ManualButton_2;
                app.ChargeBatteryWhenLoadisBelowEditField_ON.Editable = true;
                app.DischargeBatteryWhenLoadisAboveEditField_ON.Editable = true;
            else
                app.ManualButton_2.Enable = true;
                app.AutoButton.Enable = true;
            end
            
            if app.ButtonGroup.SelectedObject == app.ManualButton_2
            
                if app.TimeofUseButton.Value
                    app.customerRateType = 'tou';
                    
                    if app.ButtonGroup.SelectedObject == app.ManualButton_2  
                    
                        app.ChargeBatteryWhenLoadisBelowEditField_MID.Editable = true;
                        app.DischargeBatteryWhenLoadisAboveEditField_MID.Editable = true;
                        
                        app.ChargeBatteryWhenLoadisBelowEditField_OFF.Editable = true;
                        app.DischargeBatteryWhenLoadisAboveEditField_OFF.Editable = true;
                    
                    end
                    
                else
                    app.customerRateType = 'tiered';                    
                    
                    
                    app.ChargeBatteryWhenLoadisBelowEditField_MID.Editable = false;
                    app.ChargeBatteryWhenLoadisBelowEditField_MID.Value = 0;
                    app.DischargeBatteryWhenLoadisAboveEditField_MID.Editable = false;
                    app.DischargeBatteryWhenLoadisAboveEditField_MID.Value = 0;
                    
                    app.ChargeBatteryWhenLoadisBelowEditField_OFF.Editable = false;
                    app.ChargeBatteryWhenLoadisBelowEditField_OFF.Value = 0;
                    app.DischargeBatteryWhenLoadisAboveEditField_OFF.Editable = false;
                    app.DischargeBatteryWhenLoadisAboveEditField_OFF.Value = 0;
                    
                end
            
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            if app.WeekdayButton.Value
                app.loadProfileDay = 'weekday';
            else 
                app.loadProfileDay = 'weekend';
            end
            
        end

        % Menu selected function: BusinessMenu
        function BusinessMenuSelected(app, event)
            app.RESSettingsDialogApp = Peak_Shaving_Settings_Dialog_BUSINESS(app,app.BUSOnPeakHours, app.BUSmidPeakHours, app.BUSoffPeakHours, app.BUSonPeakPrice, app.BUSmidPeakPrice, app.BUSoffPeakPrice, app.BUStierRateKW, app.BUStier1Price, app.BUStier2Price,app.BUSdeliveryPrice1,app.BUSdeliveryPrice2,app.BUSdeliveryPrice3,app.BUSdeliveryPrice4);   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PeakShavingAppUIFigure = uifigure('Visible', 'off');
            app.PeakShavingAppUIFigure.Name = 'Peak Shaving App';
            app.PeakShavingAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @PeakShavingAppUIFigureCloseRequest, true);
            app.RateSettingsMenu = uimenu(app.PeakShavingAppUIFigure);
            app.RateSettingsMenu.Text = 'Rate Settings';
            app.ResidentialMenu = uimenu(app.RateSettingsMenu);
            app.ResidentialMenu.MenuSelectedFcn = createCallbackFcn(app, @ResidentialMenuSelected, true);
            app.ResidentialMenu.Text = 'Residential';
            app.BusinessMenu = uimenu(app.RateSettingsMenu);
            app.BusinessMenu.MenuSelectedFcn = createCallbackFcn(app, @BusinessMenuSelected, true);
            app.BusinessMenu.Text = 'Business';
            app.UITable = uitable(app.PeakShavingAppUIFigure);
            app.UITable.ColumnName = {'Time'; 'Load (KW)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true];
            app.UITable.ColumnEditable = [false true];
            app.RunSimulationButton = uibutton(app.PeakShavingAppUIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.HourLoadProfileLabel = uilabel(app.PeakShavingAppUIFigure);
            app.HourLoadProfileLabel.Text = '24 Hour Load Profile';
            app.BatteryCapacitykWhLabel = uilabel(app.PeakShavingAppUIFigure);
            app.BatteryCapacitykWhLabel.Text = 'Battery Capacity (kWh):';
            app.BatteryCapacitykWhEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.BatteryCapacitykWhEditField.Limits = [0 Inf];
            app.RatedPowerkWLabel = uilabel(app.PeakShavingAppUIFigure);
            app.RatedPowerkWLabel.Text = 'Rated Power (kW):';
            app.RatedPowerkWEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.RatedPowerkWEditField.Limits = [0 Inf];
            app.ConversionEfficiencyLabel = uilabel(app.PeakShavingAppUIFigure);
            app.ConversionEfficiencyLabel.Text = 'Conversion Efficiency (%):';
            app.ConversionEfficiencyEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.ConversionEfficiencyEditField.Limits = [0 100];
            app.ConversionEfficiencyEditField.Value = 100;
            app.AuxilleryPowerRequirementkWLabel = uilabel(app.PeakShavingAppUIFigure);
            app.AuxilleryPowerRequirementkWLabel.Text = 'Auxillery Power Requirement (kW):';
            app.AuxilleryPowerRequirementkWEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.AuxilleryPowerRequirementkWEditField.Limits = [0 Inf];
            app.BatterySpecificationsLabel = uilabel(app.PeakShavingAppUIFigure);
            app.BatterySpecificationsLabel.Text = 'Battery Specifications';
            app.ChargeControllerSettingsLabel = uilabel(app.PeakShavingAppUIFigure);
            app.ChargeControllerSettingsLabel.Text = 'Charge Controller Settings';
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel = uilabel(app.PeakShavingAppUIFigure);
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel.Text = 'Discharge Battery When Load is Above: ';
            app.DischargeBatteryWhenLoadisAboveEditField_ON = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.DischargeBatteryWhenLoadisAboveEditField_ON.Limits = [0 Inf];
            app.kWLabel = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel.Text = '(kW)';
            app.ChargeBatteryWhenLoadisBelowEditFieldLabel = uilabel(app.PeakShavingAppUIFigure);
            app.ChargeBatteryWhenLoadisBelowEditFieldLabel.Text = 'Charge Battery When Load is Below: ';
            app.ChargeBatteryWhenLoadisBelowEditField_ON = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.ChargeBatteryWhenLoadisBelowEditField_ON.Limits = [0 Inf];
            app.kWLabel_2 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_2.Text = '(kW)';
            app.BESSPEAKSHAVINGAPPLabel = uilabel(app.PeakShavingAppUIFigure);
            app.BESSPEAKSHAVINGAPPLabel.Text = 'BESS PEAK SHAVING APP';
            app.ButtonGroup = uibuttongroup(app.PeakShavingAppUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ManualButton_2 = uiradiobutton(app.ButtonGroup);
            app.ManualButton_2.Text = 'Manual';
            app.ManualButton_2.Value = true;
            app.AutoButton = uiradiobutton(app.ButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.EnterPowerLimitofMURBFacilityLabel = uilabel(app.PeakShavingAppUIFigure);
            app.EnterPowerLimitofMURBFacilityLabel.Text = 'Enter Power Limit of MURB Facility:';
            app.EnterPowerLimitofMURBFacilityEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.EnterPowerLimitofMURBFacilityEditField.Limits = [0 Inf];
            app.KVALabel = uilabel(app.PeakShavingAppUIFigure);
            app.KVALabel.Text = '(KVA)';
            app.OnPeakTieredLabel = uilabel(app.PeakShavingAppUIFigure);
            app.OnPeakTieredLabel.Text = 'On-Peak / Tiered';
            app.MidPeakLabel = uilabel(app.PeakShavingAppUIFigure);
            app.MidPeakLabel.Text = 'Mid-Peak';
            app.ChargeBatteryWhenLoadisBelowEditField_2Label = uilabel(app.PeakShavingAppUIFigure);
            app.ChargeBatteryWhenLoadisBelowEditField_2Label.Text = 'Charge Battery When Load is Below:';
            app.ChargeBatteryWhenLoadisBelowEditField_MID = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.ChargeBatteryWhenLoadisBelowEditField_MID.Limits = [0 Inf];
            app.kWLabel_4 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_4.Text = '(kW)';
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel_2 = uilabel(app.PeakShavingAppUIFigure);
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel_2.Text = 'Discharge Battery When Load is Above: ';
            app.DischargeBatteryWhenLoadisAboveEditField_MID = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.DischargeBatteryWhenLoadisAboveEditField_MID.Limits = [0 Inf];
            app.kWLabel_5 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_5.Text = '(kW)';
            app.OffPeakLabel = uilabel(app.PeakShavingAppUIFigure);
            app.OffPeakLabel.Text = 'Off-Peak';
            app.ChargeBatteryWhenLoadisBelowEditField_2Label_2 = uilabel(app.PeakShavingAppUIFigure);
            app.ChargeBatteryWhenLoadisBelowEditField_2Label_2.Text = 'Charge Battery When Load is Below:';
            app.ChargeBatteryWhenLoadisBelowEditField_OFF = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.ChargeBatteryWhenLoadisBelowEditField_OFF.Limits = [0 Inf];
            app.kWLabel_6 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_6.Text = '(kW)';
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel_3 = uilabel(app.PeakShavingAppUIFigure);
            app.DischargeBatteryWhenLoadisAboveEditFieldLabel_3.Text = 'Discharge Battery When Load is Above: ';
            app.DischargeBatteryWhenLoadisAboveEditField_OFF = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.DischargeBatteryWhenLoadisAboveEditField_OFF.Limits = [0 Inf];
            app.kWLabel_7 = uilabel(app.PeakShavingAppUIFigure);
            app.kWLabel_7.Text = '(kW)';
            app.ButtonGroup_2 = uibuttongroup(app.PeakShavingAppUIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.WeekdayButton = uitogglebutton(app.ButtonGroup_2);
            app.WeekdayButton.Text = 'Weekday';
            app.WeekdayButton.Value = true;
            app.WeekendButton = uitogglebutton(app.ButtonGroup_2);
            app.WeekendButton.Text = 'Weekend';
            app.ButtonGroup_3 = uibuttongroup(app.PeakShavingAppUIFigure);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.ResidentialButton = uitogglebutton(app.ButtonGroup_3);
            app.ResidentialButton.Text = 'Residential';
            app.ResidentialButton.Value = true;
            app.BusinessButton = uitogglebutton(app.ButtonGroup_3);
            app.BusinessButton.Text = 'Business';
            app.PowerFactorLabel = uilabel(app.PeakShavingAppUIFigure);
            app.PowerFactorLabel.Text = 'Power Factor:';
            app.PowerFactorEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.PowerFactorEditField.Limits = [0 1];
            app.PowerFactorEditField.Value = 0.95;
            app.CustomerTypeLabel = uilabel(app.PeakShavingAppUIFigure);
            app.CustomerTypeLabel.Text = 'Customer Type:';
            app.ButtonGroup_4 = uibuttongroup(app.PeakShavingAppUIFigure);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.TieredButton = uitogglebutton(app.ButtonGroup_4);
            app.TieredButton.Text = 'Tiered';
            app.TimeofUseButton = uitogglebutton(app.ButtonGroup_4);
            app.TimeofUseButton.Text = 'Time of Use';
            app.TimeofUseButton.Value = true;
            app.TabGroup = uitabgroup(app.PeakShavingAppUIFigure);
            app.LoadProfileTab = uitab(app.TabGroup);
            app.LoadProfileTab.Title = 'Load Profile';
            app.UnshavedCostEditFieldLabel = uilabel(app.LoadProfileTab);
            app.UnshavedCostEditFieldLabel.Text = 'Unshaved Cost:';
            app.UnshavedCostEditField = uieditfield(app.LoadProfileTab, 'numeric');
            app.UnshavedCostEditField.ValueDisplayFormat = '%.2f';
            app.Label = uilabel(app.LoadProfileTab);
            app.Label.Text = '$';
            app.UnshavedLoadFactorEditFieldLabel = uilabel(app.LoadProfileTab);
            app.UnshavedLoadFactorEditFieldLabel.Text = 'Unshaved Load Factor:';
            app.UnshavedLoadFactorEditField = uieditfield(app.LoadProfileTab, 'numeric');
            app.UnshavedLoadFactorEditField.ValueDisplayFormat = '%.2f';
            app.ShavedCostLabel = uilabel(app.LoadProfileTab);
            app.ShavedCostLabel.Text = 'Shaved Cost:';
            app.ShavedCostEditField = uieditfield(app.LoadProfileTab, 'numeric');
            app.ShavedCostEditField.ValueDisplayFormat = '%.2f';
            app.Label_2 = uilabel(app.LoadProfileTab);
            app.Label_2.Text = '$';
            app.ShavedLoadFactorLabel = uilabel(app.LoadProfileTab);
            app.ShavedLoadFactorLabel.Text = 'Shaved Load Factor:';
            app.ShavedLoadFactorEditField = uieditfield(app.LoadProfileTab, 'numeric');
            app.ShavedLoadFactorEditField.ValueDisplayFormat = '%.2f';
            app.UIAxes = uiaxes(app.LoadProfileTab);
            title(app.UIAxes, 'Peak Shaving Demand')
            xlabel(app.UIAxes, 'Time (h)')
            ylabel(app.UIAxes, 'Load (kVA)')
            app.UIAxes.XLim = [0 24];
            app.UIAxes_2 = uiaxes(app.LoadProfileTab);
            title(app.UIAxes_2, 'State of Charge')
            xlabel(app.UIAxes_2, 'Time (h)')
            ylabel(app.UIAxes_2, 'State of Charge (%)')
            app.UIAxes_2.XLim = [0 24];
            app.UIAxes_2.YLim = [0 100];
            app.AvailableEVCapacityTab = uitab(app.TabGroup);
            app.AvailableEVCapacityTab.Title = 'Available EV Capacity';
            app.UIAxes_3 = uiaxes(app.AvailableEVCapacityTab);
            title(app.UIAxes_3, 'Available EV Capacity')
            xlabel(app.UIAxes_3, 'Time (h)')
            ylabel(app.UIAxes_3, 'Number of Vehicles')
            app.UIAxes_3.XLim = [0 24];
            app.EVPowerConsumptionEditFieldLabel = uilabel(app.PeakShavingAppUIFigure);
            app.EVPowerConsumptionEditFieldLabel.Text = 'EV Power Consumption:';
            app.EVPowerConsumptionEditField = uieditfield(app.PeakShavingAppUIFigure, 'numeric');
            app.EVPowerConsumptionEditField.Limits = [0 Inf];
            app.KVALabel_2 = uilabel(app.PeakShavingAppUIFigure);
            app.KVALabel_2.Text = '(KVA)';
            % Show the figure after all components are created
            app.PeakShavingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Peak_ShavingFinal2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PeakShavingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PeakShavingAppUIFigure)
        end
    end
end